classdef aLOTofImaginArches < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        ArchDefinitionTab             matlab.ui.container.Tab
        BlockeditPanel                matlab.ui.container.Panel
        TranslateBlocksButton         matlab.ui.control.Button
        scalefactorEditField_2        matlab.ui.control.NumericEditField
        scalefactorEditField_2Label   matlab.ui.control.Label
        MoveBlockNodeButton           matlab.ui.control.Button
        ScaleBlocksButton             matlab.ui.control.Button
        BackgroundImagePanel          matlab.ui.container.Panel
        TargetEditField               matlab.ui.control.NumericEditField
        TargetEditFieldLabel          matlab.ui.control.Label
        InteractiveSwitch             matlab.ui.control.Switch
        InteractiveSwitchLabel        matlab.ui.control.Label
        LEditField                    matlab.ui.control.NumericEditField
        LEditFieldLabel               matlab.ui.control.Label
        scalefactorEditField          matlab.ui.control.NumericEditField
        scalefactorEditFieldLabel     matlab.ui.control.Label
        PickReferenceButton           matlab.ui.control.Button
        ScaleImageButton              matlab.ui.control.Button
        FlipYButton                   matlab.ui.control.Button
        FlipXButton                   matlab.ui.control.Button
        LoadimageButton               matlab.ui.control.Button
        BlockdataPanel                matlab.ui.container.Panel
        ViewEditblockdataButton       matlab.ui.control.Button
        NEditField                    matlab.ui.control.NumericEditField
        NEditFieldLabel               matlab.ui.control.Label
        DeletebyNButton               matlab.ui.control.Button
        DeleteallButton               matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        ArchdefinitionPanel           matlab.ui.container.Panel
        TabGroup2                     matlab.ui.container.TabGroup
        byBlocksTab                   matlab.ui.container.Tab
        AngledegEditField_2           matlab.ui.control.NumericEditField
        AngledegEditField_2Label      matlab.ui.control.Label
        PlotGeometryButton            matlab.ui.control.Button
        UpdatedataButton              matlab.ui.control.Button
        DefineCenterButton_3          matlab.ui.control.Button
        PolenihpSwitch                matlab.ui.control.Switch
        PolenihpSwitchLabel           matlab.ui.control.Label
        SnapSwitch                    matlab.ui.control.Switch
        SnapSwitchLabel               matlab.ui.control.Label
        ThicknessEditField            matlab.ui.control.NumericEditField
        ThicknessEditFieldLabel       matlab.ui.control.Label
        NofpointsEditField            matlab.ui.control.NumericEditField
        NofpointsEditFieldLabel       matlab.ui.control.Label
        AddirregularBlockButton       matlab.ui.control.Button
        AddrectangularblockButton     matlab.ui.control.Button
        CircularTab                   matlab.ui.container.Tab
        DefineThicknessButton         matlab.ui.control.Button
        RightLabel                    matlab.ui.control.Label
        LeftLabel                     matlab.ui.control.Label
        PointsCentrButton             matlab.ui.control.Button
        AngledegEditField_3Label_2    matlab.ui.control.Label
        StartangleEditField           matlab.ui.control.NumericEditField
        StartangleEditFieldLabel      matlab.ui.control.Label
        KeepexistinggeometrySwitch    matlab.ui.control.Switch
        KeepexistinggeometrySwitchLabel  matlab.ui.control.Label
        ThicknessEditField_2          matlab.ui.control.NumericEditField
        ThicknessEditField_2Label     matlab.ui.control.Label
        EndangleEditField             matlab.ui.control.NumericEditField
        EndangleEditFieldLabel        matlab.ui.control.Label
        DefineCenterButton_2          matlab.ui.control.Button
        OuterradEditField             matlab.ui.control.NumericEditField
        OuterradEditFieldLabel        matlab.ui.control.Label
        InnerradEditField             matlab.ui.control.NumericEditField
        InnerradEditFieldLabel        matlab.ui.control.Label
        NofBlocksEditField            matlab.ui.control.NumericEditField
        NofBlocksEditFieldLabel       matlab.ui.control.Label
        CalculateArchButton           matlab.ui.control.Button
        bySketchTab                   matlab.ui.container.Tab
        ThicknessEditField_3          matlab.ui.control.NumericEditField
        ThicknessEditField_3Label     matlab.ui.control.Label
        DefinePointBButton_2          matlab.ui.control.Button
        DefinePointAButton_2          matlab.ui.control.Button
        SubdivideusingblocksnewaddEditField  matlab.ui.control.NumericEditField
        SubdivideusingblocksnewaddLabel  matlab.ui.control.Label
        ComputeblocksButton           matlab.ui.control.Button
        DefineCenterButton            matlab.ui.control.Button
        SketchthearchButton           matlab.ui.control.Button
        LoadDataTab                   matlab.ui.container.Tab
        LoadblocksButton              matlab.ui.control.Button
        ThrustLineTab                 matlab.ui.container.Tab
        ForcesPanel                   matlab.ui.container.Panel
        ViewEditForcesButton          matlab.ui.control.Button
        NEditField_2                  matlab.ui.control.NumericEditField
        NLabel_2                      matlab.ui.control.Label
        DeletebyNButton_2             matlab.ui.control.Button
        DeleteallButton_2             matlab.ui.control.Button
        MagnitudeEditField            matlab.ui.control.NumericEditField
        MagnitudeLabel                matlab.ui.control.Label
        SelectpointButton             matlab.ui.control.Button
        AdmissibilityPanel            matlab.ui.container.Panel
        CheckadmissbilityButton       matlab.ui.control.Button
        CableanalogyPanel             matlab.ui.container.Panel
        PlotCableanalogyButton        matlab.ui.control.Button
        LineofthrustPanel             matlab.ui.container.Panel
        maxEditFieldLabel_2           matlab.ui.control.Label
        minEditFieldLabel_2           matlab.ui.control.Label
        HorizontalThrustSlider        matlab.ui.control.Slider
        HorizontalThrustLabel         matlab.ui.control.Label
        UpdateSliderButton            matlab.ui.control.Button
        maxEditField                  matlab.ui.control.NumericEditField
        maxEditFieldLabel             matlab.ui.control.Label
        minEditField                  matlab.ui.control.NumericEditField
        minEditFieldLabel             matlab.ui.control.Label
        InteractiveFUPfindtheLineofThrustLabel  matlab.ui.control.Label
        UnknownsLabel                 matlab.ui.control.Label
        DefinePointAButton            matlab.ui.control.Button
        DefinePointBButton            matlab.ui.control.Button
        SliderButton                  matlab.ui.control.Button
        DefineHorizontalThrustEditField  matlab.ui.control.NumericEditField
        DefineHorizontalThrustEditFieldLabel  matlab.ui.control.Label
        ForcepolygonFOPandFunicolarPolygonFUPLabel  matlab.ui.control.Label
        PreliminarFOPButton           matlab.ui.control.Button
        FinalFOPButton                matlab.ui.control.Button
        PreliminarFUPButton           matlab.ui.control.Button
        FinalFUPButton                matlab.ui.control.Button
        LOTForcefactorEditField       matlab.ui.control.NumericEditField
        LOTForcefactorEditFieldLabel  matlab.ui.control.Label
        LineofThrustPanel             matlab.ui.container.Panel
        InspectLineofthrustButton     matlab.ui.control.Button
        UITable2                      matlab.ui.control.Table
        MechanismTab                  matlab.ui.container.Tab
        RightPanel                    matlab.ui.container.Panel
        TabGroup5                     matlab.ui.container.TabGroup
        DViewTab                      matlab.ui.container.Tab
        UIAxes3                       matlab.ui.control.UIAxes
        ForcePolygonFPTab             matlab.ui.container.Tab
        ForcePoligonPanel             matlab.ui.container.Panel
        ShowForceScaleButton          matlab.ui.control.Button
        ResetViewButton_2             matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        TabGroup4                     matlab.ui.container.TabGroup
        GraphicsTab_2                 matlab.ui.container.Tab
        ForceshrinkfactorEditField    matlab.ui.control.NumericEditField
        ForceshrinkfactorEditFieldLabel  matlab.ui.control.Label
        Tab2                          matlab.ui.container.Tab
        InteractiveAreaPanel          matlab.ui.container.Panel
        PaLabel                       matlab.ui.control.Label
        NLabel                        matlab.ui.control.Label
        kgLabel                       matlab.ui.control.Label
        CurrentunitsLabel             matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
        ScrollZoomoffButton           matlab.ui.control.Button
        ScrollZoomonButton            matlab.ui.control.Button
        PanButton                     matlab.ui.control.Button
        ShowStructuralscaleButton     matlab.ui.control.Button
        ResetViewButton               matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        TabGroup3                     matlab.ui.container.TabGroup
        GraphicsTab                   matlab.ui.container.Tab
        TextLabelsSwitch              matlab.ui.control.Switch
        TextLabelsSwitchLabel         matlab.ui.control.Label
        FlipYaxisButton               matlab.ui.control.Button
        PlotReactionsButton           matlab.ui.control.Button
        alphaWEditField               matlab.ui.control.NumericEditField
        alphaWLabel                   matlab.ui.control.Label
        PlotBackgroundImageButton     matlab.ui.control.Button
        ClearSketchButton             matlab.ui.control.Button
        BackgroundImageSwitch_2       matlab.ui.control.Switch
        BackgroundImageSwitch_2Label  matlab.ui.control.Label
        SketchedGeometrySwitch        matlab.ui.control.Switch
        SketchedGeometrySwitchLabel   matlab.ui.control.Label
        ForceMagnitudeSwitch          matlab.ui.control.Switch
        ForceMagnitudeSwitchLabel     matlab.ui.control.Label
        PlotForcesButton              matlab.ui.control.Button
        PlotSketchButton              matlab.ui.control.Button
        forcesfactorEditField         matlab.ui.control.NumericEditField
        forcesfactorEditFieldLabel    matlab.ui.control.Label
        PlotblocksButton_2            matlab.ui.control.Button
        ClearInteractiveAreaButton    matlab.ui.control.Button
        SaveLoadExampleTab            matlab.ui.container.Tab
        ClearAllVariablesButton       matlab.ui.control.Button
        OpenExampleButton             matlab.ui.control.Button
        ExampleDropDown               matlab.ui.control.DropDown
        ExampleDropDownLabel          matlab.ui.control.Label
        LoadExamplesfolderButton      matlab.ui.control.Button
        SaveasEditField               matlab.ui.control.EditField
        SaveasEditFieldLabel          matlab.ui.control.Label
        SavetofolderExamplesButton    matlab.ui.control.Button
        BlocksMaterialpropertiesTab   matlab.ui.container.Tab
        SpecificWeightEditField       matlab.ui.control.NumericEditField
        SpecificWeightEditFieldLabel  matlab.ui.control.Label
        BlockThicknessEditField       matlab.ui.control.NumericEditField
        BlockThicknessEditFieldLabel  matlab.ui.control.Label
        UnitsTab_2                    matlab.ui.container.Tab
        kgcmButton                    matlab.ui.control.Button
        NmmButton                     matlab.ui.control.Button
        SIButton                      matlab.ui.control.Button
        ModulusStressButtonGroup      matlab.ui.container.ButtonGroup
        kgcm2Button                   matlab.ui.control.RadioButton
        MPaButton_2                   matlab.ui.control.RadioButton
        PaButton                      matlab.ui.control.RadioButton
        ForceButtonGroup              matlab.ui.container.ButtonGroup
        kgfButton                     matlab.ui.control.RadioButton
        kNButton                      matlab.ui.control.RadioButton
        NButton                       matlab.ui.control.RadioButton
        MassButtonGroup               matlab.ui.container.ButtonGroup
        gButton                       matlab.ui.control.RadioButton
        tonsButton                    matlab.ui.control.RadioButton
        kgButton                      matlab.ui.control.RadioButton
        LengthButtonGroup             matlab.ui.container.ButtonGroup
        mmButton                      matlab.ui.control.RadioButton
        cmButton                      matlab.ui.control.RadioButton
        mButton                       matlab.ui.control.RadioButton
        PixelEditField                matlab.ui.control.NumericEditField
        PixelEditFieldLabel           matlab.ui.control.Label
        unitlengthLabel               matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        % Set up the path for external functions
        oldpath = addpath('./external_functions/')
        xy_Center             % Center of the arch
        xy_Point_B                % Top of the arch
        xy_Point_A             % Bottom of the arch
        angle_Top_Center      % angle Top Center radiants
        angle_Bottom_Center   % angle bottom Center radiants
        angle_Top_Center_deg      % angle Top Center
        angle_Bottom_Center_deg  % angle bottom Center
        angle_Block_deg
        angle_Block
        angle_factors
        ImageFile
        previous_image_scaling_factor
        current_image_scaling_factor
        Arch_region_Position   % coordinates of the arch region
        Arch_region
        A_Blocks
        W_Blocks
        A_Blocks_like
        W_Blocks_like
        Thickness_Blocks_like
        Thickness_Blocks
        modified_Thickness_Blocks
        Reference_distance % the known distance
        Resize_reference
        Blocks_like_kind % [ 0 block, 1 Force ]
        Blocks_coordinates_like
        Blocks_coordinates_4_points_like
        xyg_Blocks_like
        Blocks_coordinates
        Blocks_coordinates_4_points
        xyg_Blocks
        % sorted version for FUP
        A_Blocks_like_sorted
        W_Blocks_like_sorted
        Thickness_Blocks_like_sorted
        xyg_Blocks_like_sorted
        Blocks_like_kind_sorted
        Blocks_coordinates_4_points_like_sorted
        Preliminar_Funicolar_Problem %
        Final_Funicolar_Problem %
        HorizontalThrust
        polyin_Arch
        xyG_polyin_Arch
        alphabet_letters
        xy_Pole_Prime
        xy_Pole_Def
        Force_Funicolar_Polygon
        LOT_Force
        LOT_xy
        UIFigure_LOT
        Number_of_Blocks
        Number_of_Blocks_like
        Folder_Examples
        alpha_W_Blocks
        Reaction_at_A
        Reaction_at_B
        Reaction_at_A_Components
        Reaction_at_B_Components
        Number_of_Forces
        Forces_applied
        Forces_applied_modified
        Forces_applied_xy
        xy_Point_F
        force_size_rescaled
        force_size_rescaled_factor
        force_size_rescaled_factor_g
        % Objects
        object_Point_A
        object_Point_A_Text
        object_Point_B
        object_Point_B_Text
        object_Point_Center
        object_Point_Center_Text
        object_Point_F
        object_Point_F_Text
        ComputingProgressbar
        DrawingProgressbar
        Reference_Points
        Reference_Points_text

        % Units
        Conversion_Unit_Force_scaling
        Conversion_Unit_Pressure_scaling
        Conversion_Unit_Mass_scaling
        Conversion_Unit_Length_scaling
        Conversion_Unit_Specific_Weight_scaling
        UNISYS
        Unit_Length_scaling % from screen to Lenght units (1 Pixel = 1 Unit)
        Unit_Mass_to_Weight
        % Dialog Figures
        DialogAppTable
        UIFigure_table
        UIFigure_forces
    end

    methods (Access = private)
        function ComputingProgressBar(app, event)
            switch event
                case 'open'
                    % open the dialog box
                    app.ComputingProgressbar = uiprogressdlg(app.UIFigure,'Title','Computing', 'Indeterminate','on');
                    drawnow
                case 'close'
                    % close the dialog box
                    close(app.ComputingProgressbar)
            end


        end

        function DrawingProgressBar(app, event)
            switch event
                case 'open'
                    % open the dialog box
                    app.DrawingProgressbar = uiprogressdlg(app.UIFigure,'Title','Drawing', 'Indeterminate',"off", "Cancelable","on");
                    drawnow
                case 'close'
                    % close the dialog box
                    close(app.DrawingProgressbar)
            end


        end

        function setup_main_figure(app)
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal')
            set(app.UIAxes, 'Units', 'Pixels')
        end

        function setup_secondary_figure(app)
            hold(app.UIAxes2, 'on');
            axis(app.UIAxes2, 'equal')

        end

        function setup_3D_figure(app)
            hold(app.UIAxes3, 'on');
            axis(app.UIAxes3, 'equal')
            angle_diff = app.AngledegEditField_2.Value/2;
            app.UIAxes3.View = [-45-angle_diff 30];
            % Configure app.UIAxes3 to hide box, axis lines, and ticks
            app.UIAxes3.XColor = 'none';  % Hides the X-axis line and ticks
            app.UIAxes3.YColor = 'none';  % Hides the Y-axis line and ticks
            app.UIAxes3.ZColor = 'none';  % Hides the Z-axis line and ticks
            app.UIAxes3.Box = 'off';      % Removes the box around the plot

        end

        function Plot_point_A(app)
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal')
            % delete previous graphical objects
            delete(app.object_Point_A)
            delete(app.object_Point_A_Text)
            app.object_Point_A = plot(app.UIAxes, app.xy_Point_A(1),app.xy_Point_A(2),"Color",[1 0 0],"MarkerEdgeColor",[0 0 0],"MarkerSize",4,"Marker","o", "MarkerFaceColor", [0 0 0]);
            app.object_Point_A_Text = text(app.UIAxes,app.xy_Point_A(1),app.xy_Point_A(2),'  Point A',"FontSize",16,"HorizontalAlignment","right", "VerticalAlignment","bottom");

        end
        function Plot_point_F(app)
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal')
            % delete previous graphical objects
            delete(app.object_Point_F)
            delete(app.object_Point_F_Text)
            app.object_Point_F = plot(app.UIAxes, app.xy_Point_F(1),app.xy_Point_F(2),"Color",[1 0 0],"MarkerEdgeColor",[0 0 0],"MarkerSize",4,"Marker","o", "MarkerFaceColor", [0 0 0]);
            app.object_Point_F_Text = text(app.UIAxes,app.xy_Point_F(1),app.xy_Point_F(2),'  Point F',"FontSize",16,"HorizontalAlignment","right", "VerticalAlignment","bottom");

        end
        function Plot_point_B(app)
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal')
            % delete previous graphical objects
            delete(app.object_Point_B)
            delete(app.object_Point_B_Text)
            app.object_Point_B = plot(app.UIAxes, app.xy_Point_B(1),app.xy_Point_B(2),"Color",'k',"MarkerEdgeColor","auto","MarkerSize",4,"Marker","o", "MarkerFaceColor", 'k');
            app.object_Point_B_Text = text(app.UIAxes,app.xy_Point_B(1),app.xy_Point_B(2),'  Point B',"FontSize",16,"HorizontalAlignment","right", "VerticalAlignment","bottom");

        end
        function Plot_point_Center(app)
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal')
            % delete previous graphical objects
            delete(app.object_Point_Center)
            delete(app.object_Point_Center_Text)
            app.object_Point_Center = plot(app.UIAxes, app.xy_Center(1),app.xy_Center(2),"Color",'k',"MarkerEdgeColor","auto","MarkerSize",4,"Marker","o", "MarkerFaceColor", 'k');
            app.object_Point_Center_Text = text(app.UIAxes,app.xy_Center(1),app.xy_Center(2),'Center',"FontSize",16,"HorizontalAlignment","center", "VerticalAlignment","top");

        end

        function app = compute_Blocks_geometrical_features(app)
            % Pre-allocate arrays for efficiency
            xg = zeros(1, app.Number_of_Blocks);
            yg = zeros(1, app.Number_of_Blocks);
            app.A_Blocks = zeros(1, app.Number_of_Blocks);
            %app.Thickness_Blocks = zeros(1, app.Number_of_Blocks);
            app.W_Blocks = zeros(1, app.Number_of_Blocks);

            for kk = 1:app.Number_of_Blocks
                % Retrieve block coordinates
                x = app.Blocks_coordinates_4_points{kk,1};
                y = app.Blocks_coordinates_4_points{kk,2};
                ps = polyshape(x, y);

                % Calculate the centroid
                [xg(kk), yg(kk)] = centroid(ps);

                % Calculate area and weight
                app.A_Blocks(kk) = area(ps) * app.Unit_Length_scaling^2;
                app.Thickness_Blocks(kk) = app.Thickness_Blocks(kk)* app.Unit_Length_scaling;
                app.W_Blocks(kk) = app.A_Blocks(kk) * app.SpecificWeightEditField.Value * app.Thickness_Blocks(kk) * app.Unit_Mass_to_Weight;
            end

            % Store the centroids
            app.xyg_Blocks = [xg', yg'];
            %
            % updating data
            update_Blocks_like(app)
            % Update the table
            app = update_Table2(app);

        end

        function saveTableData(app, newData)
            % Initialize modified_Thickness_Blocks if it is empty
            if isempty(app.modified_Thickness_Blocks)
                app.modified_Thickness_Blocks = zeros(size(app.Thickness_Blocks));  % Initialize with zeros
            end

            % Update the thickness values in app.UITable.Data with edited data
            for kk = 1:size(newData, 1)
                if app.Thickness_Blocks(kk) ~= newData(kk, 3)  % Check for changes in thickness
                    app.modified_Thickness_Blocks(kk) = 1;  % Mark this block as modified
                end
                app.Thickness_Blocks(kk) = newData(kk, 3);  % Update the thickness value
            end
            
            % Recompute the geometrical features
            compute_Blocks_geometrical_features(app);

            % Close the secondary figure
            close(app.UIFigure_table);
            app.TabGroup5.SelectedTab = app.DViewTab;
            PlotGeometryButtonPushed(app, struct())
        end

        function saveTableData_forces(app, newData)
            % Initialize modified_Thickness_Blocks if it is empty
            if isempty(app.Forces_applied_modified)
                app.Forces_applied_modified = zeros(size(app.Forces_applied));  % Initialize with zeros
            end

            % Update the thickness values in app.UITable.Data with edited data
            for kk = 1:size(newData, 1)
                if app.Forces_applied(kk) ~= newData(kk, 2)  % Check for changes in magnitude
                    app.Forces_applied_modified(kk) = 1;  % Mark this block as modified
                end
                app.Forces_applied(kk) = newData(kk, 2);  % Update the thickness value
            end

            % Recompute the geometrical features
            %compute_Blocks_geometrical_features(app);
            sorting_Blocks(app)
            % Close the secondary figure
            close(app.UIFigure_forces);
        end
        
        function app = update_Blocks_like(app)
            % define blocks and force in the same container
            if app.Number_of_Forces >0
            app.Number_of_Blocks_like = app.Number_of_Forces+app.Number_of_Blocks; %[[1:app.Number_of_Blocks],[1:app.Number_of_Forces]+app.Number_of_Blocks];
            else
               app.Number_of_Blocks_like = app.Number_of_Blocks;
            end

            app.xyg_Blocks_like = [app.xyg_Blocks ; app.Forces_applied_xy];
            app.Blocks_like_kind = [zeros(app.Number_of_Blocks,1); ones(app.Number_of_Forces,1)];
            app.Blocks_coordinates_4_points_like = app.Blocks_coordinates_4_points;
            % add those fake blocks from forces
            for i =1:app.Number_of_Forces
                app.Blocks_coordinates_4_points_like{end+1, 1} = [0,0,0,0];  % Add empty entry to first column
                app.Blocks_coordinates_4_points_like{end, 2} = [0,0,0,0];    % Add empty entry to second column
            end
            app.A_Blocks_like = [app.A_Blocks, zeros(app.Number_of_Forces,1)'];
            app.W_Blocks_like = [app.W_Blocks, app.Forces_applied];
            app.Thickness_Blocks_like = [app.Thickness_Blocks, zeros(app.Number_of_Forces,1)'];
            app.DefineHorizontalThrustEditField.Value = sum(app.W_Blocks_like)/3;
            % FigureYdimension = get(app.UIAxes, 'YLim');
            app = arrow_scale(app);
            %app.force_size_rescaled_factor = sum(app.W_Blocks_like)/FigureYdimension(2);
        end
        
        function app = sorted_Blocks_like(app)
            % define blocks and force in the same container
            if app.Number_of_Forces >0
                app.Number_of_Blocks_like = app.Number_of_Forces+app.Number_of_Blocks; %[[1:app.Number_of_Blocks],[1:app.Number_of_Forces]+app.Number_of_Blocks];
            else
                app.Number_of_Blocks_like = app.Number_of_Blocks;
            end

            app.xyg_Blocks_like = [app.xyg_Blocks ; app.Forces_applied_xy];
            app.Blocks_like_kind = [zeros(app.Number_of_Blocks,1); ones(app.Number_of_Forces,1)];
            app.Blocks_coordinates_4_points_like = app.Blocks_coordinates_4_points;
            % add those fake blocks from forces
            for i =1:app.Number_of_Forces
                app.Blocks_coordinates_4_points_like{end+1, 1} = [0,0,0,0];  % Add empty entry to first column
                app.Blocks_coordinates_4_points_like{end, 2} = [0,0,0,0];    % Add empty entry to second column
            end
            app.A_Blocks_like = [app.A_Blocks, zeros(app.Number_of_Forces,1)'];
            app.W_Blocks_like = [app.W_Blocks, app.Forces_applied];
            app.Thickness_Blocks_like = [app.Thickness_Blocks, zeros(app.Number_of_Forces,1)'];

            [xg_temp, ind]= sort (app.xyg_Blocks_like(:,1),'descend');
            if size(xg_temp,1)==1
                xg = xg_temp;
            else
                xg = xg_temp';
            end
            sv = size(app.Blocks_coordinates_4_points_like,1);
            old = app.Blocks_coordinates_4_points_like;
            app.Blocks_coordinates_4_points_like_sorted = [];
            for ii = 1:sv
                app.Blocks_coordinates_4_points_like_sorted{ii,1} = old{ind(ii),1};
                app.Blocks_coordinates_4_points_like_sorted{ii,2} = old{ind(ii),2};
            end
            app.A_Blocks_like_sorted = app.A_Blocks_like(ind);
            app.W_Blocks_like_sorted = app.W_Blocks_like(ind);
            app.Thickness_Blocks_like_sorted = app.Thickness_Blocks_like(ind);
            app.xyg_Blocks_like_sorted = app.xyg_Blocks_like(ind,:);
            app.Blocks_like_kind_sorted = app.Blocks_like_kind(ind);
        end

        function app = sorting_Blocks(app)
            [xg_temp, ind]= sort (app.xyg_Blocks(:,1),'descend');
            if size(xg_temp,1)==1
                xg = xg_temp;
            else
                xg = xg_temp';
            end
            sv = size(app.Blocks_coordinates_4_points,1);
            old = app.Blocks_coordinates_4_points;
            app.Blocks_coordinates_4_points = [];
            for ii = 1:sv
                app.Blocks_coordinates_4_points{ii,1} = old{ind(ii),1};
                app.Blocks_coordinates_4_points{ii,2} = old{ind(ii),2};
            end
            app.A_Blocks = app.A_Blocks(ind);
            app.W_Blocks = app.W_Blocks(ind);
            app.Thickness_Blocks = app.Thickness_Blocks(ind);
            app.xyg_Blocks = app.xyg_Blocks(ind,:);

            update_Blocks_like(app)
        end

        function app = update_Table1(app)
            app.UITable.Data = [];
            app.UITable.Data = [[1:1:app.Number_of_Blocks]',app.A_Blocks', app.Thickness_Blocks' ,app.W_Blocks'];%, app.xyg_Blocks(:,1), app.xyg_Blocks(:,2)];
        end
        
        function app = update_Table2(app)
            app.UITable.Data = [];
            % Create a logical index for rows where app.Thickness_Blocks_like is equal to 1
            selectedRows = app.Blocks_like_kind == 0; % 0 is a block

            % Filter each data array using the selectedRows index
            filteredData = [ (1:app.Number_of_Blocks_like)', app.A_Blocks_like', app.Thickness_Blocks_like', app.W_Blocks_like'];%, app.xyg_Blocks_like(:,1), app.xyg_Blocks_like(:,2)];
            filteredData = filteredData(selectedRows, :);

            % Assign the filtered data to the UITable
            app.UITable.Data = filteredData;
        end

        function ComputeReactions(app)
            RAXY = app.Reaction_at_A(2,:)-app.Reaction_at_A(1,:);
            app.Reaction_at_A_Components(1) = sqrt(RAXY(1)^2+RAXY(2)^2);
            app.Reaction_at_A_Components(2) = RAXY(1);
            app.Reaction_at_A_Components(3) = RAXY(2);
            app.Reaction_at_B_Components(1) = norm(app.Reaction_at_B);
            RBXY = app.Reaction_at_B(2,:)-app.Reaction_at_B(1,:);
            app.Reaction_at_B_Components(1) = sqrt(RBXY(1)^2+RBXY(2)^2);
            app.Reaction_at_B_Components(2) = RBXY(1);
            app.Reaction_at_B_Components(3) = RBXY(2);
        end

        function [hArrow] = drawArrow_G3(app, gca, p0, p1, color)
            % drawArrow(p0,p1)
            %
            % Draws a simple arrow in 2D, with the shaft length scaled and the head size constant.
            %
            % INPUTS:
            %   p0 = [x0; y0] = position of the tail
            %   p1 = [x1; y1] = position of the tip
            %   color = arrow color. Optional: default is black
            %       --> can be 'r','g','b','c','m','y','w', 'k' or a 1x3 color vector
            %
            % OUTPUTS:
            %   hArrow = handle to the patch object representing the arrow
            %

            % Defaults:
            if nargin == 3
                color = 'k';
            end

            % Parameters:
            scale = 10;
            W1 = 0.04*scale;   % Half width of the arrow head (constant)
            W2 = 0.007*scale;  % Half width of the arrow shaft (constant)
            L1 = 0.1*scale;    % Length of the arrow head (constant)
            L2 = 0.06*scale;   % Length of the arrow inset (constant)

            % Unpack the tail and tip of the arrow
            x0 = p0(1);
            y0 = p0(2);
            x1 = p1(1);
            y1 = p1(2);

            % Compute arrow length and angle
            dx = x1 - x0;
            dy = y1 - y0;
            Length = sqrt(dx^2 + dy^2);
            Angle = atan2(-dy, dx);

            % Ensure the shaft length does not overlap the head
            shaftLength = max(0, Length - L1);  % Shaft length excludes the head length

            % Define the arrow shape with constant head size and scaled shaft length
            P = [...
                0, shaftLength, shaftLength, Length, shaftLength, shaftLength, 0;  % X-coordinates
                W2,    W2,       W1,      0,     -W1,    -W2,      -W2];          % Y-coordinates (constant thickness)

            % Rotate the arrow by the calculated angle
            rotationMatrix = [cos(Angle), sin(Angle); -sin(Angle), cos(Angle)];
            P = rotationMatrix * P;

            % Shift the arrow to its desired position
            P = p0(:) * ones(1, 7) + P;

            % Plot the arrow
            hArrow = patch(gca, P(1, :), P(2, :), color);
            hArrow.EdgeColor = color;

        end

        function [hArrow] = drawArrow_G(app, gca, p0, p1, color)
            % drawArrow_G(app, gca, p0, p1, color)
            %
            % Draws a simple arrow in 2D with a constant head size and thickness,
            % while the shaft length scales dynamically.
            %
            % INPUTS:
            %   p0 = [x0; y0] = position of the tail
            %   p1 = [x1; y1] = position of the tip
            %   color = arrow color. Optional: default is black
            %       --> can be 'r','g','b','c','m','y','w', 'k' or a 1x3 color vector
            %
            % OUTPUTS:
            %   hArrow = handle to the patch object representing the arrow
            %

            % Defaults:
            if nargin < 5
                color = 'k';
            end
            s = app.force_size_rescaled_factor_g;
            % Parameters for arrowhead (constant size):
            W1 = 0.2*s*3;    % Half width of the arrowhead
            L1 = 0.3*s*3;    % Length of the arrowhead

            % Parameters for shaft (constant thickness):
            W2 = 0.05*s*3;   % Half width of the arrow shaft


            % Unpack the tail and tip of the arrow
            x0 = p0(1);
            y0 = p0(2);
            x1 = p1(1);
            y1 = p1(2);

            % Compute arrow length and angle
            dx = x1 - x0;
            dy = y1 - y0;
            Length = sqrt(dx^2 + dy^2);
            Angle = atan2(-dy, dx);

            % Ensure the shaft length does not overlap the head
            shaftLength = max(0, Length - L1);  % Shaft length excludes the head length

            % Define the arrow shape:
            % - Shaft: a rectangle with a scaled length and fixed width
            % - Head: a triangle with a constant width and length
            P = [...
                0,    shaftLength, shaftLength, Length, shaftLength, shaftLength, 0;  % X-coordinates
                W2,        W2,         W1,      0,      -W1,       -W2,      -W2];  % Y-coordinates

            % Rotate the arrow by the calculated angle
            rotationMatrix = [cos(Angle), sin(Angle); -sin(Angle), cos(Angle)];
            P = rotationMatrix * P;

            % Shift the arrow to its desired position
            P = p0(:) * ones(1, size(P, 2)) + P;

            % Plot the arrow
            hArrow = patch(gca, P(1, :), P(2, :), color);
            hArrow.EdgeColor = color;

        end

        function [hArrow] = drawArrow_Gs(app, gca, p0, p1, color)
            % drawArrow_G(app, gca, p0, p1, color)
            %
            % Draws a simple arrow in 2D with a constant head size and thickness,
            % while the shaft length scales dynamically.
            %
            % INPUTS:
            %   p0 = [x0; y0] = position of the tail
            %   p1 = [x1; y1] = position of the tip
            %   color = arrow color. Optional: default is black
            %       --> can be 'r','g','b','c','m','y','w', 'k' or a 1x3 color vector
            %
            % OUTPUTS:
            %   hArrow = handle to the patch object representing the arrow
            %

            % Defaults:
            if nargin < 5
                color = 'k';
            end
            s = app.force_size_rescaled_factor;
            % Parameters for arrowhead (constant size):
            W1 = 0.2*s*.07;    % Half width of the arrowhead
            L1 = 0.3*s*.07;    % Length of the arrowhead

            % Parameters for shaft (constant thickness):
            W2 = 0.05*s*.07;   % Half width of the arrow shaft

            % Unpack the tail and tip of the arrow
            x0 = p0(1);
            y0 = p0(2);
            x1 = p1(1);
            y1 = p1(2);

            % Compute arrow length and angle
            dx = x1 - x0;
            dy = y1 - y0;
            Length = sqrt(dx^2 + dy^2);
            Angle = atan2(-dy, dx);

            % Ensure the shaft length does not overlap the head
            shaftLength = max(0, Length - L1);  % Shaft length excludes the head length

            % Define the arrow shape:
            % - Shaft: a rectangle with a scaled length and fixed width
            % - Head: a triangle with a constant width and length
            P = [...
                0,    shaftLength, shaftLength, Length, shaftLength, shaftLength, 0;  % X-coordinates
                W2,        W2,         W1,      0,      -W1,       -W2,      -W2];  % Y-coordinates

            % Rotate the arrow by the calculated angle
            rotationMatrix = [cos(Angle), sin(Angle); -sin(Angle), cos(Angle)];
            P = rotationMatrix * P;

            % Shift the arrow to its desired position
            P = p0(:) * ones(1, size(P, 2)) + P;

            % Plot the arrow
            hArrow = patch(gca, P(1, :), P(2, :), color);
            hArrow.EdgeColor = color;

        end

        function [hArrow] = drawArrow_G2(app, gca, p0, p1, color)
            % drawArrow(p0,p1)
            %
            % Draws a simple arrow in 2D, from p0 to p1, maintaining a constant thickness.
            %
            % INPUTS:
            %   p0 = [x0; y0] = position of the tail
            %   p1 = [x1; y1] = position of the tip
            %   color = arrow color. Optional: default is black
            %       --> can be 'r','g','b','c','m','y','w', 'k' or a 1x3 color vector
            %
            % OUTPUTS:
            %   hArrow = handle to the patch object representing the arrow
            %

            % Defaults:
            if nargin == 3
                color = 'k';
            end

            % Parameters:
            W1 = 0.04*2;   % half width of the arrow head
            W2 = 0.007*2;  % half width of the arrow shaft
            L1 = 0.09;   % Length of the arrow head as a fraction of total length
            L2 = 0.06;   % Length of the arrow inset as a fraction of total length

            % Unpack the tail and tip of the arrow
            x0 = p0(1);
            y0 = p0(2);
            x1 = p1(1);
            y1 = p1(2);

            % Start by defining an arrow shape with unit length on the x-axis
            P = [...
                0, (1-L2), (1-L1), 1, (1-L1), (1-L2), 0;  % X-coordinates (normalized length)
                W2,    W2,     W1, 0,    -W1,    -W2, -W2]; % Y-coordinates (constant thickness)

            % Compute arrow length and angle
            dx = x1 - x0;
            dy = y1 - y0;
            Length = sqrt(dx^2 + dy^2);
            Angle = atan2(-dy, dx);

            % Scale only the X-coordinates by length, keeping Y-coordinates constant
            P(1, :) = Length * P(1, :);  % Scale X-coordinates
            % Y-coordinates remain unscaled to maintain constant thickness

            % Rotate the arrow by the calculated angle
            rotationMatrix = [cos(Angle), sin(Angle); -sin(Angle), cos(Angle)];
            P = rotationMatrix * P;

            % Shift the arrow to its desired position
            P = p0(:) * ones(1, 7) + P;

            % Plot the arrow
            hArrow = patch(gca, P(1, :), P(2, :), color);
            hArrow.EdgeColor = color;

        end

        function [app] = arrow_scale(app)
            try
                if isempty(app.force_size_rescaled)
                    app.force_size_rescaled = false;
                    app.force_size_rescaled_factor = 1;
                end
                FigureYdimension = get(app.UIAxes, 'YLim');
                if numel(FigureYdimension) < 2 || ~isfinite(FigureYdimension(2))
                    return
                end
                app.force_size_rescaled_factor_g = FigureYdimension(2)/20;
                max_weight = max(app.W_Blocks_like(:));
                if isempty(max_weight) || ~isscalar(max_weight) || ...
                        ~isfinite(max_weight) || max_weight <= 0
                    return
                end
                if FigureYdimension(2) < max_weight && ~app.force_size_rescaled
                    app.forcesfactorEditField.Value = 0.3 * FigureYdimension(2) / max_weight;
                    app.force_size_rescaled = true;
                    app.force_size_rescaled_factor = sum(app.W_Blocks_like(:))/FigureYdimension(2);
                end
            catch ME
                warning('arrow_scale: scaling delle frecce saltato (%s)', ME.message);
            end
        end


    end


    methods (Access = public)

        function app = update_Table_from_Dialog(app, Table)
            app.UITable.Data = [];
            app.UITable.Data = Table;
            % Re-enable the Plot Options button
            app.EditdataButton.Enable = 'on';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Start)
            SIButtonPushed(app, 'starting');
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            try
                [filename, filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
                fullname = [filepath, filename];
                app.ImageFile = imread(fullname);

                % Define the desired dimensions for displaying the image (in pixels or units)
                desired_width = 500;  % Adjust this to your desired display width
                desired_height = 300; % Adjust this to your desired display height

                % Get the size of the image
                [img_height, img_width, ~] = size(app.ImageFile);

                % Scale the image display to match desired dimensions
                x_scale = 1;%desired_width / img_width;
                y_scale = 1;%desired_height / img_height;
                imagesc(app.UIAxes, [0, img_width * app.current_image_scaling_factor ], [0, img_height * app.current_image_scaling_factor ], app.ImageFile);

                % Adjust the y-axis direction and call setup functions
                set(app.UIAxes, 'YDir', 'normal');
                setup_main_figure(app);
                ResetViewButtonPushed(app);

%                 % Check and display a warning for units if necessary
%                 if strcmp(app.LengthButtonGroup.SelectedObject.Text, 'mm')
%                     % Units are already in mm
%                 else
%                     warndlg('Units are changed to Nmm system (1 Pixel = 1000mm)', 'Warning');
%                     NmmButtonPushed(app, event);
%                 end
                FlipYButtonPushed(app, event)
            catch
                % Handle errors if any (e.g., file not selected)
            end


        end

        % Button pushed function: SketchthearchButton
        function SketchthearchButtonPushed(app, event)

            app.Arch_region = drawfreehand(app.UIAxes,'Color',[0.5 0.5 0.5]);
            app.Arch_region_Position = app.Arch_region.Position;
            setup_main_figure(app)
            PlotSketchButtonPushed(app,event)

        end

        % Button pushed function: DefineCenterButton, DefineCenterButton_2
        function DefineCenterButtonPushed(app, event)
            [x, y] = selectDataPoints(app.UIAxes);
            app.xy_Center(1) = x;
            app.xy_Center(2) = y;
            setup_main_figure(app)
            plot(app.UIAxes, x,y,"Color",[1,0,0],"MarkerEdgeColor",[1,0,0],"MarkerSize",5,"Marker","o", "LineWidth",2)
            text(app.UIAxes,x,y,'  Center',"FontSize",14,"HorizontalAlignment","left")

        end

        % Callback function
        function DefineRightButtonPushed(app, event)
            [x, y] = selectDataPoints(app.UIAxes);
            app.xy_Point_B(1) = x;
            app.xy_Point_B(2) = y;
            setup_main_figure(app)
            plot(app.UIAxes, x,y,"Color",[1,0,0],"MarkerEdgeColor",[1,0,0],"MarkerSize",5,"Marker","o", "LineWidth",2)
            text(app.UIAxes,x,y,'  Right',"FontSize",14,"HorizontalAlignment","left")
        end

        % Callback function
        function DefineLeftButtonPushed(app, event)
            [x, y] = selectDataPoints(app.UIAxes);
            app.xy_Point_A(1) = x;
            app.xy_Point_A(2) = y;
            setup_main_figure(app)
            plot(app.UIAxes, x,y,"Color",[1,0,0],"MarkerEdgeColor",[1,0,0],"MarkerSize",5,"Marker","o", "LineWidth",2)
            text(app.UIAxes,x,y,'  Left',"FontSize",14,"HorizontalAlignment","left")
        end

        % Button pushed function: ComputeblocksButton
        function ComputeblocksButtonPushed(app, event)
            % Compute angle
            ComputingProgressBar(app, 'open')
            app.angle_Top_Center = atan2(app.xy_Point_B(2)-app.xy_Center(2),app.xy_Point_B(1)-app.xy_Center(1));
            app.angle_Bottom_Center = atan2(app.xy_Point_A(2)-app.xy_Center(2),app.xy_Point_A(1)-app.xy_Center(1));
            plot(app.UIAxes,[app.xy_Center(1), app.xy_Point_B(1)],[app.xy_Center(2), app.xy_Point_B(2)], "Color",'k', "LineStyle","--", "LineWidth",2)
            plot(app.UIAxes,[app.xy_Center(1), app.xy_Point_A(1)],[app.xy_Center(2), app.xy_Point_A(2)], "Color",'k', "LineStyle","--", "LineWidth",2)
            app.angle_Top_Center_deg = 360*app.angle_Top_Center/(2*pi);
            app.angle_Bottom_Center_deg = 360*app.angle_Bottom_Center/(2*pi);
            app.angle_Block = (app.angle_Bottom_Center-app.angle_Top_Center)/app.SubdivideusingblocksnewaddEditField.Value;
            app.angle_Block_deg = (app.angle_Bottom_Center_deg-app.angle_Top_Center_deg)/app.SubdivideusingblocksnewaddEditField.Value;
            x = app.xy_Point_B(1)-app.xy_Center(1);
            y = app.xy_Point_B(2)-app.xy_Center(2);
            [theta,rho] = cart2pol(x,y);
            thetas = [theta:app.angle_Block:app.angle_Bottom_Center];
            rhos = thetas*0+rho*1.1;
            [x,y] = pol2cart(thetas,rhos);
            X = x + app.xy_Center(1);
            Y = y + app.xy_Center(2);
            if isempty(app.Arch_region_Position)
                f = warndlg('Arch not defined','Warning');
            else

            end
            L1 = app.Arch_region_Position';
            try
                for jj=1:length(rhos)
                    plot(app.UIAxes,[app.xy_Center(1), X(jj)],[app.xy_Center(2), Y(jj)], "Color",'k', "LineStyle","--", "LineWidth",1)

                    L2 = [app.xy_Center(1), X(jj);
                        app.xy_Center(2), Y(jj)];

                    P(jj,:,:) = InterX_G(L1,L2);

                    %                 a = P(jj,:,:);
                    D1 = sqrt(sum((P(jj,1,1)-app.xy_Center(1))+(P(jj,2,1)-app.xy_Center(2)).^2));
                    D2 = sqrt(sum((P(jj,1,2)-app.xy_Center(1))+(P(jj,2,2)-app.xy_Center(2)).^2));
                    if D1<D2
                        P(jj,:,:) = P(jj,:,[2 1]);
                    end
                    plot(app.UIAxes,P(jj,1,1),P(jj,2,1),"Color",'k', "MarkerEdgeColor",[1 0 0], "Marker","+","MarkerSize",10, "MarkerFaceColor",[1 1 1])
                    plot(app.UIAxes,P(jj,1,2),P(jj,2,2),"Color",'k', "MarkerEdgeColor",[0 1 0], "Marker","+","MarkerSize",10, "MarkerFaceColor",[1 1 1])
                end
                app.Blocks_coordinates = P;

                %%%
                Nblock = size(app.Blocks_coordinates_4_points,1);
                if Nblock == 0
                    for kk = 1:app.SubdivideusingblocksnewaddEditField.Value
                        x = [P(kk,1,1) P(kk,1,2) P(kk+1,1,2) P(kk+1,1,1)];
                        y = [P(kk,2,1) P(kk,2,2) P(kk+1,2,2) P(kk+1,2,1)];
                        app.Blocks_coordinates_4_points{kk,1} = x;
                        app.Blocks_coordinates_4_points{kk,2} = y;
                    end
                    app.Number_of_Blocks = app.SubdivideusingblocksnewaddEditField.Value;
                else
                    old = app.Blocks_coordinates_4_points;
                    app.Blocks_coordinates_4_points = [];
                    for kk = 1:Nblock
                        app.Blocks_coordinates_4_points{kk,1} = old{kk,1};
                        app.Blocks_coordinates_4_points{kk,2} = old{kk,2};
                    end

                    count = 1;
                    for kk = Nblock+1:Nblock+app.SubdivideusingblocksnewaddEditField.Value
                        x = [P(count,1,1) P(count,1,2) P(count+1,1,2) P(count+1,1,1)];
                        y = [P(count,2,1) P(count,2,2) P(count+1,2,2) P(count+1,2,1)];
                        app.Blocks_coordinates_4_points{kk,1} = x;
                        app.Blocks_coordinates_4_points{kk,2} = y;
                        count = count +1;
                    end
                    app.Number_of_Blocks = app.Number_of_Blocks + app.SubdivideusingblocksnewaddEditField.Value;
                end
                app = compute_Blocks_geometrical_features(app);

                app = sorting_Blocks(app);

                app = update_Table2(app);
                PlotblocksButton_2Pushed(app,event)
                ComputingProgressBar(app, 'close')
                if Nblock >0
                    f = warndlg(['There were already ', num2str(Nblock), ' defined blocks. Added ', num2str(app.SubdivideusingblocksnewaddEditField.Value), ' new blocks' ],'Warning');
                else
                end
            catch
                ComputingProgressBar(app, 'close')
                f = warndlg('Blocks were not created! Check intersection with sketch.','Warning');

            end


        end

        % Button pushed function: FlipXButton
        function FlipXButtonPushed(app, event)
            if isempty(app.ImageFile)
            else
                app.ImageFile = flipdim(app.ImageFile ,2);
                %                 imagesc(app.UIAxes, app.ImageFile);
                % Get the size of the image
                [img_height, img_width, ~] = size(app.ImageFile);
                % Scale the image display to match desired dimensions
                x_scale = app.scalefactorEditField.Value;%desired_width / img_width;
                y_scale = app.scalefactorEditField.Value; %desired_height / img_height;
                imagesc(app.UIAxes, [0, img_width * app.current_image_scaling_factor ], [0, img_height * app.current_image_scaling_factor ], app.ImageFile);
            end
        end

        % Button pushed function: FlipYButton
        function FlipYButtonPushed(app, event)
            if isempty(app.ImageFile)
            else
                app.ImageFile = flipdim(app.ImageFile ,1);
                %                 imagesc(app.UIAxes, app.ImageFile);
                % Get the size of the image
                [img_height, img_width, ~] = size(app.ImageFile);
                % Scale the image display to match desired dimensions
                x_scale = app.scalefactorEditField.Value;%desired_width / img_width;
                y_scale = app.scalefactorEditField.Value; %desired_height / img_height;
                imagesc(app.UIAxes, [0, img_width * app.current_image_scaling_factor ], [0, img_height * app.current_image_scaling_factor ], app.ImageFile);
            end
        end

        % Button pushed function: PickReferenceButton
        function PickReferenceButtonPushed(app, event)
            delete(app.Reference_Points)
            delete(app.Reference_Points_text)
            try
                app.Reference_Points = drawline(app.UIAxes, 'Stripecolor',[1 1 1], 'Color', 'k', 'LineWidth', 1);
                a = app.Reference_Points.Position;
                app.Reference_distance = sqrt(sum((a(1,:)-a(2,:)).^2)); %
                angle = rad2deg(atan((a(2,2)-a(1,2))/(a(2,1)-a(1,1))));
                app.Reference_Points_text = text(app.UIAxes,mean(a(:,1)),mean(a(:,2)),[num2str(app.Reference_distance), ' ', app.LengthButtonGroup.SelectedObject.Text ],"FontSize",14,"HorizontalAlignment","center","VerticalAlignment","bottom", "Rotation", angle);
                app.Resize_reference = app.Reference_distance/app.PixelEditField.Value;
                app.LEditField.Value = app.Reference_distance;
                app.scalefactorEditField.Value = app.TargetEditField.Value/app.Reference_distance;
                app.scalefactorEditField_2.Value = app.scalefactorEditField.Value;
            catch
            end
        end

        % Button pushed function: ScaleImageButton
        function ScaleImageButtonPushed(app, event)
            if isempty(app.previous_image_scaling_factor)
                app.previous_image_scaling_factor=1;
            else
            end
            ComputingProgressBar(app, 'open')
            if isempty(app.ImageFile)
            else
                cla(app.UIAxes);
                % Get the size of the image
                [img_height, img_width, ~] = size(app.ImageFile);

                % Scale the image display to match desired dimensions

                app.current_image_scaling_factor = app.scalefactorEditField.Value*app.previous_image_scaling_factor;
                imagesc(app.UIAxes, [0, img_width * app.current_image_scaling_factor ], [0, img_height * app.current_image_scaling_factor ], app.ImageFile);
                app.previous_image_scaling_factor = app.scalefactorEditField.Value;
                %                 app.ImageFile = imresize(app.ImageFile ,app.scalefactorEditField.Value);
                %                 imagesc(app.UIAxes, app.ImageFile);
            end
            ResetViewButtonPushed(app)
            ComputingProgressBar(app, 'close')

        end

        % Button pushed function: ClearInteractiveAreaButton
        function ClearInteractiveAreaButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Callback function
        function ClearBlocksButtonPushed(app, event)
            cla(app.UIAxes);
            if ~isempty(app.ImageFile)
                %                 imagesc(app.UIAxes, app.ImageFile);
                % Get the size of the image
                [img_height, img_width, ~] = size(app.ImageFile);
                % Scale the image display to match desired dimensions
                x_scale = app.scalefactorEditField.Value;%desired_width / img_width;
                y_scale = app.scalefactorEditField.Value; %desired_height / img_height;
                imagesc(app.UIAxes, [0, img_width * app.current_image_scaling_factor ], [0, img_height * app.current_image_scaling_factor ], app.ImageFile);
            else
            end

            PlotSketchButtonPushed(app, event)

        end

        % Callback function
        function UpdateViewButtonPushed(app, event)
            cla(app.UIAxes);

            switch app.BackgroundImageSwitch_2.Value
                case 'Off'
                case 'On'
                    if ~isempty(app.ImageFile)
                        %                         imagesc(app.UIAxes, app.ImageFile);
                        % Get the size of the image
                        [img_height, img_width, ~] = size(app.ImageFile);
                        % Scale the image display to match desired dimensions
                        x_scale = app.scalefactorEditField.Value;%desired_width / img_width;
                        y_scale = app.scalefactorEditField.Value; %desired_height / img_height;
                        imagesc(app.UIAxes, [0, img_width * app.current_image_scaling_factor ], [0, img_height * app.current_image_scaling_factor ], app.ImageFile);
                    else
                    end
            end

            if ~isempty(app.Arch_region_Position)
                x = app.Arch_region_Position(:,1);
                y = app.Arch_region_Position(:,2);
                ps = polyshape(x,y);
                pg = plot(app.UIAxes,ps, "FaceColor" , [0.3,0.3,0.3], "EdgeColor" , [0.2,0.2,0.2], "LineWidth", 2);
            else
            end
        end

        % Callback function
        function LoadExample1MenuSelected(app, event)
            %
            fullname = ['C:\Users\giovanni.castellazzi\Dropbox\FEM\DOMES\GUI_demo', filesep, 'arch_example_1.png'];
            app.ImageFile = imread(fullname);
            app.ImageFile = flipdim(app.ImageFile ,1);
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal')
            set(app.UIAxes, 'Units', 'Pixels')
            imagesc(app.UIAxes, app.ImageFile);
            % Get the size of the image
            [img_height, img_width, ~] = size(app.ImageFile);
            % Scale the image display to match desired dimensions
            x_scale = app.scalefactorEditField.Value;%desired_width / img_width;
            y_scale = app.scalefactorEditField.Value; %desired_height / img_height;
            imagesc(app.UIAxes, [0, img_width * app.current_image_scaling_factor ], [0, img_height * app.current_image_scaling_factor ], app.ImageFile);
        end

        % Callback function
        function ButtonPushed(app, event)
            web([pwd, 'arch_definition.html'], '-browser')
        end

        % Button pushed function: DefinePointAButton, DefinePointAButton_2
        function DefinePointAButtonPushed(app, event)

            % select the point
            [app.xy_Point_A(1), app.xy_Point_A(2)] = selectDataPoints(app.UIAxes);
            Plot_point_A(app)

        end

        % Button pushed function: DefinePointBButton, DefinePointBButton_2
        function DefinePointBButtonPushed(app, event)
            % select the point
            [app.xy_Point_B(1), app.xy_Point_B(2)] = selectDataPoints(app.UIAxes);
            Plot_point_B(app)

        end

        % Button pushed function: PreliminarFOPButton
        function PreliminarFOPButtonPushed(app, event)
            app.TabGroup5.SelectedTab = app.ForcePolygonFPTab;
            cla(app.UIAxes2);
            sorted_Blocks_like(app)
            DrawingProgressBar(app, 'open')
            app.alpha_W_Blocks = app.alphaWEditField.Value;

%             arrow_end = -sum(app.W_Blocks(:)) - sum(app.Forces_applied);
            arrow_end = -sum(app.W_Blocks_like_sorted(:));
            xH =[0,app.DefineHorizontalThrustEditField.Value];
            yH = [arrow_end/2 ,arrow_end/2];
            app.xy_Pole_Prime = [xH' yH'];
            plot(app.UIAxes2,xH,yH, "Color",[0.3 0.3 0.3],"LineWidth",1, "LineStyle","--" );
            plot(app.UIAxes2, xH(2),yH(2),"Color",'k',"MarkerEdgeColor","auto","MarkerSize",4,"Marker","o");
            text(app.UIAxes2,xH(2),yH(2), '  O''',"FontSize",16,"HorizontalAlignment","left");
            title(app.UIAxes2, 'Preliminar Funicolar Polygon');

            count = 1;
            xO = xH(2);
            yO = yH(2);
            for GenerateLetter=1:20
                for letter='a':'z'
                    app.alphabet_letters(count) = letter;
                    count = count +1;
                end
                for letter='A':'Z'
                    app.alphabet_letters(count) = letter;
                    count = count +1;
                end
            end
            wyi = 0;
            wxi = 0;
            shrink = app.W_Blocks_like_sorted(1)*app.forcesfactorEditField.Value*app.ForceshrinkfactorEditField.Value;
            for jj=1:app.Number_of_Blocks_like
                wye = wyi-app.W_Blocks_like_sorted(jj);
                wxe = wxi; %+app.W_Blocks(jj)*app.alpha_W_Blocks; % THIS IF THE WEIGHT POSSESS ALSO AN HORIZONTAL COMPONENT (SEISMIC EVENT)
                v1(jj,:,:) = [ wxi, wyi; wxe, wye];
                v2(jj,:,:) = [v1(jj,2,1), v1(jj,2,2) ; xO, yO-shrink*(jj-1)];
                v3(jj,:,:) = [ v2(jj,2,1), v2(jj,2,2); v1(jj,1,1), v1(jj,1,2)];
                hArrow_v1 = drawArrow_Gs(app, app.UIAxes2,v1(jj,1,:),v1(jj,2,:), 'r');
                hArrow_v2 = drawArrow_Gs(app, app.UIAxes2,v2(jj,1,:),v2(jj,2,:), 'k');
                hArrow_v3 = drawArrow_Gs(app, app.UIAxes2,v3(jj,1,:),v3(jj,2,:), 'b');
                text(app.UIAxes2,v1(jj,1,1),v1(jj,1,2), [app.alphabet_letters(jj), '  '],"FontSize",16,"HorizontalAlignment","right");
                text(app.UIAxes2,mean(v3(jj,:,1)),mean(v3(jj,:,2)), ['  O''', app.alphabet_letters(jj), ''],"FontSize",16,"HorizontalAlignment","left");
                wyi = wye-shrink;
                % wxi = wxe-shrink;
                wxi = 0;
                app.Preliminar_Funicolar_Problem.u1(jj,:,:) = [ v1(jj,1,1)-v2(jj,2,1), v1(jj,1,2)-v2(jj,2,2)];
                app.Preliminar_Funicolar_Problem.u2(jj,:,:) = [ 0, 1];
            end

            text(app.UIAxes2,v1(jj,2,1),v1(jj,2,2), [app.alphabet_letters(jj+1), '  '],"FontSize",16,"HorizontalAlignment","right");
            text(app.UIAxes2,mean(v2(jj,:,1)),mean(v2(jj,:,2)), ['  O''', app.alphabet_letters(jj+1), ''],"FontSize",16,"HorizontalAlignment","left");
            app.Preliminar_Funicolar_Problem.u1(jj+1,:,:) = [ v2(jj,2,1)-v2(jj,1,1), v2(jj,2,2)-v2(jj,1,2)];
            app.Preliminar_Funicolar_Problem.u1(jj+1,:,:) = [  xO-v1(jj,2,1),  yO-shrink*(jj-1)- v1(jj,2,2)];
            app.Preliminar_Funicolar_Problem.u2(jj+1,:,:) = [ 0, 1];
            zoom(app.UIAxes2, 0.9);
            DrawingProgressBar(app, 'close');
            ResetViewButton_2Pushed(app);

        end

        % Callback function
        function LoadExample2MenuSelected(app, event)
            %             [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
            fullname = ['C:\Users\giovanni.castellazzi\Dropbox\FEM\DOMES\GUI_demo', filesep, 'arch_example_2.png'];
            app.ImageFile = imread(fullname);
            app.ImageFile = flipdim(app.ImageFile ,1);
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal')
            set(app.UIAxes, 'Units', 'Pixels')
            %             imagesc(app.UIAxes, app.ImageFile);
            % Get the size of the image
            [img_height, img_width, ~] = size(app.ImageFile);
            % Scale the image display to match desired dimensions
            x_scale = app.scalefactorEditField.Value;%desired_width / img_width;
            y_scale = app.scalefactorEditField.Value; %desired_height / img_height;
            imagesc(app.UIAxes, [0, img_width * app.current_image_scaling_factor ], [0, img_height * app.current_image_scaling_factor ], app.ImageFile);
        end

        % Button pushed function: PreliminarFUPButton
        function PreliminarFUPButtonPushed(app, event)
            %             app.Number_of_Blocks = app.SubdivideusingblocksnewaddEditField.Value;
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal')
            xA = app.xy_Point_A(1);
            yA = app.xy_Point_A(2);
            xB = app.xy_Point_B(1);
            yB = app.xy_Point_B(2);
            M1 = [xB, yB];
            for jj=1:(app.Number_of_Blocks_like+1)
                u1(1) = app.Preliminar_Funicolar_Problem.u1(jj,:,1);
                u1(2) = app.Preliminar_Funicolar_Problem.u1(jj,:,2);
                u2(1) = app.Preliminar_Funicolar_Problem.u2(jj,:,1);
                u2(2) = app.Preliminar_Funicolar_Problem.u2(jj,:,2);
                if jj < (app.Number_of_Blocks_like+1)
                    M2 = app.xyg_Blocks_like_sorted(jj,:);
%                 elseif jj >= (app.Number_of_Blocks_like+1) && jj < (app.Number_of_Blocks_like+app.Number_of_Forces+1)
%                     M2 = app.Forces_applied_xy(jj-app.Number_of_Blocks,:);
                else

                    M2 = [xA, yA];
                    u1 = -u1;
                end
%                 pippo=[M1;u1;M2;u2]
                [I, rc] = lines_intersection(M1, u1, M2, u2, ~true);
                if rc ==1
                    M1s = I(1,1:2);
                else
                end
                plot(app.UIAxes, [M1(1), M1s(1)],[M1(2), M1s(2)], "Color", [0.3 0.3 0.3], "LineWidth", 1, "LineStyle","--")
                switch app.TextLabelsSwitch.Value
                    case 'Off'
                    case 'On'
                        text(app.UIAxes,mean([M1(1), M1s(1)]),mean([M1(2), M1s(2)]), ['O''', app.alphabet_letters(jj), '  '],"FontSize",16,"HorizontalAlignment","right", "Color",[0.3 0.3 0.3]);
                end
                M1 = [M1s(1), M1s(2)];

            end
            plot(app.UIAxes, [xB, M1s(1)],[yB, M1s(2)], "Color", 'r', "LineWidth", 1, "LineStyle","--")
            switch app.TextLabelsSwitch.Value
                case 'Off'
                case 'On'
                    text(app.UIAxes,mean([xB, M1s(1)]),mean([yB, M1s(2)]), [' x''z'' '],"FontSize",16,"HorizontalAlignment","right", "Color",'r', "VerticalAlignment","bottom");
            end

            M1 = app.xy_Pole_Prime(2,:);
            u1 = [xB-M1s(1), yB-M1s(2)];
            M2 = app.xy_Pole_Prime(1,:);%[0, 0];
            u2 = [0 -1];
            [I, rc] = lines_intersection(M1, u1, M2, u2, ~true);
            Pole_projection = I(1,1:2);
            plot(app.UIAxes2, [app.xy_Pole_Prime(2,1), Pole_projection(1)],[app.xy_Pole_Prime(2,2), Pole_projection(2)], "Color", 'r', "LineWidth", 2, "LineStyle","--")
            xy_P = [Pole_projection(1), Pole_projection(2)];
            text(app.UIAxes2,xy_P(1), xy_P(2), [' p '],"FontSize",16,"HorizontalAlignment","right", "Color",'r', "VerticalAlignment","bottom");

            plot(app.UIAxes, [xB, xA],[yB, yA], "Color", 'b', "LineWidth", 1, "LineStyle","--")
            switch app.TextLabelsSwitch.Value
                case 'Off'
                case 'On'
                    text(app.UIAxes,mean([xB, xA]),mean([yB, yA]), [' AB '],"FontSize",16,"HorizontalAlignment","right", "Color",'b', "VerticalAlignment","top");
            end
            M1 = xy_P;
            u1 = [xB-xA, yB-yA];
            M2 = app.xy_Pole_Prime(2,:);%[0, 0];
            u2 = [0 -1];
            [I, rc] = lines_intersection(M1, u1, M2, u2, ~true);
            Pole_projection = I(1,1:2);
            plot(app.UIAxes2, [xy_P(1), Pole_projection(1)],[xy_P(2), Pole_projection(2)], "Color", 'b', "LineWidth", 2, "LineStyle","--")
            app.xy_Pole_Def = Pole_projection;

        end

        % Button pushed function: FinalFOPButton
        function FinalFOPButtonPushed(app, event)
            %             app.Number_of_Blocks = app.SubdivideusingblocksnewaddEditField.Value;
            y = 0;
            cla(app.UIAxes2);
            hold(app.UIAxes2, 'on');
            axis(app.UIAxes2, 'equal')
            for kk=1:app.Number_of_Blocks_like
                arrow_end = y-app.W_Blocks_like_sorted(kk);
                y = arrow_end;

            end
            xH =[0,app.DefineHorizontalThrustEditField.Value];

            yH = [arrow_end/2 ,arrow_end/2];
            app.xy_Pole_Prime = [xH' yH'];
            plot(app.UIAxes2,xH,yH, "Color",[0.3 0.3 0.3],"LineWidth",1, "LineStyle","--" );
            plot(app.UIAxes2, xH(2),yH(2),"Color",'k',"MarkerEdgeColor","auto","MarkerSize",4,"Marker","o");
            text(app.UIAxes2,xH(2),yH(2), '  O''',"FontSize",16,"HorizontalAlignment","left");
            title(app.UIAxes2, 'Preliminar Funicolar Polygon');

            count = 1;
            xO = xH(2);
            yO = yH(2);
            for letter='a':'z'
                app.alphabet_letters(count) = letter;
                count = count +1;
            end
            wyi = 0;
            shrink = app.W_Blocks_like_sorted(1)*app.forcesfactorEditField.Value*app.ForceshrinkfactorEditField.Value;
            for jj=1:app.Number_of_Blocks_like
                wye = wyi-app.W_Blocks_like_sorted(jj);
                v1(jj,:,:) = [ 0, wyi; 0, wye];
                v2(jj,:,:) = [v1(jj,2,1), v1(jj,2,2) ; xO, yO-shrink*(jj-1)];
                v3(jj,:,:) = [ v2(jj,2,1), v2(jj,2,2); v1(jj,1,1), v1(jj,1,2)];
                hArrow_v1 = drawArrow_Gs(app, app.UIAxes2,v1(jj,1,:),v1(jj,2,:), 'r');
                hArrow_v2 = drawArrow_Gs(app, app.UIAxes2,v2(jj,1,:),v2(jj,2,:), [0.7 0.7 0.7]);
                hArrow_v3 = drawArrow_Gs(app, app.UIAxes2,v3(jj,1,:),v3(jj,2,:), [0.7 0.7 0.7]);
                text(app.UIAxes2,v1(jj,1,1),v1(jj,1,2), [app.alphabet_letters(jj), '  '],"FontSize",16,"HorizontalAlignment","right");
                text(app.UIAxes2,mean(v3(jj,:,1)),mean(v3(jj,:,2)), ['  O''', app.alphabet_letters(jj), ''],"FontSize",16,"HorizontalAlignment","left");
                wyi = wye-shrink;
            end
            text(app.UIAxes2,v1(jj,2,1),v1(jj,2,2), [app.alphabet_letters(jj+1), '  '],"FontSize",16,"HorizontalAlignment","right");
            text(app.UIAxes2,mean(v2(jj,:,1)),mean(v2(jj,:,2)), ['  O''', app.alphabet_letters(jj+1), ''],"FontSize",16,"HorizontalAlignment","left");

            yH = [app.xy_Pole_Def(2) , app.xy_Pole_Def(2) ];
            plot(app.UIAxes2,xH,yH, "Color",'b',"LineWidth",1, "LineStyle","--" );
            plot(app.UIAxes2, xH(2),yH(2),"Color",'k',"MarkerEdgeColor","auto","MarkerSize",4,"Marker","o");
            text(app.UIAxes2,xH(2),yH(2), '  O',"FontSize",16,"HorizontalAlignment","left");

            title(app.UIAxes2, 'Final Funicolar Polygon');

            count = 1;
            xO = xH(2);
            yO = yH(2);
            
            for GenerateLetter=1:20
                for letter='a':'z'
                    app.alphabet_letters(count) = letter;
                    count = count + 1;
                end
                for letter='A':'Z'
                    app.alphabet_letters(count) = letter;
                    count = count + 1;
                end
            end
            wyi = 0;
            shrink = app.W_Blocks_like_sorted(1)*app.forcesfactorEditField.Value*app.ForceshrinkfactorEditField.Value;
            for jj=1:app.Number_of_Blocks_like
                wye = wyi-app.W_Blocks_like_sorted(jj);
                v1(jj,:,:) = [ 0, wyi; 0, wye];
                v2(jj,:,:) = [v1(jj,2,1), v1(jj,2,2) ; xO, yO-shrink*(jj-1)];
                v3(jj,:,:) = [ v2(jj,2,1), v2(jj,2,2); v1(jj,1,1), v1(jj,1,2)];
                hArrow_v1 = drawArrow_Gs(app, app.UIAxes2,v1(jj,1,:),v1(jj,2,:), 'r');
                hArrow_v2 = drawArrow_Gs(app, app.UIAxes2,v2(jj,1,:),v2(jj,2,:), 'k');
                hArrow_v3 = drawArrow_Gs(app, app.UIAxes2,v3(jj,1,:),v3(jj,2,:), 'b');
                app.Force_Funicolar_Polygon(jj,1) = [sqrt((v1(jj,1,1)-v1(jj,2,1))^2+(v1(jj,1,2)-v1(jj,2,2))^2)];
                app.Force_Funicolar_Polygon(jj,2) = [sqrt((v2(jj,1,1)-v2(jj,2,1))^2+(v2(jj,1,2)-v2(jj,2,2))^2)];
                app.Force_Funicolar_Polygon(jj,3) = [sqrt((v3(jj,1,1)-v3(jj,2,1))^2+(v3(jj,1,2)-v3(jj,2,2))^2)];
                text(app.UIAxes2,mean(v3(jj,:,1)),mean(v3(jj,:,2)), ['  O', app.alphabet_letters(jj), ''],"FontSize",16,"HorizontalAlignment","right", "VerticalAlignment","top");
                wyi = wye-shrink;
                app.Final_Funicolar_Problem.u1(jj,:,:) = [ v1(jj,1,1)-v2(jj,2,1), v1(jj,1,2)-v2(jj,2,2)];
                app.Final_Funicolar_Problem.u2(jj,:,:) = [ 0, 1];
                if jj==1
                    app.Reaction_at_B = [ v2(jj,2,1), v2(jj,2,2); v1(jj,1,1), v1(jj,1,2)];
                elseif jj==app.Number_of_Blocks_like
                    app.Reaction_at_A = [v1(jj,2,1), v1(jj,2,2) ; xO, yO-shrink*(jj-1)];
                end
            end
            text(app.UIAxes2,mean(v2(jj,:,1)),mean(v2(jj,:,2)), ['O', app.alphabet_letters(jj+1), '  '],"FontSize",16,"HorizontalAlignment","right", "VerticalAlignment","top");
            app.Final_Funicolar_Problem.u1(jj+1,:,:) = [ v2(jj,2,1)-v2(jj,1,1), v2(jj,2,2)-v2(jj,1,2)];
            app.Final_Funicolar_Problem.u1(jj+1,:,:) = [  xO-v1(jj,2,1),  yO-shrink*(jj-1)- v1(jj,2,2)];
            app.Final_Funicolar_Problem.u2(jj+1,:,:) = [ 0, 1];


        end

        % Button pushed function: FinalFUPButton
        function FinalFUPButtonPushed(app, event)
            %             app.Number_of_Blocks = app.SubdivideusingblocksnewaddEditField.Value;
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal')
            xA = app.xy_Point_A(1);
            yA = app.xy_Point_A(2);
            xB = app.xy_Point_B(1);
            yB = app.xy_Point_B(2);
            M1 = [xB, yB];
            max_F1 = max(app.Force_Funicolar_Polygon(:,1));
            max_F2 = max(app.Force_Funicolar_Polygon(:,2));
            max_F3 = max(app.Force_Funicolar_Polygon(:,3));
            max_F = max([max_F1, max_F2, max_F3]);
            Normalized_F1 = app.Force_Funicolar_Polygon(:,1)/max_F;
            Normalized_F2 = app.Force_Funicolar_Polygon(:,2)/max_F;
            Normalized_F3 = app.Force_Funicolar_Polygon(:,3)/max_F;
            LOT = [];
            count =1;
            for jj=1:app.Number_of_Blocks_like+1
                u1(1) = app.Final_Funicolar_Problem.u1(jj,:,1);
                u1(2) = app.Final_Funicolar_Problem.u1(jj,:,2);
                u2(1) = app.Final_Funicolar_Problem.u2(jj,:,1);
                u2(2) = app.Final_Funicolar_Problem.u2(jj,:,2);
                if jj < app.Number_of_Blocks_like+1
                    M2 = app.xyg_Blocks_like_sorted(jj,:);
                else

                    M2 = [xA, yA];
                    u1 = -u1;
                end
                %                 pippo=[M1;u1;M2;u2]
                [I, rc] = lines_intersection(M1, u1, M2, u2, ~true);
                if rc ==1
                    M1s = I(1,1:2);
                else
                end
                if jj > app.Number_of_Blocks_like
                    patchline(app.UIAxes, [M1(1), M1s(1)],[M1(2), M1s(2)],'edgecolor','r','linewidth',Normalized_F2(app.Number_of_Blocks_like)*app.LOTForcefactorEditField.Value,'edgealpha',0.5);
                    LOT(count) = app.Force_Funicolar_Polygon(app.Number_of_Blocks_like,2);
                    app.LOT_xy(count,:) = [M1(1), M1(2)];
                else
                    patchline(app.UIAxes, [M1(1), M1s(1)],[M1(2), M1s(2)],'edgecolor','r','linewidth',Normalized_F3(jj)*app.LOTForcefactorEditField.Value,'edgealpha',0.5);
                    LOT(count) = app.Force_Funicolar_Polygon(jj,3);
                    app.LOT_xy(count,:) = [M1(1), M1(2)];
                end
                plot(app.UIAxes, [M1(1), M1s(1)],[M1(2), M1s(2)], "Color", 'k', "LineWidth", 2 , "LineStyle","--")
                switch app.TextLabelsSwitch.Value
                    case 'Off'
                    case 'On'
                        text(app.UIAxes,mean([M1(1), M1s(1)]),mean([M1(2), M1s(2)]), ['   O', app.alphabet_letters(jj), ''],"FontSize",16,"HorizontalAlignment","left", "Color",[0.1 0.1 0.1], "VerticalAlignment","top");
                end
                M1 = [M1s(1), M1s(2)];
                count = count +1;
                app.LOT_xy(count,:) = [M1s(1), M1s(2)];
            end

            app.UITable2.Data = [];
            app.UITable2.Data = [[1:1:app.Number_of_Blocks_like+1]',LOT'];
            app.LOT_Force = LOT;

            M1 = app.xy_Pole_Prime(2,:);
            u1 = [xB-M1s(1), yB-M1s(2)];
            M2 = app.xy_Pole_Prime(1,:);%[0, 0];
            u2 = [0 -1];
            [I, rc] = lines_intersection(M1, u1, M2, u2, ~true);
            Pole_projection = I(1,1:2);
            plot(app.UIAxes2, [app.xy_Pole_Prime(2,1), Pole_projection(1)],[app.xy_Pole_Prime(2,2), Pole_projection(2)], "Color", 'r', "LineWidth", 2, "LineStyle","--")
            app.xy_Pole_Def = Pole_projection;


        end

        % Button pushed function: AddrectangularblockButton
        function AddrectangularblockButtonPushed(app, event)
            % Check the consistency of blocks
            if size(app.Blocks_coordinates_4_points, 1) == size(app.Number_of_Blocks, 1)
                % All is consistent, do nothing
            else
                remove = [];
                for ii = 1:size(app.Blocks_coordinates_4_points, 1)
                    if size(app.Blocks_coordinates_4_points{ii}, 2) < 4
                        remove = [remove, ii];  % Collect indices of rows to remove
                    end
                end

                % Remove rows that are inconsistent (not having 4 entries)
                app.Blocks_coordinates_4_points(remove, :) = [];
            end
            P = app.Blocks_coordinates_4_points;
            count = size(P,1);
            try
                for kk=1:4
                    [x, y] = selectDataPoints(app.UIAxes);
                    x_draw = x;
                    y_draw = y;
                    % snap to existing
                    if strcmp(app.SnapSwitch.Value, 'On')  % Check if SnapSwitch is 'On'
                        if size(app.Blocks_coordinates_4_points,1)>0
                            X_1 = app.Blocks_coordinates_4_points{1, 1}(1);
                            X_2 = app.Blocks_coordinates_4_points{1, 1}(2);
                            Y_1 = app.Blocks_coordinates_4_points{1, 2}(1);
                            Y_2 = app.Blocks_coordinates_4_points{1, 2}(2);
                            tolerance = sqrt((X_1 - X_2)^2 + (Y_1 - Y_2)^2)/8;
                        else
                            tolerance = 0.01;  % Define a snapping tolerance (adjust as necessary)
                        end

                        snapped = false;   % Flag to check if a point has been snapped

                        % Loop through all coordinates in the matrix
                        for ii = 1:size(app.Blocks_coordinates_4_points, 1)
                            for jj = 1:size([app.Blocks_coordinates_4_points{ii,1}], 2)
                                % Get x and y coordinates from the matrix
                                X_snap = app.Blocks_coordinates_4_points{ii, 1}(jj);  % X coordinates
                                Y_snap = app.Blocks_coordinates_4_points{ii, 2}(jj);  % Y coordinates

                                % Calculate the Euclidean distance between P(x, y) and the matrix point
                                distance = sqrt((X_snap - x)^2 + (Y_snap - y)^2);

                                % Check if the distance is within the snapping tolerance
                                if distance < tolerance
                                    X(kk) = X_snap;  % Snap the x-coordinate
                                    Y(kk) = Y_snap;  % Snap the y-coordinate
                                    x_draw = X_snap;
                                    y_draw = Y_snap;
                                    snapped = true;
                                    break;  % Exit the loop once snapping is done
                                end
                            end
                        end

                        % If no snapping occurred, keep the original coordinates
                        if ~snapped
                            X(kk) = x;
                            Y(kk) = y;
                        end

                    else
                        % If snapping is off, just keep the original coordinates
                        X(kk) = x;
                        Y(kk) = y;
                    end
                    app.xy_Center(1) = x_draw;
                    app.xy_Center(2) = y_draw;
                    hold(app.UIAxes, 'on');
                    axis(app.UIAxes, 'equal')
                    plot(app.UIAxes, x_draw,y_draw,"Color",'r',"MarkerSize",2,"Marker","x")
                    text(app.UIAxes,x_draw,y_draw,[' ', num2str(kk)],"FontSize",16,"HorizontalAlignment","left")


                end
                app.Number_of_Blocks = count +1;
                count = count +1;

                app.Blocks_coordinates_4_points{count,1} = X;
                app.Blocks_coordinates_4_points{count,2} = Y;

                kk = count;

                ps = polyshape(X,Y);
                pg = plot(app.UIAxes,ps);
                [xg,yg] = centroid(ps);

                plot(app.UIAxes,xg,yg,"Color",'k', "MarkerEdgeColor","auto", "Marker","o","MarkerSize",5)
                app.A_Blocks(count) = area(ps)*app.Unit_Length_scaling*app.Unit_Length_scaling;
                app.Thickness_Blocks(count) = app.ThicknessEditField.Value;
                app.W_Blocks(count) = app.A_Blocks(count)*app.SpecificWeightEditField.Value*app.Thickness_Blocks(count)*app.Unit_Mass_to_Weight;

                app.xyg_Blocks = [app.xyg_Blocks ; xg', yg'];

                sorting_Blocks(app)
                
                update_Table2(app)
            catch
            end
            ClearInteractiveAreaButtonPushed(app, event)
            PlotblocksButton_2Pushed(app, event)
            app.TabGroup5.SelectedTab = app.DViewTab;
            PlotGeometryButtonPushed(app, event)
        end

        % Button pushed function: PlotblocksButton_2
        function PlotblocksButton_2Pushed(app, event)
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal')

            if isempty(app.ImageFile)
            else

                switch app.BackgroundImageSwitch_2.Value
                    case 'Off'

                    case 'On'
                        %                         imagesc(app.UIAxes, app.ImageFile);
                        % Get the size of the image
                        [img_height, img_width, ~] = size(app.ImageFile);
                        % Scale the image display to match desired dimensions
                        x_scale = app.scalefactorEditField.Value;%desired_width / img_width;
                        y_scale = app.scalefactorEditField.Value; %desired_height / img_height;
                        imagesc(app.UIAxes, [0, img_width * app.current_image_scaling_factor ], [0, img_height * app.current_image_scaling_factor ], app.ImageFile);
                end
            end
            if isempty(app.ImageFile)
            else

                switch app.SketchedGeometrySwitch.Value
                    case 'Off'

                    case 'On'
                        PlotSketchButtonPushed(app,event)
                end
            end

            brickColors = [
                244, 177, 131;  % Light brick
                239, 139, 71;  % Medium light brick
                248, 203, 173; % Slightly darker brick
                251, 229, 214;  % Very light brick
                ]./255;

            if app.Number_of_Blocks > 0
                for kk = 1:app.Number_of_Blocks_like
                    if app.Blocks_like_kind(kk) == 0 % 0 is a block - 1 is a force
                        x = app.Blocks_coordinates_4_points_like{kk,1};
                        y = app.Blocks_coordinates_4_points_like{kk,2};
                        ps = polyshape(x, y);

                        % Randomly select a color from the brick palette
                        colorIdx = randi(size(brickColors, 1));  % Random index from 1 to number of colors
                        blockColor = brickColors(colorIdx, :);  % Pick the color

                        % Plot the block with the chosen color
                        pg = plot(app.UIAxes, ps, "FaceColor", blockColor, "EdgeColor", "k", "LineWidth", 1);

                        % Calculate and plot the centroid
                        [xg(kk), yg(kk)] = centroid(ps);
                        plot(app.UIAxes, xg(kk), yg(kk), "Color", [0 0 0], "MarkerEdgeColor", [0 0 0], ...
                            "Marker", "o", "MarkerSize", 5, "MarkerFaceColor", [0 0 0]);

                        % Add a label near the centroid
                        text(app.UIAxes, xg(kk), yg(kk), [' ', num2str(kk)], "FontSize", 16, ...
                            "HorizontalAlignment", "left", "FontWeight", "bold");
                    end
                end
            else
                % Clear the axes if no blocks
                cla(app.UIAxes);
                if ~isempty(app.ImageFile)
                    % Display the image with scaling
                    [img_height, img_width, ~] = size(app.ImageFile);
                    x_scale = app.scalefactorEditField.Value;
                    y_scale = app.scalefactorEditField.Value;
                    imagesc(app.UIAxes, [0, img_width * app.current_image_scaling_factor], ...
                        [0, img_height * app.current_image_scaling_factor], app.ImageFile);
                end
            end
            ResetViewButtonPushed(app, event)
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            value = app.LEditField.Value;

        end

        % Button pushed function: ShowStructuralscaleButton
        function ShowStructuralscaleButtonPushed(app, event)

            nXLen = app.UIAxes.XLim(2)-app.UIAxes.XLim(1);

            if nXLen < 1
                nXLen_new = 10^(numel(num2str(nXLen)));
            else
                nXLen_new = 10^(floor(log10(nXLen)));
            end
            nXLen = nXLen_new;
            nYLen = app.UIAxes.YLim(2)-app.UIAxes.YLim(1);
            DeltaY = abs(nYLen/50/2)*app.Unit_Length_scaling;
            DeltaX = abs(nXLen/2/3)*app.Unit_Length_scaling;

            color_bar = [1 0 ;  0 1 ; 1 0;  0 1 ; 1 0;  0 1 ; 1 0;  0 1 ; 1 0;  0 1 ; 1 0];


            X_Limit = get(app.UIAxes, 'XLim');
            X_dim = abs(X_Limit(1)-X_Limit(2));
            Y_Limit = get(app.UIAxes, 'YLim');
            Y_dim = abs(Y_Limit(1)-Y_Limit(2));
            X_cord = X_Limit(1);
            Y_cord = Y_Limit(1);
            if X_cord > 0
                c1 = 1.05;
            else
                c1 = 0.85;
            end
            if Y_cord > 0
                c2 = 1.25;
            else
                c2 = 0.75;
            end
            X = c1*X_cord; %c1*nPos(3);
            Y = c2*Y_cord; %c2*nPos(4);
            Nbars = 3;
            for jj=1:Nbars

                for kk=1:2
                    Rect = [X+(jj-1)*DeltaX, Y+(kk-1)*DeltaY, DeltaX, DeltaY, ];
                    rectangle(app.UIAxes,'Position',Rect,'FaceColor',[color_bar(jj,kk),color_bar(jj,kk),color_bar(jj,kk)],'EdgeColor','k',  'LineWidth',1)

                end

            end

            text(app.UIAxes,Rect(1)+Rect(3),Rect(2),[' | ' num2str(DeltaX*Nbars), ' ', app.LengthButtonGroup.SelectedObject.Text ],"FontSize",16,"HorizontalAlignment","left", "VerticalAlignment","middle")

        end

        % Button pushed function: ShowForceScaleButton
        function ShowForceScaleButtonPushed(app, event)
            %             set(h, 'Units', 'Points');
            nUnits = get(app.UIAxes2, 'Units');
            nPos = get(app.UIAxes2, 'Position');
            nXLen = nPos(3) - nPos(1);
            nYLen = nPos(4) - nPos(2);
            nXLen = app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1);
            if nXLen < 1
                nXLen = 10^(numel(num2str(nXLen)));
            else
                nXLen_new = 10^(floor(log10(nXLen)));
            end
            nXLen = nXLen_new;
            nYLen = app.UIAxes2.YLim(2)-app.UIAxes2.YLim(1);
            DeltaY = abs(nYLen/50/2);
            DeltaX = abs(nXLen/2/3);

            color_bar = [1 0 ;  0 1 ; 1 0;  0 1 ; 1 0;  0 1 ; 1 0;  0 1 ; 1 0;  0 1 ; 1 0];
            %             color_bar = [1 0 ;  0 1 ; 1 0]*0+1;
            X_Limit = get(app.UIAxes2, 'XLim');
            X_dim = abs(X_Limit(1)-X_Limit(2));
            Y_Limit = get(app.UIAxes2, 'YLim');
            Y_dim = abs(Y_Limit(1)-Y_Limit(2));
            X_cord = X_Limit(1);
            Y_cord = Y_Limit(1);
            if X_cord > 0
                c1 = 0.20;
                c2 = 1.05;
            else
                c1 = 0.20;
                c2 = 0.95;
            end
            X = c1*X_dim; %c1*nPos(3);
            Y = c2*Y_cord; %c2*nPos(4);
            Nbars = 3;
            for jj=1:Nbars

                for kk=1:2
                    Rect = [X+(jj-1)*DeltaX, Y+(kk-1)*DeltaY, DeltaX, DeltaY, ];
                    rectangle(app.UIAxes2,'Position',Rect,'FaceColor',[color_bar(jj,kk),color_bar(jj,kk),color_bar(jj,kk)],'EdgeColor','k',  'LineWidth',1)

                end

            end

            text(app.UIAxes2,Rect(1)+Rect(3),Rect(2),[' | ' num2str(DeltaX*Nbars) ' ', app.ForceButtonGroup.SelectedObject.Text ],"FontSize",16,"HorizontalAlignment","left", "VerticalAlignment","middle")

        end

        % Button pushed function: LoadblocksButton
        function LoadblocksButtonPushed(app, event)


        end

        % Callback function
        function LoadExample2Menu_2Selected(app, event)
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal')
            app.forcesfactorEditField.Value = 0.01;
            app.LOTForcefactorEditField.Value = 15;
            app.DefineHorizontalThrustEditField.Value = 12700*app.SpecificWeightEditField.Value;
            cla(app.UIAxes);
            app.Blocks_coordinates_4_points{1,1} = [534.3243  672.8556  626.2699  547.8096];
            app.Blocks_coordinates_4_points{1,2} = [469.4226  469.4226  655.7657  612.8577];

            app.Blocks_coordinates_4_points{2,1} = [547.8096  626.2699  557.6172  499.9979];
            app.Blocks_coordinates_4_points{2,2} = [612.8577  655.7657  782.0377  664.3473];

            app.Blocks_coordinates_4_points{3,1} = [499.9979  557.6172  426.4414  415.4079];
            app.Blocks_coordinates_4_points{3,2} = [663.1213  782.0377  818.8159  701.1255];

            app.Blocks_coordinates_4_points{4,1} = [323.4623  415.4079  425.2155  220.4833];
            app.Blocks_coordinates_4_points{4,2} = [694.9958  701.1255  817.5900  783.2636];

            app.Blocks_coordinates_4_points{5,1} = [274.4247  322.2364  220.4833  175.1234];
            app.Blocks_coordinates_4_points{5,2} = [637.3766  694.9958  783.2636  642.2803];

            app.Blocks_coordinates_4_points{6,1} = [236.4205  275.6506  171.4456  108.9226];
            app.Blocks_coordinates_4_points{6,2} = [546.6569  636.1506  642.2803  558.9163];

            app.Blocks_coordinates_4_points{7,1} = [227.8389  236.4205  108.9226  121.1820];
            app.Blocks_coordinates_4_points{7,2} = [468.1967  546.6569  558.9163  468.1967];

            app.Number_of_Blocks = size(app.Blocks_coordinates_4_points,1);
            for kk=1:app.Number_of_Blocks
                x = app.Blocks_coordinates_4_points{kk,1};
                y = app.Blocks_coordinates_4_points{kk,2};
                ps = polyshape(x,y);
                pg = plot(app.UIAxes,ps);
                [xg(kk),yg(kk)] = centroid(ps);
                app.A_Blocks(kk) = area(ps);
                app.Thickness_Blocks(kk) = app.ThicknessEditField.Value;
                app.W_Blocks(kk) = area(ps)*app.SpecificWeightEditField.Value*app.Thickness_Blocks(kk);
            end
            app.xyg_Blocks = [xg', yg'];
        end

        % Button pushed function: DeleteallButton
        function DeleteallButtonPushed(app, event)
            app.UITable.Data = [];
            app.Blocks_coordinates_4_points = [];
            app.A_Blocks = [];
            app.W_Blocks = [];
            app.Thickness_Blocks = [];
            app.xyg_Blocks = [];
            app.Number_of_Blocks = 0;
            update_Blocks_like(app);
            cla(app.UIAxes);
            if ~isempty(app.ImageFile)
                %                 imagesc(app.UIAxes, app.ImageFile);
                % Get the size of the image
                [img_height, img_width, ~] = size(app.ImageFile);
                % Scale the image display to match desired dimensions
                x_scale = app.scalefactorEditField.Value;%desired_width / img_width;
                y_scale = app.scalefactorEditField.Value; %desired_height / img_height;
                imagesc(app.UIAxes, [0, img_width * app.current_image_scaling_factor ], [0, img_height * app.current_image_scaling_factor ], app.ImageFile);
            else
            end
            if ~isempty(app.Arch_region_Position)
                x = app.Arch_region_Position(:,1);
                y = app.Arch_region_Position(:,2);
                ps = polyshape(x,y);
                pg = plot(app.UIAxes,ps, "FaceColor" , [0.3,0.3,0.3], "EdgeColor" , [0.2,0.2,0.2], "LineWidth", 2);
            else
            end
        end

        % Button pushed function: DeletebyNButton
        function DeletebyNButtonPushed(app, event)
            if app.NEditField.Value <= app.Number_of_Blocks
                iBlocks = [1:app.Number_of_Blocks];
                iBlocks_new = setdiff(iBlocks, app.NEditField.Value);
                AB = app.A_Blocks;
                app.A_Blocks = [];
                app.A_Blocks = AB(iBlocks_new);
                TB = app.Thickness_Blocks;
                app.Thickness_Blocks = [];
                app.Thickness_Blocks = TB(iBlocks_new);
                WB = app.W_Blocks;
                app.W_Blocks = [];
                app.W_Blocks = WB(iBlocks_new);
                XY = app.xyg_Blocks;
                app.xyg_Blocks = [];
                app.xyg_Blocks = XY(iBlocks_new,:);

                old = app.Blocks_coordinates_4_points;
                app.Blocks_coordinates_4_points = [];
                for jj = 1:length(iBlocks_new)
                    app.Blocks_coordinates_4_points{jj,1} = old{iBlocks_new(jj),1};
                    app.Blocks_coordinates_4_points{jj,2} = old{iBlocks_new(jj),2};
                end
                app.Number_of_Blocks = length(iBlocks_new);
                sorting_Blocks(app)
                update_Blocks_like(app)
                update_Table2(app);
                ClearInteractiveAreaButtonPushed(app, event)
                PlotblocksButton_2Pushed(app, event)
            else
            end

        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;

        end

        % Callback function
        function LoadExample1Menu_2Selected(app, event)

            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal')
            app.forcesfactorEditField.Value = 0.01;
            app.LOTForcefactorEditField.Value = 15;
            app.DefineHorizontalThrustEditField.Value = 10300*app.SpecificWeightEditField.Value;
            cla(app.UIAxes);
            app.Blocks_coordinates_4_points{1,1} = [  697.4833  791.6953  780.3898  696.5411];
            app.Blocks_coordinates_4_points{1,2} = [34.0560   34.9981  218.7115  194.2164];

            app.Blocks_coordinates_4_points{2,1} = [ 651.3194  696.5411  779.4477  701.2517];
            app.Blocks_coordinates_4_points{2,2} = [287.4862  195.1585  219.6536  325.1710];

            app.Blocks_coordinates_4_points{3,1} = [575.9498  650.3773  701.2517  595.7343];
            app.Blocks_coordinates_4_points{3,2} = [359.0874  288.4284  326.1132  398.6564];

            app.Blocks_coordinates_4_points{4,1} = [477.9693  575.9498  594.7922  486.4484];
            app.Blocks_coordinates_4_points{4,2} = [395.8300  359.0874  399.5985  430.6885];

            app.Blocks_coordinates_4_points{5,1} = [422.3842  477.9693  485.5063  423.3264];
            app.Blocks_coordinates_4_points{5,2} = [401.4827  396.7721  432.5727  437.2833];

            app.Blocks_coordinates_4_points{6,1} = [  370.5676  422.3842  422.3842  363.0307];
            app.Blocks_coordinates_4_points{6,2} = [398.6564  402.4249  437.2833  434.4569];

            app.Blocks_coordinates_4_points{7,1} = [273.5293  369.6255  363.0307  247.1499];
            app.Blocks_coordinates_4_points{7,2} = [ 360.0295  398.6564  435.3991  397.7143];

            app.Blocks_coordinates_4_points{8,1} = [195.3333  272.5872  247.1499  145.4010];
            app.Blocks_coordinates_4_points{8,2} = [ 290.3126  360.0295  397.7143  328.9395];

            app.Blocks_coordinates_4_points{9,1} = [ 144.4589  194.3912  146.3431   62.4944];
            app.Blocks_coordinates_4_points{9,2} = [ 193.2742  291.2547  328.9395  214.9430];

            app.Blocks_coordinates_4_points{10,1} = [44.5941  143.5167  143.5167   61.5523];
            app.Blocks_coordinates_4_points{10,2} = [27.0697   29.8961  192.8828  215.4937];

            app.Number_of_Blocks = size(app.Blocks_coordinates_4_points,1);

            for kk=1:app.Number_of_Blocks
                x = app.Blocks_coordinates_4_points{kk,1};
                y = app.Blocks_coordinates_4_points{kk,2};

                ps = polyshape(x,y);
                pg = plot(app.UIAxes,ps);
                [xg(kk),yg(kk)] = centroid(ps);

                app.A_Blocks(kk) = area(ps);
                app.Thickness_Blocks(kk) = app.ThicknessEditField.Value;
                app.W_Blocks(kk) = area(ps)*app.SpecificWeightEditField.Value*app.Thickness_Blocks(kk);
            end

            app.xyg_Blocks = [xg', yg'];
        end

        % Button pushed function: AddirregularBlockButton
        function AddirregularBlockButtonPushed(app, event)
            % Check the consistency of blocks
            if size(app.Blocks_coordinates_4_points, 1) == size(app.Number_of_Blocks, 1)
                % All is consistent, do nothing
            else
                remove = [];
                for ii = 1:size(app.Blocks_coordinates_4_points, 1)
                    if size(app.Blocks_coordinates_4_points{ii}, 2) < 4
                        remove = [remove, ii];  % Collect indices of rows to remove
                    end
                end

                % Remove rows that are inconsistent (not having 4 entries)
                app.Blocks_coordinates_4_points(remove, :) = [];
            end

            P = app.Blocks_coordinates_4_points;
            count = size(P,1);

            for kk=1:app.NofpointsEditField.Value

                [x, y] = selectDataPoints(app.UIAxes);
                x_draw = x;
                y_draw = y;
                % snap to existing
                if strcmp(app.SnapSwitch.Value, 'On')  % Check if SnapSwitch is 'On'
                    if size(app.Blocks_coordinates_4_points,1)>0
                        X_1 = app.Blocks_coordinates_4_points{1, 1}(1);
                        X_2 = app.Blocks_coordinates_4_points{1, 1}(2);
                        Y_1 = app.Blocks_coordinates_4_points{1, 2}(1);
                        Y_2 = app.Blocks_coordinates_4_points{1, 2}(2);
                        tolerance = sqrt((X_1 - X_2)^2 + (Y_1 - Y_2)^2)/20;
                    else
                        tolerance = 0.01;  % Define a snapping tolerance (adjust as necessary)
                    end

                    snapped = false;   % Flag to check if a point has been snapped

                    % Loop through all coordinates in the matrix
                    for ii = 1:size(app.Blocks_coordinates_4_points, 1)
                        for jj = 1:size([app.Blocks_coordinates_4_points{ii,1}], 2)
                            % Get x and y coordinates from the matrix
                            X_snap = app.Blocks_coordinates_4_points{ii, 1}(jj);  % X coordinates
                            Y_snap = app.Blocks_coordinates_4_points{ii, 2}(jj);  % Y coordinates

                            % Calculate the Euclidean distance between P(x, y) and the matrix point
                            distance = sqrt((X_snap - x)^2 + (Y_snap - y)^2);

                            % Check if the distance is within the snapping tolerance
                            if distance < tolerance
                                X(kk) = X_snap;  % Snap the x-coordinate
                                Y(kk) = Y_snap;  % Snap the y-coordinate
                                x_draw = X_snap;
                                y_draw = Y_snap;
                                snapped = true;
                                break;  % Exit the loop once snapping is done
                            end
                        end
                    end

                    % If no snapping occurred, keep the original coordinates
                    if ~snapped
                        X(kk) = x;
                        Y(kk) = y;
                    end

                else
                    % If snapping is off, just keep the original coordinates
                    X(kk) = x;
                    Y(kk) = y;
                end
                app.xy_Center(1) = x_draw;
                app.xy_Center(2) = y_draw;
                hold(app.UIAxes, 'on');
                axis(app.UIAxes, 'equal')
                plot(app.UIAxes, x_draw,y_draw,"Color",'k',"MarkerSize",2,"Marker","o")
                text(app.UIAxes,x_draw,y_draw,[' ', num2str(kk)],"FontSize",16,"HorizontalAlignment","left")

            end
            app.Number_of_Blocks = count +1;
            count = count +1;

            app.Blocks_coordinates_4_points{count,1} = X;
            app.Blocks_coordinates_4_points{count,2} = Y;

            kk = count;

            ps = polyshape(X,Y);
            pg = plot(app.UIAxes,ps);
            [xg,yg] = centroid(ps);

            plot(app.UIAxes,xg,yg,"Color",'k', "MarkerEdgeColor","auto", "Marker","o","MarkerSize",5)
            text(app.UIAxes,xg,yg,[' ', num2str(count)],"FontSize",16,"HorizontalAlignment","left", "FontWeight","bold")

            app.A_Blocks(count) = area(ps)*app.Unit_Length_scaling*app.Unit_Length_scaling;
            app.Thickness_Blocks(count) = app.ThicknessEditField.Value;
            app.W_Blocks(count) = app.A_Blocks(count)*app.SpecificWeightEditField.Value*app.Thickness_Blocks(count)*app.Unit_Mass_to_Weight;

            app.xyg_Blocks = [app.xyg_Blocks ; xg', yg'];

            app = sorting_Blocks(app);

            app = update_Table2(app);

%             ClearInteractiveAreaButtonPushed(app, event)
%             PlotblocksButton_2Pushed(app, event)
            ClearInteractiveAreaButtonPushed(app, event)
            PlotblocksButton_2Pushed(app, event)
            app.TabGroup5.SelectedTab = app.DViewTab;
            PlotGeometryButtonPushed(app, event)
        end

        % Button pushed function: PlotCableanalogyButton
        function PlotCableanalogyButtonPushed(app, event)
            % Find maximum force components and normalize them
            max_F1 = max(app.Force_Funicolar_Polygon(:, 1));
            max_F2 = max(app.Force_Funicolar_Polygon(:, 2));
            max_F3 = max(app.Force_Funicolar_Polygon(:, 3));
            max_F = max([max_F1, max_F2, max_F3]);
            Normalized_F1 = app.Force_Funicolar_Polygon(:, 1) / max_F;
            Normalized_F2 = app.Force_Funicolar_Polygon(:, 2) / max_F;
            Normalized_F3 = app.Force_Funicolar_Polygon(:, 3) / max_F;

            % Minimum y-coordinate for adjusting the y-axis position
            Y_min = min(app.LOT_xy(:, 2));
            Cable_color = [0.3, 0.7, 0.4];
            Weigth_color = [0.7, 0.65, 0.65];

            % Calculate the overall dimension of the structure based on block centroids.
            % Some legacy examples may not contain xyg_Blocks_like_sorted as an N-by-2 array.
            block_centroids = app.xyg_Blocks_like_sorted;
            if isempty(block_centroids) || size(block_centroids, 2) < 2
                block_centroids = app.xyg_Blocks;
            end
            if isempty(block_centroids) || size(block_centroids, 2) < 2
                block_centroids = app.LOT_xy;
            end

            if isempty(block_centroids) || size(block_centroids, 2) < 2
                x_limits = get(app.UIAxes, 'XLim');
                y_limits = get(app.UIAxes, 'YLim');
                structure_dimension = hypot(diff(x_limits), diff(y_limits));
            else
                block_centroids = block_centroids(:, 1:2);
                valid_rows = all(isfinite(block_centroids), 2);
                block_centroids = block_centroids(valid_rows, :);

                if isempty(block_centroids)
                    x_limits = get(app.UIAxes, 'XLim');
                    y_limits = get(app.UIAxes, 'YLim');
                    structure_dimension = hypot(diff(x_limits), diff(y_limits));
                else
                    x_extent = max(block_centroids(:, 1)) - min(block_centroids(:, 1));
                    y_extent = max(block_centroids(:, 2)) - min(block_centroids(:, 2));
                    structure_dimension = hypot(x_extent, y_extent);
                end
            end

            if ~isfinite(structure_dimension) || structure_dimension <= 0
                structure_dimension = 1;
            end
            max_hang = structure_dimension / 10;

            for rr = 1:size(app.LOT_xy, 1) - 1
                % Coordinates for cable segment
                x = [app.LOT_xy(rr, 1), app.LOT_xy(rr + 1, 1)];
                y = -[app.LOT_xy(rr, 2), app.LOT_xy(rr + 1, 2)] + 2 * Y_min;

                % Draw cable segment
                patchline(app.UIAxes, x, y, 'edgecolor', Cable_color, 'linewidth', 4, 'edgealpha', 0.5);

                % Draw weight if not the last segment
                if rr < (size(app.LOT_xy, 1) - 1)
                    dim_X_Axes = get(app.UIAxes, 'XLim');

                    % Width of the weight rectangle
                    B = abs(dim_X_Axes(1) - dim_X_Axes(2)) / 50;

                    % Proportion hang to structure dimension, scaled within [0, max_hang]
                    hang = max_hang * min(1, norm(get(app.UIAxes, 'YLim')) / structure_dimension);
                    H = app.W_Blocks_like_sorted(rr)/max(app.W_Blocks_like_sorted)*structure_dimension/10;

                    % Scale H based on the block weight, adjusting for screen scale factor
                    screen_scale = min(norm(get(app.UIAxes, 'YLim')), 1e4);
                    %                     H = % app.W_Blocks(rr) * app.LOTForcefactorEditField.Value * screen_scale / 90;

                    % Position of the weight rectangle
                    Position = [x(2) - B / 2, y(2) - H - hang, B, H];
                    rectangle(app.UIAxes, 'Position', Position, 'Curvature', [0.5, 0.5], ...
                        "FaceColor", Weigth_color, "LineWidth", 1);

                    % Draw the line connecting to the weight
                    plot(app.UIAxes, [x(2), x(2)], [y(2), y(2) - hang], '-ko', ...
                        "LineWidth", 2, "MarkerFaceColor", [0, 0, 0]);
                end
            end
        end

        % Button pushed function: SavetofolderExamplesButton
        function SavetofolderExamplesButtonPushed(app, event)
            method = 5;
            if method == 1
                filename = app.SaveasEditField.Value;
                current_directory = pwd;
                example_directory = 'Examples';
                folder = [current_directory filesep example_directory];
                if ~exist(folder, 'dir')
                    mkdir(folder)
                end
                save_to_folder = [ pwd filesep example_directory filesep];
                % variables list: REMEMBER TO UPDATE IT ALONG WITH CHANGE IN SCRIPTS
                saved_variables.xy_Center  = app.xy_Center;           % Center of the arch
                saved_variables.xy_Point_B     = app.xy_Point_B;           % Top of the arch
                saved_variables.xy_Point_A   = app.xy_Point_A;          % Bottom of the arch
                saved_variables.xy_Point_A = app.xy_Point_A;
                saved_variables.xy_Point_B = app.xy_Point_B;
                saved_variables.angle_Top_Center = app.angle_Top_Center;      % angle Top Center radiants
                saved_variables.angle_Bottom_Center = app.angle_Bottom_Center;   % angle bottom Center radiants
                saved_variables.angle_Top_Center_deg  = app.angle_Top_Center_deg;     % angle Top Center
                saved_variables.angle_Bottom_Center_deg = app.angle_Bottom_Center_deg; % angle bottom Center
                saved_variables.angle_Block_deg = app.angle_Block_deg;
                saved_variables.angle_Block = app.angle_Block;
                saved_variables.ImageFile =app.ImageFile;
                saved_variables.Arch_region_Position =app.Arch_region_Position;  % coordinates of the arch region
                saved_variables.Arch_region =app.Arch_region;
                saved_variables.A_Blocks=app.A_Blocks;
                saved_variables.W_Blocks=app.W_Blocks;
                saved_variables.Thickness_Blocks=app.Thickness_Blocks;
                saved_variables.Reference_distance=app.Reference_distance; % the known distance
                saved_variables.Resize_reference=app.Resize_reference;
                saved_variables.Blocks_coordinates=app.Blocks_coordinates;
                saved_variables.Blocks_coordinates_4_points=app.Blocks_coordinates_4_points;
                saved_variables.xyg_Blocks=app.xyg_Blocks;
                saved_variables.Preliminar_Funicolar_Problem= app.Preliminar_Funicolar_Problem;%
                saved_variables.Final_Funicolar_Problem= app.Final_Funicolar_Problem;
                saved_variables.polyin_Arch=app.polyin_Arch;
                saved_variables.xyG_polyin_Arch=app.xyG_polyin_Arch;
                saved_variables.alphabet_letters=app.alphabet_letters;
                saved_variables.xy_Pole_Prime=app.xy_Pole_Prime;
                saved_variables.xy_Pole_Def=app.xy_Pole_Def;
                saved_variables.Force_Funicolar_Polygon=app.Force_Funicolar_Polygon;
                saved_variables.LOT_Force=app.LOT_xy;
                saved_variables.LOT_xy=app.LOT_xy;
                saved_variables.Number_of_Blocks=app.Number_of_Blocks;
                saved_variables.UNISYS = app.UNISYS;
                save([save_to_folder, filename, '.mat'],  'saved_variables');
            elseif method == 2
                % Set up the save folder and filename
                filename = app.SaveasEditField.Value;
                current_directory = pwd;
                example_directory = 'Examples';
                folder = fullfile(current_directory, example_directory);
                if ~exist(folder, 'dir')
                    mkdir(folder);
                end
                save_to_folder = fullfile(folder, filename);

                % Get all properties of app and save them into saved_variables
                saved_variables = struct();
                app_properties = properties(app);
                for i = 1:length(app_properties)
                    prop_name = app_properties{i};
                    saved_variables.(prop_name) = app.(prop_name);
                end

                % Save the structure as a .mat file
                save([save_to_folder, '.mat'], 'saved_variables');
            elseif method == 3
                % Set up the save folder and filename
                filename = app.SaveasEditField.Value;
                current_directory = pwd;
                example_directory = 'Examples';
                folder = fullfile(current_directory, example_directory);
                if ~exist(folder, 'dir')
                    mkdir(folder);
                end
                save_to_folder = fullfile(folder, filename);

                % Initialize structure to hold non-handle properties only
                saved_variables = struct();

                % Get all properties of app and filter out UI handles
                app_properties = properties(app);
                for i = 1:length(app_properties)
                    prop_name = app_properties{i};
                    prop_value = app.(prop_name);

                    % Check if property is saveable (exclude UI handles)
                    if ~isa(prop_value, 'matlab.ui.Figure') && ...
                            ~isa(prop_value, 'matlab.ui.control.UIControl') && ...
                            ~isa(prop_value, 'matlab.ui.control.UIAxes') && ...
                            ~isa(prop_value, 'matlab.graphics.axis.Axes') && ...
                            ~isa(prop_value, 'matlab.ui.container.Panel') && ...
                            ~isa(prop_value, 'matlab.ui.container.Tab')
                        % Save the property if it's not a UI handle
                        saved_variables.(prop_name) = prop_value;
                    end
                end

                % Save the structure as a .mat file
                save([save_to_folder, '.mat'], 'saved_variables');
            elseif method == 4
                % Set up the save folder and filename
                filename = app.SaveasEditField.Value;
                current_directory = pwd;
                example_directory = 'Examples';
                folder = fullfile(current_directory, example_directory);
                if ~exist(folder, 'dir')
                    mkdir(folder);
                end
                save_to_folder = fullfile(folder, filename);

                % Initialize structure to hold saveable properties only
                saved_variables = struct();

                % Get all properties of app and filter out unsupported types
                app_properties = properties(app);
                for i = 1:length(app_properties)
                    prop_name = app_properties{i};
                    prop_value = app.(prop_name);

                    % Check if the property is of a basic data type
                    if ischar(prop_value) || isnumeric(prop_value) || islogical(prop_value) || isstruct(prop_value) || iscell(prop_value)
                        % Save the property if it's a basic data type
                        saved_variables.(prop_name) = prop_value;
                    end
                end

                % Save the structure as a .mat file
                save([save_to_folder, '.mat'], 'saved_variables');
            elseif method == 5
                % Set up the save folder and filename
                filename = app.SaveasEditField.Value;
                current_directory = pwd;
                example_directory = 'Examples';
                folder = fullfile(current_directory, example_directory);
                if ~exist(folder, 'dir')
                    mkdir(folder);
                end
                save_to_folder = fullfile(folder, filename);

                % Save each property explicitly
                saved_variables.xy_Center = app.xy_Center;
                saved_variables.xy_Point_B = app.xy_Point_B;
                saved_variables.xy_Point_A = app.xy_Point_A;
                saved_variables.angle_Top_Center = app.angle_Top_Center;
                saved_variables.angle_Bottom_Center = app.angle_Bottom_Center;
                saved_variables.angle_Top_Center_deg = app.angle_Top_Center_deg;
                saved_variables.angle_Bottom_Center_deg = app.angle_Bottom_Center_deg;
                saved_variables.angle_Block_deg = app.angle_Block_deg;
                saved_variables.angle_Block = app.angle_Block;
                saved_variables.angle_factors = app.angle_factors;
                saved_variables.ImageFile = app.ImageFile;
                saved_variables.previous_image_scaling_factor = app.previous_image_scaling_factor;
                saved_variables.current_image_scaling_factor = app.current_image_scaling_factor;
                saved_variables.Arch_region_Position = app.Arch_region_Position;
                saved_variables.Arch_region = app.Arch_region;
                saved_variables.A_Blocks = app.A_Blocks;
                saved_variables.W_Blocks = app.W_Blocks;
                saved_variables.Thickness_Blocks = app.Thickness_Blocks;
                saved_variables.Reference_distance = app.Reference_distance;
                saved_variables.Resize_reference = app.Resize_reference;
                saved_variables.Blocks_coordinates = app.Blocks_coordinates;
                saved_variables.Blocks_coordinates_4_points = app.Blocks_coordinates_4_points;
                saved_variables.xyg_Blocks = app.xyg_Blocks;
                saved_variables.Preliminar_Funicolar_Problem = app.Preliminar_Funicolar_Problem;
                saved_variables.Final_Funicolar_Problem = app.Final_Funicolar_Problem;
                saved_variables.polyin_Arch = app.polyin_Arch;
                saved_variables.xyG_polyin_Arch = app.xyG_polyin_Arch;
                saved_variables.alphabet_letters = app.alphabet_letters;
                saved_variables.xy_Pole_Prime = app.xy_Pole_Prime;
                saved_variables.xy_Pole_Def = app.xy_Pole_Def;
                saved_variables.Force_Funicolar_Polygon = app.Force_Funicolar_Polygon;
                saved_variables.LOT_Force = app.LOT_Force;
                saved_variables.LOT_xy = app.LOT_xy;
                saved_variables.Number_of_Blocks = app.Number_of_Blocks;
                saved_variables.Folder_Examples = app.Folder_Examples;
                saved_variables.alpha_W_Blocks = app.alpha_W_Blocks;
                saved_variables.HorizontalThrust = app.DefineHorizontalThrustEditField.Value;
                saved_variables.PolenihpSwitch.Value = app.PolenihpSwitch.Value;
                % Reactions
                saved_variables.Reaction_at_A = app.Reaction_at_A;
                saved_variables.Reaction_at_B = app.Reaction_at_B;
                saved_variables.Reaction_at_A_Components = app.Reaction_at_A_Components;
                saved_variables.Reaction_at_B_Components = app.Reaction_at_B_Components;

                % Units
                saved_variables.Conversion_Unit_Force_scaling = app.Conversion_Unit_Force_scaling;
                saved_variables.Conversion_Unit_Pressure_scaling = app.Conversion_Unit_Pressure_scaling;
                saved_variables.Conversion_Unit_Mass_scaling = app.Conversion_Unit_Mass_scaling;
                saved_variables.Conversion_Unit_Length_scaling = app.Conversion_Unit_Length_scaling;
                saved_variables.Conversion_Unit_Specific_Weight_scaling = app.Conversion_Unit_Specific_Weight_scaling;
                saved_variables.UNISYS = app.UNISYS;
                saved_variables.Unit_Length_scaling = app.Unit_Length_scaling;
                saved_variables.Unit_Mass_to_Weight = app.Unit_Mass_to_Weight;

                % Save the structure as a .mat file
                save([save_to_folder, '.mat'], 'saved_variables');
            else
            end

        end

        % Value changed function: SaveasEditField
        function SaveasEditFieldValueChanged(app, event)
            value = app.SaveasEditField.Value;

        end

        % Value changed function: ExampleDropDown
        function ExampleDropDownValueChanged(app, event)
            value = app.ExampleDropDown.Value;

        end

        % Button pushed function: LoadExamplesfolderButton
        function LoadExamplesfolderButtonPushed(app, event)
            app.ExampleDropDown.Items = {' .. '};
            filename = app.SaveasEditField.Value;
            current_directory = pwd;
            example_directory = 'Examples';
            if strcmp(current_directory,example_directory)
                cd('../')
            else

            end
            folder = [current_directory filesep example_directory filesep];
            if ~exist(folder, 'dir')
                % Open finder
                folder = uigetdir;
            end
            try
                fileList = dir([folder filesep '*.mat']);
                for kk = 1:size(fileList,1)
                    app.ExampleDropDown.Items{kk} = fileList(kk).name;
                end
                app.Folder_Examples = folder;
            catch

            end
            %             cd(folder)


            %             app.ExampleDropDown.Items
        end

        % Button pushed function: OpenExampleButton
        function OpenExampleButtonPushed(app, event)

            ClearAllVariablesButtonPushed(app, event)
            method = 3;
            if method == 1
                load([app.Folder_Examples filesep app.ExampleDropDown.Value ])

                app.xy_Center  = saved_variables.xy_Center;           % Center of the arch
                app.xy_Point_B     = saved_variables.xy_Point_B;           % Top of the arch
                app.xy_Point_A   = saved_variables.xy_Point_A;          % Bottom of the arch
                app.xy_Point_A = saved_variables.xy_Point_A;
                app.xy_Point_B = saved_variables.xy_Point_B;
                app.angle_Top_Center = saved_variables.angle_Top_Center;      % angle Top Center radiants
                app.angle_Bottom_Center = saved_variables.angle_Bottom_Center;   % angle bottom Center radiants
                app.angle_Top_Center_deg  = saved_variables.angle_Top_Center_deg;     % angle Top Center
                app.angle_Bottom_Center_deg = saved_variables.angle_Bottom_Center_deg; % angle bottom Center
                app.angle_Block_deg = saved_variables.angle_Block_deg;
                app.angle_Block = saved_variables.angle_Block;
                app.ImageFile =saved_variables.ImageFile;
                app.Arch_region_Position =saved_variables.Arch_region_Position;  % coordinates of the arch region
                app.Arch_region =saved_variables.Arch_region;
                app.A_Blocks=saved_variables.A_Blocks;
                app.W_Blocks=saved_variables.W_Blocks;

                app.Reference_distance=saved_variables.Reference_distance; % the known distance
                app.Resize_reference=saved_variables.Resize_reference;
                app.Blocks_coordinates=saved_variables.Blocks_coordinates;
                app.Blocks_coordinates_4_points=saved_variables.Blocks_coordinates_4_points;
                app.xyg_Blocks=saved_variables.xyg_Blocks;
                app.Preliminar_Funicolar_Problem= saved_variables.Preliminar_Funicolar_Problem;%
                app.Final_Funicolar_Problem= saved_variables.Final_Funicolar_Problem;
                app.polyin_Arch=saved_variables.polyin_Arch;
                app.xyG_polyin_Arch=saved_variables.xyG_polyin_Arch;
                app.alphabet_letters=saved_variables.alphabet_letters;
                app.xy_Pole_Prime=saved_variables.xy_Pole_Prime;
                app.xy_Pole_Def=saved_variables.xy_Pole_Def;
                app.Force_Funicolar_Polygon=saved_variables.Force_Funicolar_Polygon;
                app.LOT_Force=saved_variables.LOT_xy;
                app.LOT_xy=saved_variables.LOT_xy;
                app.Number_of_Blocks=saved_variables.Number_of_Blocks;
                app.HorizontalThrust = saved_variables.HorizontalThrust;
                app.DefineHorizontalThrustEditField.Value = app.HorizontalThrust;
                %%%
                try
                    app.Thickness_Blocks= saved_variables.Thickness_Blocks;
                catch
                    app.Thickness_Blocks= [1:1:app.Number_of_Blocks];
                end
                try
                    app.UNISYS = saved_variables.UNISYS;
                    if strcmp(app.UNISYS,'SI')
                        SIButtonPushed(app,event)
                    elseif strcmp(app.UNISYS,'Nmm')
                        NmmButtonPushed(app,event)
                    elseif strcmp(app.UNISYS,'kgcm')
                        kgcmButtonPushed(app,event)
                    else
                        warndlg('Units were not found! Units are set to SI system (1 Pixel = 0.001m)','Warning');
                        SIButtonPushed(app,event)
                    end
                catch
                    warndlg('Units were not found! Units are set to SI system (1 Pixel = 0.001m)','Warning');
                    SIButtonPushed(app,event)
                end


                %%%%
                PlotblocksButton_2Pushed(app, event)
                ResetViewButtonPushed(app, event)
                update_Table2(app)
                try
                    PlotGeometryButtonPushed(app, event)
                catch
                end
            elseif method == 2
                load_folder = [app.Folder_Examples filesep app.ExampleDropDown.Value ];

                %             % Set up the folder and filename for loading
                %             filename = app.SaveasEditField.Value;
                %             current_directory = pwd;
                %             example_directory = 'Examples';
                %             load_folder = fullfile(current_directory, example_directory, [filename, '.mat']);

                % Check if the file exists
                if exist(load_folder, 'file') ~= 2
                    uialert(app.UIFigure, 'File not found. Please check the filename and try again.', 'File Not Found');
                    return;
                end

                % Load the .mat file
                loaded_data = load(load_folder, 'saved_variables');
                saved_variables = loaded_data.saved_variables;

                % Assign each field to the corresponding app property
                app.xy_Center = saved_variables.xy_Center;
                app.xy_Point_B = saved_variables.xy_Point_B;
                app.xy_Point_A = saved_variables.xy_Point_A;
                app.angle_Top_Center = saved_variables.angle_Top_Center;
                app.angle_Bottom_Center = saved_variables.angle_Bottom_Center;
                app.angle_Top_Center_deg = saved_variables.angle_Top_Center_deg;
                app.angle_Bottom_Center_deg = saved_variables.angle_Bottom_Center_deg;
                app.angle_Block_deg = saved_variables.angle_Block_deg;
                app.angle_Block = saved_variables.angle_Block;
                app.angle_factors = saved_variables.angle_factors;
                app.ImageFile = saved_variables.ImageFile;
                app.previous_image_scaling_factor = saved_variables.previous_image_scaling_factor;
                app.current_image_scaling_factor = saved_variables.current_image_scaling_factor;
                app.Arch_region_Position = saved_variables.Arch_region_Position;
                app.Arch_region = saved_variables.Arch_region;
                app.A_Blocks = saved_variables.A_Blocks;
                app.W_Blocks = saved_variables.W_Blocks;

                % Check and assign Thickness_Blocks
                try
                    app.Thickness_Blocks = saved_variables.Thickness_Blocks;
                catch
                    app.Thickness_Blocks = 1:app.Number_of_Blocks;  % Default if not found
                end

                app.Reference_distance = saved_variables.Reference_distance;
                app.Resize_reference = saved_variables.Resize_reference;
                app.Blocks_coordinates = saved_variables.Blocks_coordinates;
                app.Blocks_coordinates_4_points = saved_variables.Blocks_coordinates_4_points;
                app.xyg_Blocks = saved_variables.xyg_Blocks;
                app.Preliminar_Funicolar_Problem = saved_variables.Preliminar_Funicolar_Problem;
                app.Final_Funicolar_Problem = saved_variables.Final_Funicolar_Problem;
                app.polyin_Arch = saved_variables.polyin_Arch;
                app.xyG_polyin_Arch = saved_variables.xyG_polyin_Arch;
                app.alphabet_letters = saved_variables.alphabet_letters;
                app.xy_Pole_Prime = saved_variables.xy_Pole_Prime;
                app.xy_Pole_Def = saved_variables.xy_Pole_Def;
                app.Force_Funicolar_Polygon = saved_variables.Force_Funicolar_Polygon;
                app.LOT_Force = saved_variables.LOT_Force;
                app.LOT_xy = saved_variables.LOT_xy;
                app.Number_of_Blocks = saved_variables.Number_of_Blocks;
                app.Folder_Examples = saved_variables.Folder_Examples;
                app.alpha_W_Blocks = saved_variables.alpha_W_Blocks;

                % Reactions
                app.Reaction_at_A = saved_variables.Reaction_at_A;
                app.Reaction_at_B = saved_variables.Reaction_at_B;
                app.Reaction_at_A_Components = saved_variables.Reaction_at_A_Components;
                app.Reaction_at_B_Components = saved_variables.Reaction_at_B_Components;

                % Units and scaling checks
                try
                    app.UNISYS = saved_variables.UNISYS;
                    if strcmp(app.UNISYS, 'SI')
                        SIButtonPushed(app, event);
                    elseif strcmp(app.UNISYS, 'Nmm')
                        NmmButtonPushed(app, event);
                    elseif strcmp(app.UNISYS, 'kgcm')
                        kgcmButtonPushed(app, event);
                    else
                        warndlg('Units were not found! Units are set to SI system (1 Pixel = 0.001m)', 'Warning');
                        SIButtonPushed(app, event);
                    end
                catch
                    warndlg('Units were not found! Units are set to SI system (1 Pixel = 0.001m)', 'Warning');
                    SIButtonPushed(app, event);
                end

                % Assign unit conversion factors if available
                app.Conversion_Unit_Force_scaling = saved_variables.Conversion_Unit_Force_scaling;
                app.Conversion_Unit_Pressure_scaling = saved_variables.Conversion_Unit_Pressure_scaling;
                app.Conversion_Unit_Mass_scaling = saved_variables.Conversion_Unit_Mass_scaling;
                app.Conversion_Unit_Length_scaling = saved_variables.Conversion_Unit_Length_scaling;
                app.Conversion_Unit_Specific_Weight_scaling = saved_variables.Conversion_Unit_Specific_Weight_scaling;
                app.Unit_Length_scaling = saved_variables.Unit_Length_scaling;
                app.Unit_Mass_to_Weight = saved_variables.Unit_Mass_to_Weight;

                % Post-load updates and plotting functions
                PlotblocksButton_2Pushed(app, event);
                ResetViewButtonPushed(app, event);
                update_Table2(app);

                try
                    PlotGeometryButtonPushed(app, event);
                catch
                    % Handle any issues that might occur during geometry plotting
                    warning('An error occurred while plotting the geometry.');
                end

                % Notify user of successful load
                uialert(app.UIFigure, 'Data loaded successfully!', 'Load Complete');
            elseif method == 3
                % Set up the folder and filename for loading
                load_folder = [app.Folder_Examples filesep app.ExampleDropDown.Value ];

                % Check if the file exists
                if exist(load_folder, 'file') ~= 2
                    uialert(app.UIFigure, 'File not found. Please check the filename and try again.', 'File Not Found');
                    return;
                end

                % Load the .mat file
                loaded_data = load(load_folder, 'saved_variables');
                saved_variables = loaded_data.saved_variables;

                % Dynamically assign each field in saved_variables to the corresponding app property
                field_names = fieldnames(saved_variables);
                for i = 1:length(field_names)
                    field_name = field_names{i};
                    try
                        app.(field_name) = saved_variables.(field_name);
                    catch
                        warning(['Could not load field: ', field_name]);
                    end
                end

                % Check and set defaults for any missing fields
                if ~isfield(saved_variables, 'Thickness_Blocks')
                    app.Thickness_Blocks = 1:app.Number_of_Blocks;
                end
                try
                    app.HorizontalThrust = saved_variables.HorizontalThrust;
                    app.DefineHorizontalThrustEditField.Value = app.HorizontalThrust;
                catch
                end

                % Units check and setting defaults
                try
                    if isfield(saved_variables, 'UNISYS')
                        app.UNISYS = saved_variables.UNISYS;
                        if strcmp(app.UNISYS, 'SI')
                            SIButtonPushed(app, event);
                        elseif strcmp(app.UNISYS, 'Nmm')
                            NmmButtonPushed(app, event);
                        elseif strcmp(app.UNISYS, 'kgcm')
                            kgcmButtonPushed(app, event);
                        else
                            warndlg('Units were not found! Units are set to SI system (1 Pixel = 0.001m)', 'Warning');
                            SIButtonPushed(app, event);
                        end
                    else
                        warndlg('Units were not found! Units are set to SI system (1 Pixel = 0.001m)', 'Warning');
                        SIButtonPushed(app, event);
                    end
                catch
                    warndlg('Error setting units. Units are set to SI system (1 Pixel = 0.001m)', 'Warning');
                    SIButtonPushed(app, event);
                end

                % Post-load updates and plotting functions
                PlotblocksButton_2Pushed(app, event);
                ResetViewButtonPushed(app, event);
                update_Table2(app);

                try
                    PlotGeometryButtonPushed(app, event);
                catch
                    % Handle any issues that might occur during geometry plotting
                    warning('An error occurred while plotting the geometry.');
                end

                % Notify user of successful load
                uialert(app.UIFigure, 'Data loaded successfully!', 'Load Complete');
            else
            end
            % Check the condition of PolenihpSwitch and set its value
            if strcmp(app.PolenihpSwitch.Value, 'on')
                app.PolenihpSwitch.Value = 'On';  % Turn on the switch
            else
                app.PolenihpSwitch.Value = 'Off';  % Ensure it is off otherwise
            end


        end

        % Button pushed function: ClearAllVariablesButton
        function ClearAllVariablesButtonPushed(app, event)
            app.xy_Center  = [];% saved_variables.xy_Center;           % Center of the arch
            app.xy_Point_B     = [];% saved_variables.xy_Point_B;           % Top of the arch
            app.xy_Point_A   = [];% saved_variables.xy_Point_A;          % Bottom of the arch
            app.xy_Point_A = [];% saved_variables.xy_Point_A;
            app.xy_Point_B = [];% saved_variables.xy_Point_B;
            app.angle_Top_Center = [];% saved_variables.angle_Top_Center;      % angle Top Center radiants
            app.angle_Bottom_Center = [];% saved_variables.angle_Bottom_Center;   % angle bottom Center radiants
            app.angle_Top_Center_deg  = [];% saved_variables.angle_Top_Center_deg;     % angle Top Center
            app.angle_Bottom_Center_deg = [];% saved_variables.angle_Bottom_Center_deg; % angle bottom Center
            app.angle_Block_deg = [];% saved_variables.angle_Block_deg;
            app.angle_Block = [];% saved_variables.angle_Block;
            app.ImageFile = [];%saved_variables.ImageFile;
            app.Arch_region_Position = [];%saved_variables.Arch_region_Position;  % coordinates of the arch region
            app.Arch_region = [];%saved_variables.Arch_region;
            app.A_Blocks= [];%saved_variables.A_Blocks;
            app.W_Blocks= [];%saved_variables.W_Blocks;
            app.Reference_distance= [];%saved_variables.Reference_distance; % the known distance
            app.Resize_reference= [];%saved_variables.Resize_reference;
            app.Blocks_coordinates= [];%saved_variables.Blocks_coordinates;
            app.Blocks_coordinates_4_points= [];%saved_variables.Blocks_coordinates_4_points;
            app.xyg_Blocks= [];%saved_variables.xyg_Blocks;
            app.Preliminar_Funicolar_Problem= [];% saved_variables.Preliminar_Funicolar_Problem;%
            app.Final_Funicolar_Problem= [];% saved_variables.Final_Funicolar_Problem;
            app.polyin_Arch= [];%saved_variables.polyin_Arch;
            app.xyG_polyin_Arch= [];%saved_variables.xyG_polyin_Arch;
            app.alphabet_letters= [];%saved_variables.alphabet_letters;
            app.xy_Pole_Prime= [];%saved_variables.xy_Pole_Prime;
            app.xy_Pole_Def= [];%saved_variables.xy_Pole_Def;
            app.Force_Funicolar_Polygon= [];%saved_variables.Force_Funicolar_Polygon;
            app.LOT_Force= [];%saved_variables.LOT_xy;
            app.LOT_xy= [];%saved_variables.LOT_xy;
            app.Number_of_Blocks= [];%saved_variables.Number_of_Blocks;
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            app.setup_main_figure
            app.setup_secondary_figure
            app.setup_3D_figure
            set(app.UIAxes, 'XLim', [0,1000]);
            set(app.UIAxes, 'YLim', [0,1000]);

        end

        % Value changed function: BackgroundImageSwitch_2
        function BackgroundImageSwitch_2ValueChanged(app, event)
            value = app.BackgroundImageSwitch_2.Value;

        end

        % Button pushed function: ResetViewButton
        function ResetViewButtonPushed(app, event)
            resetplotview(app.UIAxes)
            zoom(app.UIAxes, 0.98)
        end

        % Button pushed function: PanButton
        function PanButtonPushed(app, event)
            pan(app.UIAxes, 'on')
        end

        % Button pushed function: ScrollZoomonButton
        function ScrollZoomonButtonPushed(app, event)
            zoom(app.UIAxes, 'on')
        end

        % Button pushed function: ScrollZoomoffButton
        function ScrollZoomoffButtonPushed(app, event)
            zoom(app.UIAxes, 'off')
        end

        % Button pushed function: ResetViewButton_2
        function ResetViewButton_2Pushed(app, event)
            resetplotview(app.UIAxes2)
            zoom(app.UIAxes2, 0.98)
        end

        % Button pushed function: CalculateArchButton
        function CalculateArchButtonPushed(app, event)

            %ComputingProgressBar(app, 'open')
            % CIRCULAR ARCH
            Num_blocks = app.NofBlocksEditField.Value;
            start_angle = app.StartangleEditField.Value;
            end_angle = app.EndangleEditField.Value;
            total_angle = end_angle - start_angle;
            thetas_deg = [start_angle:total_angle/Num_blocks:end_angle];
            thetas = 2*pi*thetas_deg/360;
            [x_inner,y_inner] = pol2cart(thetas,app.InnerradEditField.Value/app.Unit_Length_scaling);
            [x_outer,y_outer] = pol2cart(thetas,app.OuterradEditField.Value/app.Unit_Length_scaling);
            x_inner = x_inner+ app.xy_Center(1);
            y_inner = y_inner+ app.xy_Center(2);
            x_outer = x_outer+ app.xy_Center(1);
            y_outer = y_outer+ app.xy_Center(2);
            new_blocks = cell(Num_blocks, 2);
            Thickness(1:Num_blocks) = app.ThicknessEditField.Value;
            for jj=1:Num_blocks
                new_blocks{jj,1} = [x_inner(jj) , x_outer(jj),  x_outer(jj+1), x_inner(jj+1) ];
                new_blocks{jj,2} = [y_inner(jj) , y_outer(jj),  y_outer(jj+1), y_inner(jj+1) ];
                
            end
            if strcmp(app.KeepexistinggeometrySwitch.Value, 'Yes')
                % Concatenate the existing geometry with the new blocks
                app.Blocks_coordinates_4_points = [app.Blocks_coordinates_4_points; new_blocks];
                app.Number_of_Blocks = app.Number_of_Blocks+Num_blocks;
                app.Thickness_Blocks = [app.Thickness_Blocks, Thickness];
                compute_Blocks_geometrical_features(app)
            else
                % Replace the existing geometry with the new blocks
                app.Blocks_coordinates_4_points = new_blocks;
                app.Number_of_Blocks = Num_blocks;
                vX = [];vY = [];
                app.A_Blocks = [];
                app.W_Blocks = [];
                app.Thickness_Blocks = Thickness;
                compute_Blocks_geometrical_features(app)
            end


            sorting_Blocks(app)
            update_Table2(app)
            PlotblocksButton_2Pushed(app,event)
            %ComputingProgressBar(app, 'close')

        end

        % Value changed function: NofBlocksEditField
        function NofBlocksEditFieldValueChanged(app, event)
            value = app.NofBlocksEditField.Value;

        end

        % Value changing function: HorizontalThrustSlider
        function HorizontalThrustSliderValueChanging(app, event)
            %             changingValue = app.DefineHorizontalThrustEditField.Value;
            %             app.HorizontalThrustSlider.Limits = [0 changingValue];
            %             app.DefineHorizontalThrustEditField.Value = app.HorizontalThrustSlider.Value;
            %
            %             PreliminarFOPButtonPushed(app, event)
            %             PreliminarFUPButtonPushed(app, event)
            %             FinalFOPButtonPushed(app, event)
            %             FinalFUPButtonPushed(app, event)

        end

        % Button pushed function: UpdateSliderButton
        function UpdateSliderButtonPushed(app, event)
            minH = app.minEditField.Value;
            maxH = app.maxEditField.Value;
            if minH>maxH
                minH = 0.9*maxH;
            else
            end
            app.HorizontalThrustSlider.Limits = [minH maxH];
        end

        % Value changed function: HorizontalThrustSlider
        function HorizontalThrustSliderValueChanged(app, event)
            %             value = app.HorizontalThrustSlider.Value;
            app.DefineHorizontalThrustEditField.Value = app.HorizontalThrustSlider.Value;

            PreliminarFOPButtonPushed(app, event)
            PreliminarFUPButtonPushed(app, event)
            FinalFOPButtonPushed(app, event)
            FinalFUPButtonPushed(app, event)
            PlotReactionsButtonPushed(app, event)
        end

        % Button pushed function: PlotSketchButton
        function PlotSketchButtonPushed(app, event)

            if ~isempty(app.Arch_region_Position)
                x = app.Arch_region_Position(:,1);
                y = app.Arch_region_Position(:,2);
                ps = polyshape(x,y);
                pg = plot(app.UIAxes,ps, "FaceColor" , [0.3,0.3,0.3], "EdgeColor" , [0.2,0.2,0.2], "LineWidth", 2);
            else
            end
        end

        % Button pushed function: PlotForcesButton
        function PlotForcesButtonPushed(app, event)
            %             FigureYdimension = get(app.UIAxes, 'YLim');
            %             if isempty(app.force_size_rescaled)
            %                 app.force_size_rescaled = false;
            %                 app.force_size_rescaled_factor = 1;
            %             else
            %             end
            %             app.force_size_rescaled_factor_g = FigureYdimension(2)/20;
            %
            %             if FigureYdimension(2) < max(app.W_Blocks_like) && ~app.force_size_rescaled
            %                 app.forcesfactorEditField.Value = 0.3 * FigureYdimension(2) / max(app.W_Blocks_like);
            %                 app.force_size_rescaled = true;
            %                 app.force_size_rescaled_factor = sum(app.W_Blocks_like)/FigureYdimension(2);
            %
            %             end

            app = arrow_scale(app);
%             arrow_width  = app.W_Blocks_like*app.forcesfactorEditField.Value/10;
%             arrow_length = app.W_Blocks_like*app.forcesfactorEditField.Value/10;
%             arrow_thick = 2;
            for kk=1:app.Number_of_Blocks_like
                xg = app.xyg_Blocks_like(kk,1);
                yg = app.xyg_Blocks_like(kk,2);
                arrow_end = yg-app.W_Blocks_like(kk)*app.forcesfactorEditField.Value;
                if app.Blocks_like_kind(kk) == 0 % Block
                    color = 'r';
                    force_name = ' W';
                elseif app.Blocks_like_kind(kk) == 1 % Force
                    color = 'b';
                    force_name = ' F';
                    plot(app.UIAxes,xg,yg,"Color",[0 0 1], "MarkerEdgeColor",[0 0 1], "Marker","o","MarkerSize",5, "MarkerFaceColor",[0 0 1])
                else
                end
%                 ar1 = plot(app.UIAxes,[xg,xg], [yg,arrow_end], "Color",color,"LineWidth",arrow_thick );
%                 ar2 = plot(app.UIAxes,[xg,xg-arrow_width], [arrow_end, arrow_end+arrow_length], "Color",color,"LineWidth",arrow_thick  );
%                 ar3 = plot(app.UIAxes,[xg,xg+arrow_width], [arrow_end, arrow_end+arrow_length], "Color",color,"LineWidth",arrow_thick  );
                vxi = xg;
                vxj = xg;
                vyi = yg;
                vyj = arrow_end;
                drawArrow_G(app, app.UIAxes,[vxi, vyi],[vxj, vyj], color);
                switch app.ForceMagnitudeSwitch.Value
                    case 'Off'
                    case 'On'
                        %text(app.UIAxes,xg,mean([yg,arrow_end]),[force_name, num2str(kk),'= ', num2str(app.W_Blocks_like(kk))],"FontSize",16,"HorizontalAlignment","left", "FontWeight","bold")
                        formatted_value = sprintf('%.2f', app.W_Blocks_like(kk));  % Format to 2 decimal places
                        latex_text = [' $' force_name, '_{' num2str(kk) '} = ' formatted_value '$'];  % LaTeX style text
                        text(app.UIAxes, xg, mean([yg, arrow_end]), latex_text, ...
                            "FontSize", 16, "HorizontalAlignment", "left", "FontWeight", "bold", ...
                            "Interpreter", "latex");
                end
            end


        end

        % Button pushed function: ClearSketchButton
        function ClearSketchButtonPushed(app, event)
            delete(app.Arch_region);
            app.Arch_region_Position = [];
            ResetViewButtonPushed(app)
        end

        % Button pushed function: PlotBackgroundImageButton
        function PlotBackgroundImageButtonPushed(app, event)
            app.BackgroundImageSwitch_2.Value = 'On';
            %             imagesc(app.UIAxes, app.ImageFile);
            % Get the size of the image
            [img_height, img_width, ~] = size(app.ImageFile);
            % Scale the image display to match desired dimensions
            imagesc(app.UIAxes, [0, img_width * app.current_image_scaling_factor ], [0, img_height * app.current_image_scaling_factor ], app.ImageFile);
            ResetViewButtonPushed(app)
        end

        % Selection changed function: LengthButtonGroup
        function LengthButtonGroupSelectionChanged(app, event)
            app.mLabel.Text = [ ' [ ', app.LengthButtonGroup.SelectedObject.Text, ' ]' ];
        end

        % Selection changed function: MassButtonGroup
        function MassButtonGroupSelectionChanged(app, event)
            app.kgLabel.Text = [ ' [ ', app.MassButtonGroup.SelectedObject.Text, ' ]' ];
        end

        % Selection changed function: ForceButtonGroup
        function ForceButtonGroupSelectionChanged(app, event)
            app.NLabel.Text = [ ' [ ', app.ForceButtonGroup.SelectedObject.Text, ' ]' ];
        end

        % Selection changed function: ModulusStressButtonGroup
        function ModulusStressButtonGroupSelectionChanged(app, event)
            app.PaLabel.Text = [ ' [ ', app.ModulusStressButtonGroup.SelectedObject.Text, ' ]' ];
        end

        % Button pushed function: SIButton
        function SIButtonPushed(app, event)
            if isempty(app.UNISYS)
                app.UNISYS = 'SI';
            end
            app.NButton.Value = true;
            app.PaButton.Value = true;
            app.kgButton.Value = true;
            app.mButton.Value = true;
            if strcmp(app.UNISYS,'SI')
                app.Conversion_Unit_Force_scaling = 1;
                app.Conversion_Unit_Pressure_scaling = 1;
                app.Conversion_Unit_Mass_scaling = 1;
                app.Conversion_Unit_Length_scaling = 1;
            elseif strcmp(app.UNISYS,'Nmm')
                app.Conversion_Unit_Force_scaling = 1;
                app.Conversion_Unit_Pressure_scaling = 1e6;
                app.Conversion_Unit_Mass_scaling = 1e3;
                app.Conversion_Unit_Length_scaling = 1e-3;
            elseif strcmp(app.UNISYS,'kgcm')
                app.Conversion_Unit_Force_scaling = 1e-1;
                app.Conversion_Unit_Pressure_scaling = 1e-3;
                app.Conversion_Unit_Mass_scaling = 1;
                app.Conversion_Unit_Length_scaling = 1e-2;
            else
            end
            app.Conversion_Unit_Specific_Weight_scaling = app.Conversion_Unit_Mass_scaling/app.Conversion_Unit_Length_scaling^3;
            app.SpecificWeightEditField.Value = app.SpecificWeightEditField.Value*app.Conversion_Unit_Specific_Weight_scaling;
            app.ThicknessEditField.Value = app.ThicknessEditField.Value*app.Conversion_Unit_Length_scaling;
            app.Unit_Mass_to_Weight = 1e1;
            app.NLabel.Text = [ ' [ ', app.ForceButtonGroup.SelectedObject.Text, ' ]' ];
            app.PaLabel.Text = [ ' [ ', app.ModulusStressButtonGroup.SelectedObject.Text, ' ]' ];
            app.kgLabel.Text = [ ' [ ', app.MassButtonGroup.SelectedObject.Text, ' ]' ];
            app.mLabel.Text = [ ' [ ', app.LengthButtonGroup.SelectedObject.Text, ' ]' ];
            app.PixelEditField.Value = 0.001; % 1 Pixel = 1000 mm = 0.001 * 1000m
            app.Unit_Length_scaling = 1e-3;
            app.Unit_Length_scaling = app.Conversion_Unit_Length_scaling; 

            app.UNISYS = 'SI';
            compute_Blocks_geometrical_features(app)
        end

        % Button pushed function: NmmButton
        function NmmButtonPushed(app, event)
            if isempty(app.UNISYS)
                app.UNISYS = 'SI';
            end
            app.NButton.Value = true;
            app.MPaButton_2.Value = true;
            app.tonsButton.Value = true;
            app.mmButton.Value = true;
            if strcmp(app.UNISYS,'SI')
                app.Conversion_Unit_Force_scaling = 1;
                app.Conversion_Unit_Pressure_scaling = 1e-6;
                app.Conversion_Unit_Mass_scaling = 1e-3;
                app.Conversion_Unit_Length_scaling = 1e3;
            elseif strcmp(app.UNISYS,'Nmm')
                app.Conversion_Unit_Force_scaling = 1;
                app.Conversion_Unit_Pressure_scaling = 1;
                app.Conversion_Unit_Mass_scaling = 1;
                app.Conversion_Unit_Length_scaling = 1;
            elseif strcmp(app.UNISYS,'kgcm')
                app.Conversion_Unit_Force_scaling = 1e-1;
                app.Conversion_Unit_Pressure_scaling = 1e1*1e2;
                app.Conversion_Unit_Mass_scaling = 1e-3;
                app.Conversion_Unit_Length_scaling = 1e1;
            else
            end
            app.Conversion_Unit_Specific_Weight_scaling = app.Conversion_Unit_Mass_scaling/app.Conversion_Unit_Length_scaling^3;
            app.SpecificWeightEditField.Value = app.SpecificWeightEditField.Value*app.Conversion_Unit_Specific_Weight_scaling;
            app.ThicknessEditField.Value = app.ThicknessEditField.Value*app.Conversion_Unit_Length_scaling;
            app.Unit_Mass_to_Weight = 1e4;
            app.NLabel.Text = [ ' [ ', app.ForceButtonGroup.SelectedObject.Text, ' ]' ];
            app.PaLabel.Text = [ ' [ ', app.ModulusStressButtonGroup.SelectedObject.Text, ' ]' ];
            app.kgLabel.Text = [ ' [ ', app.MassButtonGroup.SelectedObject.Text, ' ]' ];
            app.mLabel.Text = [ ' [ ', app.LengthButtonGroup.SelectedObject.Text, ' ]' ];
            app.PixelEditField.Value = 1; % 1 Pixel = 1000 mm = 1 * 1000mm
            app.Unit_Length_scaling = 1;
            app.Unit_Length_scaling = app.Conversion_Unit_Length_scaling;
            app.UNISYS = 'Nmm';
            compute_Blocks_geometrical_features(app)
        end

        % Button pushed function: kgcmButton
        function kgcmButtonPushed(app, event)
            if isempty(app.UNISYS)
                app.UNISYS = 'SI';
            end
            app.kgfButton.Value = true;
            app.kgcm2Button.Value = true;
            app.kgButton.Value = true;
            app.cmButton.Value = true;
            if strcmp(app.UNISYS,'SI')
                app.Conversion_Unit_Force_scaling = 1e-1;
                app.Conversion_Unit_Pressure_scaling = 1e-1*1e4;
                app.Conversion_Unit_Mass_scaling = 1;
                app.Conversion_Unit_Length_scaling = 1e2;
            elseif strcmp(app.UNISYS,'Nmm')
                app.Conversion_Unit_Force_scaling = 1e-1;
                app.Conversion_Unit_Pressure_scaling = 1e-3;
                app.Conversion_Unit_Mass_scaling = 1e3;
                app.Conversion_Unit_Length_scaling = 1e-1;
            elseif strcmp(app.UNISYS,'kgcm')
                app.Conversion_Unit_Force_scaling = 1;
                app.Conversion_Unit_Pressure_scaling = 1;
                app.Conversion_Unit_Mass_scaling = 1;
                app.Conversion_Unit_Length_scaling = 1;
            else
            end
            app.Conversion_Unit_Specific_Weight_scaling = app.Conversion_Unit_Mass_scaling/app.Conversion_Unit_Length_scaling^3;
            app.SpecificWeightEditField.Value = app.SpecificWeightEditField.Value*app.Conversion_Unit_Specific_Weight_scaling;
            app.ThicknessEditField.Value = app.ThicknessEditField.Value*app.Conversion_Unit_Length_scaling;
            app.Unit_Mass_to_Weight = 1e1;
            app.NLabel.Text = [ ' [ ', app.ForceButtonGroup.SelectedObject.Text, ' ]' ];
            app.PaLabel.Text = [ ' [ ', app.ModulusStressButtonGroup.SelectedObject.Text, ' ]' ];
            app.kgLabel.Text = [ ' [ ', app.MassButtonGroup.SelectedObject.Text, ' ]' ];
            app.mLabel.Text = [ ' [ ', app.LengthButtonGroup.SelectedObject.Text, ' ]' ];
            app.PixelEditField.Value = 0.1; % 1 Pixel = 1000 mm = 0.1 * 1000cm
            app.Unit_Length_scaling = 1e-1;
            app.Unit_Length_scaling = app.Conversion_Unit_Length_scaling;
            app.UNISYS = 'kgcm';
            compute_Blocks_geometrical_features(app)
        end

        % Button pushed function: FlipYaxisButton
        function FlipYaxisButtonPushed(app, event)
            set(app.UIAxes, 'YDir','normal')

        end

        % Button pushed function: PlotReactionsButton
        function PlotReactionsButtonPushed(app, event)
            if ~isempty(app.Reaction_at_A)
                % Compute structure dimension for scaling arrow lengths
                all_x_coords = [];
                all_y_coords = [];
                for k = 1:app.Number_of_Blocks
                    all_x_coords = [all_x_coords, app.Blocks_coordinates_4_points{k, 1}];
                    all_y_coords = [all_y_coords, app.Blocks_coordinates_4_points{k, 2}];
                end
                structure_dimension = sqrt((max(all_x_coords) - min(all_x_coords))^2 + (max(all_y_coords) - min(all_y_coords))^2);

                scaling_factor = (1/40)*structure_dimension/max(max(app.Reaction_at_A));
                % Reaction Plot
                %xyA = app.Reaction_at_A*app.forcesfactorEditField.Value/3;
                xyA = app.Reaction_at_A*scaling_factor;
                xyA(:,1) =xyA(:,1) - xyA(2,1);
                xyA(:,2) =xyA(:,2) - xyA(2,2);
                xyA = xyA +[app.xy_Point_A; app.xy_Point_A];
                %xyB = app.Reaction_at_B*app.forcesfactorEditField.Value/3;%+[app.xy_Point_B; app.xy_Point_B];
                xyB = app.Reaction_at_B*scaling_factor;
                xyB(:,1) =xyB(:,1) - xyB(2,1);
                xyB(:,2) =xyB(:,2) - xyB(2,2);
                xyB = xyB +[app.xy_Point_B; app.xy_Point_B];
                drawArrow_G(app, app.UIAxes,[xyA(1,1), xyA(2,2)], xyA(2,:), 'r');
                drawArrow_G(app, app.UIAxes,[xyB(1,1), xyB(2,2)], xyB(2,:), 'r');
                drawArrow_G(app, app.UIAxes,[xyA(2,1), xyA(1,2)], xyA(2,:), 'b');
                drawArrow_G(app, app.UIAxes,[xyB(2,1), xyB(1,2)], xyB(2,:), 'b');
                drawArrow_G(app, app.UIAxes,xyA(1,:),xyA(2,:), 'k');
                drawArrow_G(app, app.UIAxes,xyB(1,:),xyB(2,:), 'k');
                ComputeReactions(app)
                % Plot reaction values in engineering format
                formatSpec = '%.2e';  % Format for engineering notation with two decimal places
                text(app.UIAxes, mean(xyA(:, 1)), mean(xyA(:, 2)), ...
                    [num2str(app.Reaction_at_A_Components(1), formatSpec), ' ', app.ForceButtonGroup.SelectedObject.Text, ' '], ...
                    "FontSize", 16, "HorizontalAlignment", "right", "Color", 'k', "VerticalAlignment", "bottom", "BackgroundColor", 'w');

                text(app.UIAxes, mean(xyA(:, 1)), mean(xyA(2, 2)), ...
                    [num2str(app.Reaction_at_A_Components(2), formatSpec), ' ', app.ForceButtonGroup.SelectedObject.Text, ' '], ...
                    "FontSize", 16, "HorizontalAlignment", "right", "Color", 'r', "VerticalAlignment", "top", "BackgroundColor", 'w');

                text(app.UIAxes, mean(xyA(2, 1)), mean(xyA(:, 2)), ...
                    [' ', num2str(app.Reaction_at_A_Components(3), formatSpec), ' ', app.ForceButtonGroup.SelectedObject.Text], ...
                    "FontSize", 16, "HorizontalAlignment", "left", "Color", 'b', "VerticalAlignment", "top", "BackgroundColor", 'w');

                text(app.UIAxes, mean(xyB(:, 1)), mean(xyB(:, 2)), ...
                    [' ', num2str(app.Reaction_at_B_Components(1), formatSpec), ' ', app.ForceButtonGroup.SelectedObject.Text], ...
                    "FontSize", 16, "HorizontalAlignment", "left", "Color", 'k', "VerticalAlignment", "bottom", "BackgroundColor", 'w');

                text(app.UIAxes, mean(xyB(:, 1)), mean(xyB(2, 2)), ...
                    [' ', num2str(app.Reaction_at_B_Components(2), formatSpec), ' ', app.ForceButtonGroup.SelectedObject.Text], ...
                    "FontSize", 16, "HorizontalAlignment", "left", "Color", 'r', "VerticalAlignment", "top", "BackgroundColor", 'w');

                text(app.UIAxes, mean(xyB(2, 1)), mean(xyB(:, 2)), ...
                    [' ', num2str(app.Reaction_at_B_Components(3), formatSpec), ' ', app.ForceButtonGroup.SelectedObject.Text, ' '], ...
                    "FontSize", 16, "HorizontalAlignment", "right", "Color", 'b', "VerticalAlignment", "top", "BackgroundColor", 'w');
            else
            end
        end

        % Button pushed function: ViewEditblockdataButton
        function ViewEditblockdataButtonPushed(app, event)
            % Close any existing secondary figure if it exists and is valid
            if ~isempty(app.UIFigure_table) && isvalid(app.UIFigure_table)
                close(app.UIFigure_table);
            end

            % Define standard cell dimensions (adjust if needed)
            cellWidth = 100;   % Width per column
            cellHeight = 22;   % Height per row

            % Calculate required table dimensions
            numCols = size(app.UITable.Data, 2);
            numRows = size(app.UITable.Data, 1);
            tableWidth = numCols * cellWidth;
            tableHeight = numRows * cellHeight;

            % Ensure the figure is not too large by setting max dimensions
            maxWidth = 0.9 * app.UIFigure.Position(3);
            maxHeight = 0.9 * app.UIFigure.Position(4);
            tableWidth = min(tableWidth, maxWidth);
            tableHeight = min(tableHeight, maxHeight);

            % Create the secondary figure in app
            app.UIFigure_table = uifigure('Name', 'Blocks currently defined');
            app.UIFigure_table.Position = [app.UIFigure.Position(1:2) + 20, tableWidth + 40, tableHeight + 120];

            % Retrieve data from the main table
            tdata =  [app.UITable.Data, app.xyg_Blocks]; %app.UITable.Data;

            % Create a uitable with only the thickness column editable
            uit = uitable(app.UIFigure_table, 'Data', tdata, ...
                'ColumnEditable', [false, false, true, false, false, false], ...  % Only allow editing of thickness
                'ColumnName', { ...
                'N.', ...
                ['Area [ ', app.LengthButtonGroup.SelectedObject.Text, '^2 ]'], ...
                ['Thickness [ ', app.LengthButtonGroup.SelectedObject.Text, ' ]'], ...
                ['Weight [ ', app.ForceButtonGroup.SelectedObject.Text, ' ]'], ...
                ['xG [ ', app.LengthButtonGroup.SelectedObject.Text, ' ]'], ...
                ['yG [ ', app.LengthButtonGroup.SelectedObject.Text, ' ]'] ...
                }, "ColumnSortable", true);

            % Set table position slightly lower to make room for buttons at the top
            uit.Position = [20, 80, tableWidth, tableHeight];  % Adjusted to leave space for buttons

            % Create "Save" and "Cancel" buttons at the top right
            btnSave = uibutton(app.UIFigure_table, 'push', 'Text', 'Save', ...
                'Position', [app.UIFigure_table.Position(3) - 180, app.UIFigure_table.Position(4) - 40, 80, 30], ...  % Save button position
                'ButtonPushedFcn', @(btnSave, event) saveTableData(app, uit.Data));

            btnCancel = uibutton(app.UIFigure_table, 'push', 'Text', 'Cancel', ...
                'Position', [app.UIFigure_table.Position(3) - 90, app.UIFigure_table.Position(4) - 40, 80, 30], ...  % Cancel button position
                'ButtonPushedFcn', @(btnCancel, event) close(app.UIFigure_table));  % Closes the secondary figure
        end

        % Callback function
        function EditdataButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.EditdataButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.DialogAppTable = DialogAppEditTable(app, app.UNISYS, app.UITable);
        end

        % Button pushed function: CheckadmissbilityButton
        function CheckadmissbilityButtonPushed(app, event)
            % these are the coordinates of the block
            coordinates_of_all_the_blocks = app.Blocks_coordinates_4_points;
            coordinates_of_first_block = coordinates_of_all_the_blocks{1};
            i = 1;
            Block_coords = coordinates_of_all_the_blocks{i};
            coordinates_of_the_LOT = app.LOT_xy;
            magnitude_of_LOT = app.LOT_Force; % this is the N
            N = magnitude_of_LOT(1);
            %
            % Now compute the intersection between edges and LOT
            % to do
            %
            % Now compute the eccentricity
            % to do
            eccentricity  = 1;
            %
            [ admissible ] = compute_the_admissible_domain(eccentricity, N);
            if admissible == 1
                disp("Admissible")
            else
                disp(" NOT Admissible")
            end

        end

        % Button pushed function: DefineCenterButton_3
        function DefineCenterButton_3Pushed(app, event)
            [x, y] = selectDataPoints(app.UIAxes);
            app.xy_Center(1) = x;
            app.xy_Center(2) = y;
            setup_main_figure(app)
            plot(app.UIAxes, x,y,"Color",[1,0,0],"MarkerEdgeColor",[1,0,0],"MarkerSize",5,"Marker","o", "LineWidth",2)
            text(app.UIAxes,x,y,'  Center',"FontSize",14,"HorizontalAlignment","left")

        end

        % Value changed function: PolenihpSwitch
        function PolenihpSwitchValueChanged(app, event)
            value = app.PolenihpSwitch.Value;
            if strcmp(value, 'On')
                app.UpdatedataButton.Enable = 'on';
                app.DefineCenterButton_3.Enable = 'on';
            else
                app.UpdatedataButton.Enable = 'on';
                app.DefineCenterButton_3.Enable = 'off';
            end

        end

        % Button pushed function: UpdatedataButton
        function UpdatedataButtonPushed(app, event)
            value = app.PolenihpSwitch.Value;
            if strcmp(value, 'On')

                % Recall the center coordinates
                if ~isempty(app.xy_Center)
                    xc = app.xy_Center(1);
                    yc = app.xy_Center(2);
                else
                    % Display a warning message that requests the center coordinates
                    % and allows graphical selection if missing
                    DefineCenterButton_3Pushed(app, event);  % Calls function to define the center
                    xc = app.xy_Center(1);
                    yc = app.xy_Center(2);
                end
                thickness = app.ThicknessEditField.Value * 2; % HERE WEPPUT 2 TO HAVE THE SAME (hopefully) Weigth of the constant thickness
                % Loop through each block and compute the angle_factor
                for i = 1:app.Number_of_Blocks
                    % Get the G coordinates (block centroid)
                    xg_block = app.xyg_Blocks(i, 1);
                    yg_block = app.xyg_Blocks(i, 2);

                    % Define point P coordinates
                    xp = xc;
                    yp = yc + 1;

                    % Calculate the angle between CG and CP using atan2
                    angle_block = abs(atan2(yg_block - yc, xg_block - xc) - atan2(yp - yc, xp - xc));

                    % Convert angle to degrees and ensure it is within 0 to 180 degrees
                    angle_block_deg = mod(rad2deg(angle_block), 180);

                    % Ensure the angle is within [0, 90] degrees by mirroring if greater than 90
                    if angle_block_deg > 90
                        angle_block_deg = 180 - angle_block_deg;
                    end

                    % Calculate angle_factor for each block
                    % Assuming thickness is defined for each block
                    angle_factor = (angle_block_deg / 90);  % Scale as per formula
                    app.Thickness_Blocks(i) = thickness * angle_factor;
                    app.modified_Thickness_Blocks(i) = 0;
                    % Save the computed angle factor
                    app.angle_factors(i) = angle_factor;  % Assuming angle_factors is defined to store values

                    % Optional: Display or log each computed angle_factor
                    disp(['Block ', num2str(i), ': angle_factor = ', num2str(angle_factor)]);

                end
                % update the geometrical features
                compute_Blocks_geometrical_features(app);
            else
                for i = 1:app.Number_of_Blocks
                    app.Thickness_Blocks(i) = app.ThicknessEditField.Value;
                end
                % update the geometrical features
                compute_Blocks_geometrical_features(app);
            end
        end

        % Button pushed function: PlotGeometryButton
        function PlotGeometryButtonPushed(app, event)
            % Create one figure for all toroidal shapes
            cla(app.UIAxes3);
            setup_3D_figure(app)
            % Collect all x- and y-coordinates from each block into a single array
            all_y_coords = [];
            all_x_coords = [];
            for k = 1:app.Number_of_Blocks
                all_y_coords = [all_y_coords, app.Blocks_coordinates_4_points{k, 2}];  % Append x-coordinates of each block
                all_x_coords = [all_x_coords, app.Blocks_coordinates_4_points{k, 1}];  % Append x-coordinates of each block
            end
            min_X = min(all_x_coords);
            max_X = max(all_x_coords);
            min_Y = min(all_y_coords);
            max_Y = max(all_y_coords);
            value = app.PolenihpSwitch.Value;
            if strcmp(value, 'On')
                angle_diff = app.AngledegEditField_2.Value/2;
                app.UIAxes3.View = [-45-angle_diff 30];
                method =2;
                if method ==1
                    % Loop through each block to plot its toroidal extrusion and calculate volume
                    for k = 1:app.Number_of_Blocks
                        % Define the profile based on app.Blocks_coordinates_4_points
                        profile_x = app.Blocks_coordinates_4_points{k, 1};  % x-coordinates
                        profile_y = app.Blocks_coordinates_4_points{k, 2};  % y-coordinates

                        % Define the rectangle coordinates
                        x = [profile_x , profile_x(1)];
                        y = [profile_y, profile_y(1)];
                        z = zeros(size(x));  % Initial z-coordinates (all zeros)

                        % Rotation angle range (15 degrees in radians)
                        theta = linspace(0, deg2rad(app.AngledegEditField_2.Value), round(36*app.AngledegEditField_2.Value/360));  % 50 increments up to 15 degrees
                        dtheta = theta(2) - theta(1);  % Small angle increment in radians

                        % Preallocate arrays for storing rotated coordinates and initialize volume
                        X = zeros(length(x), length(theta));
                        Y = zeros(length(x), length(theta));  % Original Y will become Z
                        Z = zeros(length(x), length(theta));  % Original Z will become Y
                        total_volume = 0;

                        % Center of rotation
                        xc = app.xy_Center(1);  % Center x-coordinate
                        yc = app.xy_Center(2);  % Center y-coordinate
                        zc = 0;

                        % Loop through each angle and calculate rotated coordinates
                        for i = 1:length(theta)
                            % Rotation matrix for rotation around y-axis in the X-Z plane
                            rotation_matrix = [cos(theta(i)), 0, sin(theta(i));
                                0,           1,           0;
                                -sin(theta(i)), 0, cos(theta(i))];

                            % Rotate each corner of the rectangle around the Y-axis through the center
                            for j = 1:length(x)
                                % Shift rectangle point to origin around center of rotation
                                original_point = [x(j) - xc; y(j) - yc; z(j) - zc];

                                % Apply rotation and shift back
                                rotated_point = rotation_matrix * original_point + [xc; yc; zc];

                                % Store rotated coordinates with Y as Z and Z as Y
                                X(j, i) = rotated_point(1);
                                Y(j, i) = rotated_point(2);  %
                                Z(j, i) = rotated_point(3);  %
                            end

                            % Calculate the cross-sectional area of the rectangle
                            rectangle_width = max(x) - min(x);
                            rectangle_height = max(y) - min(y);
                            slice_area = rectangle_width * rectangle_height;

                            % Calculate the radial distance from the axis for this slice
                            radius = abs(X(1, i));

                            % Approximate volume of this slice (differential volume element)
                            slice_volume = slice_area * radius * dtheta;

                            % Accumulate total volume
                            total_volume = total_volume + slice_volume;
                        end
                        % Set renderer to OpenGL (improves edge visibility in some cases)
                        %                     set(app.UIAxes3, 'Renderer', 'opengl');

                        % Plot the 3D toroidal surface with no transparency to ensure edges display
                        surf(app.UIAxes3, X, Z, Y - min_Y, 'FaceAlpha', 1, 'EdgeColor', 'k', 'EdgeAlpha', 1, 'FaceColor', [0.6, 0.3, 0.1]);

                        % Close the volume by adding patches at the first and last profiles with no transparency
                        % First profile patch
                        patch(app.UIAxes3, X(:, 1), Z(:, 1), Y(:, 1) - min_Y, [0.6, 0.3, 0.1], 'FaceAlpha', 1, 'EdgeColor', 'k', 'EdgeAlpha', 1);

                        % Last profile patch
                        patch(app.UIAxes3, X(:, end), Z(:, end), Y(:, end) - min_Y, [0.6, 0.3, 0.1], 'FaceAlpha', 1, 'EdgeColor', 'k', 'EdgeAlpha', 1);

                    end
                else
                    % Initialize modified_Thickness_Blocks if it is empty
                    if isempty(app.modified_Thickness_Blocks)
                        app.modified_Thickness_Blocks = zeros(app.Number_of_Blocks);  % Initialize with zeros
                    else
                        if size(app.modified_Thickness_Blocks) ~= app.Number_of_Blocks
                            app.modified_Thickness_Blocks = zeros(app.Number_of_Blocks);  % Initialize with zeros
                        else
                        end

                    end
                    % Loop through each block to plot its toroidal extrusion and calculate volume
                    count = 0;
                    for k = 1:app.Number_of_Blocks
                        % Define the profile based on app.Blocks_coordinates_4_points
                        profile_x = app.Blocks_coordinates_4_points{k, 1};  % x-coordinates
                        profile_y = app.Blocks_coordinates_4_points{k, 2};  % y-coordinates

                        % Define the rectangle coordinates
                        x = [profile_x, profile_x(1)];
                        y = [profile_y, profile_y(1)];
                        z = zeros(size(x));  % Initial z-coordinates (all zeros)

                        % Center of rotation
                        xc = app.xy_Center(1);  % Center x-coordinate
                        yc = app.xy_Center(2);  % Center y-coordinate
                        zc = 0;

                        % Calculate the centroid of the block profile for chord calculations
                        centroid_x = mean(x);
                        centroid_y = mean(y);
                        radius_to_centroid = sqrt((centroid_x - xc)^2 + (centroid_y - yc)^2);

                        % Check if thickness modification is needed for this block
                        if app.modified_Thickness_Blocks(k) == 1
                            if count == 0
                                % Compute theta limit based on thickness
                                thickness = app.Thickness_Blocks(k);
                                theta_limit = 2 * asin(thickness / (2 * radius_to_centroid));  % Limiting angle to match thickness
                                count = count +1;
                            else
                            end

                            %theta = linspace(0, theta_limit, (36 * theta_limit / (360)));
                            theta = linspace(0, theta_limit, length(theta));

                            % Set color for modified blocks
                            face_color = [0.8, 0.5, 0.3];  % Different color for modified blocks
                        else
                            % Default theta range for unmodified blocks
                            theta = linspace(0, deg2rad(app.AngledegEditField_2.Value), round(36 * app.AngledegEditField_2.Value / 360));

                            % Default color for unmodified blocks
                            face_color = [0.6, 0.3, 0.1];
                        end
%                         k
%                         app.modified_Thickness_Blocks(k)
%                         theta
                        dtheta = theta(2) - theta(1);  % Small angle increment in radians

                        % Preallocate arrays for storing rotated coordinates and initialize volume
                        X = zeros(length(x), length(theta));
                        Y = zeros(length(x), length(theta));
                        Z = zeros(length(x), length(theta));
                        total_volume = 0;

                        % Loop through each angle and calculate rotated coordinates
                        for i = 1:length(theta)
                            % Rotation matrix for rotation around y-axis in the X-Z plane
                            rotation_matrix = [cos(theta(i)), 0, sin(theta(i));
                                0,           1,           0;
                                -sin(theta(i)), 0, cos(theta(i))];

                            % Rotate each corner of the rectangle around the Y-axis through the center
                            for j = 1:length(x)
                                % Shift rectangle point to origin around center of rotation
                                original_point = [x(j) - xc; y(j) - yc; z(j) - zc];

                                % Apply rotation and shift back
                                rotated_point = rotation_matrix * original_point + [xc; yc; zc];

                                % Store rotated coordinates with Y as Z and Z as Y
                                X(j, i) = rotated_point(1);
                                Y(j, i) = rotated_point(2);
                                Z(j, i) = rotated_point(3);
                            end

                            % Calculate the cross-sectional area of the rectangle
                            rectangle_width = max(x) - min(x);
                            rectangle_height = max(y) - min(y);
                            slice_area = rectangle_width * rectangle_height;

                            % Calculate the radial distance from the axis for this slice
                            radius = abs(X(1, i));

                            % Approximate volume of this slice (differential volume element)
                            slice_volume = slice_area * radius * dtheta;

                            % Accumulate total volume
                            total_volume = total_volume + slice_volume;
                        end

                        % Plot the 3D toroidal surface with modified color for blocks with adjusted thickness
                        surf(app.UIAxes3, X, Z, Y - min_Y, 'FaceAlpha', 1, 'EdgeColor', 'k', 'EdgeAlpha', 1, 'FaceColor', face_color);

                        % Close the volume by adding patches at the first and last profiles
                        patch(app.UIAxes3, X(:, 1), Z(:, 1), Y(:, 1) - min_Y, face_color, 'FaceAlpha', 1, 'EdgeColor', 'k', 'EdgeAlpha', 1);
                        patch(app.UIAxes3, X(:, end), Z(:, end), Y(:, end) - min_Y, face_color, 'FaceAlpha', 1, 'EdgeColor', 'k', 'EdgeAlpha', 1);
                    end
                end
                % Center coordinates
                x_center = app.xy_Center(1);
                y_center = 0;
            else
                angle_diff = 0;
                app.UIAxes3.View = [-45-angle_diff 30];
                % Loop through each block to plot its toroidal extrusion and calculate volume
                for k = 1:app.Number_of_Blocks
                    % Define the profile based on app.Blocks_coordinates_4_points
                    profile_x = app.Blocks_coordinates_4_points{k, 1};  % x-coordinates
                    profile_y = app.Blocks_coordinates_4_points{k, 2};  % y-coordinates

                    % Define the rectangle coordinates
                    x = [profile_x , profile_x(1)];
                    y = [profile_y, profile_y(1)];
                    z = zeros(size(x));  % Initial z-coordinates (all zeros)

                    % Rotation angle range (15 degrees in radians)
                    %                     Z_values = [0 app.ThicknessEditField.Value];
                    Z_values = [0 app.Thickness_Blocks(k)];


                    % Preallocate arrays for storing rotated coordinates and initialize volume
                    X = zeros(length(x), length(Z_values));
                    Y = zeros(length(x), length(Z_values));  % Original Y will become Z
                    Z = zeros(length(x), length(Z_values));  % Original Z will become Y
                    total_volume = 0;
                    for j = 1:length(x)
                        for i = 1:length(Z_values)
                            original_point = [x(j); y(j); z(j)];

                            % Apply rotation and shift back
                            shifted_point =  original_point + [0; 0; Z_values(i)];

                            % Store rotated coordinates with Y as Z and Z as Y
                            X(j, i) = shifted_point(1);
                            Y(j, i) = shifted_point(2);  %
                            Z(j, i) = shifted_point(3);
                        end
                    end
                    % Plot the 3D toroidal surface with no transparency to ensure edges display
                    surf(app.UIAxes3, X, Z, Y - min_Y, 'FaceAlpha', 1, 'EdgeColor', 'k', 'EdgeAlpha', 1, 'FaceColor', [0.6, 0.3, 0.1]);

                    % Close the volume by adding patches at the first and last profiles with no transparency
                    % First profile patch
                    patch(app.UIAxes3, X(:, 1), Z(:, 1), Y(:, 1) - min_Y, [0.6, 0.3, 0.1], 'FaceAlpha', 1, 'EdgeColor', 'k', 'EdgeAlpha', 1);

                    % Last profile patch
                    patch(app.UIAxes3, X(:, end), Z(:, end), Y(:, end) - min_Y, [0.6, 0.3, 0.1], 'FaceAlpha', 1, 'EdgeColor', 'k', 'EdgeAlpha', 1);
                end
                % Center coordinates
                x_center = app.xy_Center(1);
                y_center = app.ThicknessEditField.Value/2;
            end


            % Calculate the radius based on the horizontal extent of all blocks
            radius = (max(all_x_coords) - min(all_x_coords)) / 2;

            % Define the angle range for the circle
            theta = linspace(0, 2*pi, 100);

            % Parametric equations for the circle
            x_circle = x_center + radius * cos(theta);
            y_circle = y_center + radius * sin(theta);
            z_circle = zeros(size(x_circle));  % Define Z coordinates to keep the circle in the XY plane at a specific height

            % Plot the circle in the XY plane of UIAxes3
            plot3(app.UIAxes3, x_circle, y_circle, z_circle, 'k--', 'LineWidth', 1.5);  % Dashed circle
            plot3(app.UIAxes3, x_center, y_center, 0, 'ro', 'MarkerFaceColor', 'r');  % Center point in red
            plot3(app.UIAxes3, [x_center,x_center], [y_center,y_center], [0, max_Y-min_Y], 'k--', 'LineWidth', 1.5);  % Dashed circle
            plot3(app.UIAxes3, x_center, y_center, max_Y-min_Y, 'ro', 'MarkerFaceColor', 'r');  % Center point in red


        end

        % Button pushed function: ScaleBlocksButton
        function ScaleBlocksButtonPushed(app, event)

            try

                % Calculate the scaling factor
                scaling_factor = app.scalefactorEditField_2.Value;

                % Update the blocks' coordinates by scaling with respect to the reference point
                for k = 1:app.Number_of_Blocks
                    % Get the current block's coordinates
                    current_x = app.Blocks_coordinates_4_points{k, 1};
                    current_y = app.Blocks_coordinates_4_points{k, 2};
                    ReferencePoint(1) = 0;
                    ReferencePoint(2) = 0;
                    % Scale the block's coordinates relative to the reference point
                    new_x = ReferencePoint(1) + scaling_factor * (current_x - ReferencePoint(1));
                    new_y = ReferencePoint(2) + scaling_factor * (current_y - ReferencePoint(2));

                    % Update the block's coordinates in app data
                    app.Blocks_coordinates_4_points{k, 1} = new_x;
                    app.Blocks_coordinates_4_points{k, 2} = new_y;
                end
                app = compute_Blocks_geometrical_features(app);
                % Refresh the UI (optional: you may have a function to re-plot blocks)
                RefreshPlot(app);  % Ensure this function exists to refresh the blocks on the UI
                ResetViewButtonPushed(app, event)
            catch
                % Handle errors if any (e.g., user cancels the drawline operation)
            end
        end

        % Button pushed function: TranslateBlocksButton
        function TranslateBlocksButtonPushed(app, event)
            delete(app.Reference_Points)
            delete(app.Reference_Points_text)
            try
                app.Reference_Points = drawline(app.UIAxes, 'Stripecolor',[1 1 1], 'Color', 'k', 'LineWidth', 1);
                a = app.Reference_Points.Position;
                app.Reference_distance = sqrt(sum((a(1,:)-a(2,:)).^2)); %
                % update the blocks coordinate using a (from the first
                % point to the second point
                % Calculate the translation vector (from first point to second point)
                translation_vector = a(2, :) - a(1, :);

                % Store translation distance and angle for reference
                app.Reference_distance = norm(translation_vector);

                % Update the blocks' coordinates using the translation vector
                for k = 1:app.Number_of_Blocks
                    % Get the current block's coordinates
                    current_x = app.Blocks_coordinates_4_points{k, 1};
                    current_y = app.Blocks_coordinates_4_points{k, 2};

                    % Translate the block's coordinates
                    new_x = current_x + translation_vector(1);
                    new_y = current_y + translation_vector(2);

                    % Update the block's coordinates in app data
                    app.Blocks_coordinates_4_points{k, 1} = new_x;
                    app.Blocks_coordinates_4_points{k, 2} = new_y;
                end
                app = compute_Blocks_geometrical_features(app);
                % Refresh the UI (optional: you may have a function to re-plot blocks)
                RefreshPlot(app);  % Ensure this function exists to refresh the blocks on the UI
                ResetViewButtonPushed(app, event)
            catch
                % Handle errors if any (e.g., user cancels the drawline operation)
            end

        end

        % Button pushed function: SliderButton
        function SliderButtonPushed(app, event)
            scale_factor = 0.7;
            app.minEditField.Value = app.DefineHorizontalThrustEditField.Value * scale_factor;
            app.maxEditField.Value = app.DefineHorizontalThrustEditField.Value * (1/scale_factor);
        end

        % Button pushed function: InspectLineofthrustButton
        function InspectLineofthrustButtonPushed(app, event)
                        % Close any existing secondary figure if it exists and is valid
            if ~isempty(app.UIFigure_LOT) && isvalid(app.UIFigure_LOT)
                close(app.UIFigure_LOT);
            end

            % Define standard cell dimensions (adjust if needed)
            cellWidth = 100;   % Width per column
            cellHeight = 22;   % Height per row

            % Calculate required table dimensions
            numCols = size(app.UITable.Data, 2);
            numRows = size(app.UITable.Data, 1);
            tableWidth = numCols * cellWidth;
            tableHeight = numRows * cellHeight;

            % Ensure the figure is not too large by setting max dimensions
            maxWidth = 0.9 * app.UIFigure.Position(3);
            maxHeight = 0.9 * app.UIFigure.Position(4);
            tableWidth = min(tableWidth, maxWidth);
            tableHeight = min(tableHeight, maxHeight);

            % Create the secondary figure in app
            app.UIFigure_LOT = uifigure('Name', 'Blocks currently defined');
            app.UIFigure_LOT.Position = [app.UIFigure.Position(1:2) + 20, tableWidth + 40, tableHeight + 120];

            % Retrieve data from the main table
            tdata = app.UITable2.Data;

            % Create a uitable with only the thickness column editable
            uit = uitable(app.UIFigure_LOT, 'Data', tdata, ...
                'ColumnEditable', [false, false], ...  % Only allow editing of thickness
                'ColumnName', { ...
                'ID', ...
                ['Thrust [ ', app.ForceButtonGroup.SelectedObject.Text, ' ]'], ...
                }, "ColumnSortable", true);

            % Set table position slightly lower to make room for buttons at the top
            uit.Position = [20, 80, tableWidth, tableHeight];  % Adjusted to leave space for buttons


            btnCancel = uibutton(app.UIFigure_LOT, 'push', 'Text', 'Close', ...
                'Position', [app.UIFigure_LOT.Position(3) - 90, app.UIFigure_LOT.Position(4) - 40, 80, 30], ...  % Cancel button position
                'ButtonPushedFcn', @(btnCancel, event) close(app.UIFigure_LOT));  % Closes the secondary figure
        end

        % Value changed function: ThicknessEditField
        function ThicknessEditFieldValueChanged(app, event)
            value = app.ThicknessEditField.Value;
            app.ThicknessEditField_2.Value = value;
            app.ThicknessEditField_3.Value = value;
        end

        % Button pushed function: SelectpointButton
        function SelectpointButtonPushed(app, event)
            [app.xy_Point_F(1),app.xy_Point_F(2)] = selectDataPoints(app.UIAxes);
            Plot_point_F(app)
            if isempty(app.Forces_applied)
                app.Forces_applied = [];
                app.Number_of_Forces = 0;
                app.Forces_applied_xy = [];
            else

            end
            app.Forces_applied = [app.Forces_applied, app.MagnitudeEditField.Value];
            app.Number_of_Forces = app.Number_of_Forces +1;
            app.Forces_applied_xy = [app.Forces_applied_xy; [app.xy_Point_F(1),app.xy_Point_F(2)]];
            vxi = app.xy_Point_F(1);
            vxj = vxi;
            vyi = app.xy_Point_F(2);
            vyj = vyi - app.MagnitudeEditField.Value*app.forcesfactorEditField.Value;
            hArrow_v1 = drawArrow_G(app, app.UIAxes,[vxi, vyi],[vxj, vyj], 'r');
            update_Blocks_like(app)
            sorting_Blocks(app)
            PlotForcesButtonPushed(app, event)
        end

        % Button pushed function: DeleteallButton_2
        function DeleteallButton_2Pushed(app, event)
            app.Forces_applied_xy= [];
            app.Forces_applied = [];
            app.Number_of_Forces = 0;
            update_Blocks_like(app)
            sorting_Blocks(app)
        end

        % Button pushed function: ViewEditForcesButton
        function ViewEditForcesButtonPushed(app, event)
            % Close any existing secondary figure if it exists and is valid
            if ~isempty(app.UIFigure_forces) && isvalid(app.UIFigure_forces)
                close(app.UIFigure_forces);
            end

            % Define standard cell dimensions (adjust if needed)
            cellWidth = 100;   % Width per column
            cellHeight = 22;   % Height per row

            % Calculate required table dimensions
            numCols = 4; % id, magnitude, xf, yf
            numRows = app.Number_of_Forces;
            tableWidth = numCols * cellWidth;
            tableHeight = numRows * cellHeight;

            % Ensure the figure is not too large by setting max dimensions
            maxWidth = 0.9 * app.UIFigure.Position(3);
            maxHeight = 0.9 * app.UIFigure.Position(4);
            tableWidth = min(tableWidth, maxWidth);
            tableHeight = min(tableHeight, maxHeight);

            % Create the secondary figure in app
            app.UIFigure_forces = uifigure('Name', 'Blocks currently defined');
            app.UIFigure_forces.Position = [app.UIFigure.Position(1:2) + 20, tableWidth + 40, tableHeight + 120];

            % Retrieve data from the main table
            % Create a logical index for rows where app.Thickness_Blocks_like is equal to 1
            selectedRows = app.Blocks_like_kind == 1; % 1 is a force

            % Filter each data array using the selectedRows index
            filteredData = [ (1:app.Number_of_Blocks_like)', app.W_Blocks_like', app.xyg_Blocks_like(:,1), app.xyg_Blocks_like(:,2)];%, app.xyg_Blocks_like(:,1), app.xyg_Blocks_like(:,2)];
            tdata = filteredData(selectedRows, :);


            % Create a uitable with only the thickness column editable
            uit = uitable(app.UIFigure_forces, 'Data', tdata, ...
                'ColumnEditable', [false, true, false, false], ...  % Only allow editing of thickness
                'ColumnName', { ...
                'N.', ...
                ['Magnitude [ ', app.ForceButtonGroup.SelectedObject.Text, ' ]'], ...
                ['xG [ ', app.LengthButtonGroup.SelectedObject.Text, ' ]'], ...
                ['yG [ ', app.LengthButtonGroup.SelectedObject.Text, ' ]'] ...
                }, "ColumnSortable", true);

            % Set table position slightly lower to make room for buttons at the top
            uit.Position = [20, 80, tableWidth, tableHeight];  % Adjusted to leave space for buttons

            % Create "Save" and "Cancel" buttons at the top right
            btnSave = uibutton(app.UIFigure_forces, 'push', 'Text', 'Save', ...
                'Position', [app.UIFigure_forces.Position(3) - 180, app.UIFigure_forces.Position(4) - 40, 80, 30], ...  % Save button position
                'ButtonPushedFcn', @(btnSave, event) saveTableData_forces(app, uit.Data));

            btnCancel = uibutton(app.UIFigure_forces, 'push', 'Text', 'Cancel', ...
                'Position', [app.UIFigure_forces.Position(3) - 90, app.UIFigure_forces.Position(4) - 40, 80, 30], ...  % Cancel button position
                'ButtonPushedFcn', @(btnCancel, event) close(app.UIFigure_forces));  % Closes the secondary figure
        end

        % Value changed function: BlockThicknessEditField
        function BlockThicknessEditFieldValueChanged(app, event)
            value = app.BlockThicknessEditField.Value;
            
        end

        % Button pushed function: PointsCentrButton
        function PointsCentrButtonPushed(app, event)
            % Step 1: Select and Plot Point 1
            [x, y] = selectDataPoints(app.UIAxes);
            p1 = [x, y];
            plot(app.UIAxes, x, y, "Color", [1, 0, 0], "MarkerEdgeColor", [1, 0, 0], ...
                "MarkerSize", 5, "Marker", "o", "LineWidth", 2);
            text(app.UIAxes, x, y, '  Point 1', "FontSize", 14, "HorizontalAlignment", "left");

            % Step 2: Select and Plot Point 2
            [x, y] = selectDataPoints(app.UIAxes);
            p2 = [x, y];
            plot(app.UIAxes, x, y, "Color", [1, 0, 0], "MarkerEdgeColor", [1, 0, 0], ...
                "MarkerSize", 5, "Marker", "o", "LineWidth", 2);
            text(app.UIAxes, x, y, '  Point 2', "FontSize", 14, "HorizontalAlignment", "left");

            % Step 3: Select and Plot Point 3
            [x, y] = selectDataPoints(app.UIAxes);
            p3 = [x, y];
            plot(app.UIAxes, x, y, "Color", [1, 0, 0], "MarkerEdgeColor", [1, 0, 0], ...
                "MarkerSize", 5, "Marker", "o", "LineWidth", 2);
            text(app.UIAxes, x, y, '  Point 3', "FontSize", 14, "HorizontalAlignment", "left");
            % Ensure points are column vectors
            p1 = p1(:);
            p2 = p2(:);
            p3 = p3(:);

            % Compute midpoints of segments
            mid1 = (p1 + p2) / 2;
            mid2 = (p2 + p3) / 2;

            % Direction vectors
            dir1 = p2 - p1;
            dir2 = p3 - p2;

            % Perpendicular vectors
            perp1 = [-dir1(2); dir1(1)];
            perp2 = [-dir2(2); dir2(1)];

            % Solve linear system for intersection of perpendicular bisectors
            A = [perp1, -perp2];
            b = mid2 - mid1;
            t = A \ b;

            % Compute center
            center = mid1 + t(1) * perp1;
            plot(app.UIAxes, center(1), center(2), "Color", [0, 0, 1], "MarkerEdgeColor", [0, 0, 1], ...
                "MarkerSize", 8, "Marker", "x", "LineWidth", 2);
            text(app.UIAxes, center(1), center(2), '  Center', "FontSize", 14, "HorizontalAlignment", "left");

            % Optional: Plot the Circle
            radius = norm(p1 - center); % Radius of the circle
            THICN = app.OuterradEditField.Value - app.InnerradEditField.Value;
            app.InnerradEditField.Value = radius;
            app.OuterradEditField.Value = radius + THICN;

            theta = linspace(0, 2*pi, 100); % Circle parameterization
            circleX = center(1) + radius * cos(theta);
            circleY = center(2) + radius * sin(theta);
            plot(app.UIAxes, circleX, circleY, "Color", [0, 0.5, 0.5], "LineWidth", 1.5);
            app.xy_Center(1) = center(1);
            app.xy_Center(2) = center(2);

            function angle_deg = computeAngle(center, point)
                % COMPUTEANGLE Compute the angle between the line segment and the horizontal line
                %
                % INPUTS:
                %   center - [x_center, y_center]
                %   point - [x_point, y_point]
                %
                % OUTPUT:
                %   angle_deg - Angle in degrees with respect to the horizontal line

                % Calculate differences
                delta_x = point(1) - center(1);
                delta_y = point(2) - center(2);

                % Compute angle in radians
                angle_rad = atan2(delta_y, delta_x);

                % Convert to degrees
                angle_deg = rad2deg(angle_rad);
            end

            % Compute angles
            app.EndangleEditField.Value = computeAngle(center, p1);
            app.StartangleEditField.Value = computeAngle(center, p3);

            %
        end

        % Button pushed function: DefineThicknessButton
        function DefineThicknessButtonPushed(app, event)
            % Step 1: Select and Plot Point 1
            [x, y] = selectDataPoints(app.UIAxes);
            p1 = [x, y];
            plot(app.UIAxes, x, y, "Color", [1, 0, 0], "MarkerEdgeColor", [1, 0, 0], ...
                "MarkerSize", 5, "Marker", "o", "LineWidth", 2);
            text(app.UIAxes, x, y, '  Point 1', "FontSize", 14, "HorizontalAlignment", "left");

            % Step 2: Select and Plot Point 2
            [x, y] = selectDataPoints(app.UIAxes);
            p2 = [x, y];
            plot(app.UIAxes, x, y, "Color", [1, 0, 0], "MarkerEdgeColor", [1, 0, 0], ...
                "MarkerSize", 5, "Marker", "o", "LineWidth", 2);
            text(app.UIAxes, x, y, '  Point 2', "FontSize", 14, "HorizontalAlignment", "left");

            % Step 3: Calculate Euclidean Distance
            distance = sqrt((p2(1) - p1(1))^2 + (p2(2) - p1(2))^2);

            % Step 4: Update Thickness Edit Field
            app.ThicknessEditField_2.Value = distance;
            app.OuterradEditField.Value = app.InnerradEditField.Value + distance;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {803, 803};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {336, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ArchDefinitionTab = uitab(app.TabGroup);
            app.ArchDefinitionTab.Title = 'Arch Definition';
            app.ArchdefinitionPanel = uipanel(app.ArchDefinitionTab);
            app.ArchdefinitionPanel.Title = 'Arch definition';
            app.TabGroup2 = uitabgroup(app.ArchdefinitionPanel);
            app.byBlocksTab = uitab(app.TabGroup2);
            app.byBlocksTab.Title = 'by Blocks';
            app.AddrectangularblockButton = uibutton(app.byBlocksTab, 'push');
            app.AddrectangularblockButton.ButtonPushedFcn = createCallbackFcn(app, @AddrectangularblockButtonPushed, true);
            app.AddrectangularblockButton.Text = 'Add rectangular block';
            app.AddirregularBlockButton = uibutton(app.byBlocksTab, 'push');
            app.AddirregularBlockButton.ButtonPushedFcn = createCallbackFcn(app, @AddirregularBlockButtonPushed, true);
            app.AddirregularBlockButton.Text = 'Add irregular Block';
            app.NofpointsEditFieldLabel = uilabel(app.byBlocksTab);
            app.NofpointsEditFieldLabel.Text = 'N. of points';
            app.NofpointsEditField = uieditfield(app.byBlocksTab, 'numeric');
            app.NofpointsEditField.Value = 6;
            app.ThicknessEditFieldLabel = uilabel(app.byBlocksTab);
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.byBlocksTab, 'numeric');
            app.ThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessEditFieldValueChanged, true);
            app.ThicknessEditField.Value = 1;
            app.SnapSwitchLabel = uilabel(app.byBlocksTab);
            app.SnapSwitchLabel.Text = 'Snap';
            app.SnapSwitch = uiswitch(app.byBlocksTab, 'slider');
            app.PolenihpSwitchLabel = uilabel(app.byBlocksTab);
            app.PolenihpSwitchLabel.Text = 'Poleni hp.';
            app.PolenihpSwitch = uiswitch(app.byBlocksTab, 'slider');
            app.PolenihpSwitch.ValueChangedFcn = createCallbackFcn(app, @PolenihpSwitchValueChanged, true);
            app.DefineCenterButton_3 = uibutton(app.byBlocksTab, 'push');
            app.DefineCenterButton_3.ButtonPushedFcn = createCallbackFcn(app, @DefineCenterButton_3Pushed, true);
            app.DefineCenterButton_3.Text = 'Define Center';
            app.UpdatedataButton = uibutton(app.byBlocksTab, 'push');
            app.UpdatedataButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatedataButtonPushed, true);
            app.UpdatedataButton.Enable = 'off';
            app.UpdatedataButton.Text = 'Update data';
            app.PlotGeometryButton = uibutton(app.byBlocksTab, 'push');
            app.PlotGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGeometryButtonPushed, true);
            app.PlotGeometryButton.Text = 'Plot Geometry';
            app.AngledegEditField_2Label = uilabel(app.byBlocksTab);
            app.AngledegEditField_2Label.Text = 'Angle (deg)';
            app.AngledegEditField_2 = uieditfield(app.byBlocksTab, 'numeric');
            app.AngledegEditField_2.Value = 45;
            app.CircularTab = uitab(app.TabGroup2);
            app.CircularTab.Title = 'Circular';
            app.CalculateArchButton = uibutton(app.CircularTab, 'push');
            app.CalculateArchButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateArchButtonPushed, true);
            app.CalculateArchButton.Text = 'Calculate Arch';
            app.NofBlocksEditFieldLabel = uilabel(app.CircularTab);
            app.NofBlocksEditFieldLabel.Text = 'N. of Blocks';
            app.NofBlocksEditField = uieditfield(app.CircularTab, 'numeric');
            app.NofBlocksEditField.ValueChangedFcn = createCallbackFcn(app, @NofBlocksEditFieldValueChanged, true);
            app.NofBlocksEditField.Value = 10;
            app.InnerradEditFieldLabel = uilabel(app.CircularTab);
            app.InnerradEditFieldLabel.Text = 'Inner rad.';
            app.InnerradEditField = uieditfield(app.CircularTab, 'numeric');
            app.InnerradEditField.Value = 10;
            app.OuterradEditFieldLabel = uilabel(app.CircularTab);
            app.OuterradEditFieldLabel.Text = 'Outer rad.';
            app.OuterradEditField = uieditfield(app.CircularTab, 'numeric');
            app.OuterradEditField.Value = 12;
            app.DefineCenterButton_2 = uibutton(app.CircularTab, 'push');
            app.DefineCenterButton_2.ButtonPushedFcn = createCallbackFcn(app, @DefineCenterButtonPushed, true);
            app.DefineCenterButton_2.Text = 'Define Center';
            app.EndangleEditFieldLabel = uilabel(app.CircularTab);
            app.EndangleEditFieldLabel.Text = 'End angle';
            app.EndangleEditField = uieditfield(app.CircularTab, 'numeric');
            app.EndangleEditField.Value = 165;
            app.ThicknessEditField_2Label = uilabel(app.CircularTab);
            app.ThicknessEditField_2Label.Text = 'Thickness';
            app.ThicknessEditField_2 = uieditfield(app.CircularTab, 'numeric');
            app.ThicknessEditField_2.Value = 1;
            app.KeepexistinggeometrySwitchLabel = uilabel(app.CircularTab);
            app.KeepexistinggeometrySwitchLabel.Text = 'Keep existing geometry?';
            app.KeepexistinggeometrySwitch = uiswitch(app.CircularTab, 'slider');
            app.KeepexistinggeometrySwitch.Items = {'No', 'Yes'};
            app.KeepexistinggeometrySwitch.Value = 'No';
            app.StartangleEditFieldLabel = uilabel(app.CircularTab);
            app.StartangleEditFieldLabel.Text = 'Start angle ';
            app.StartangleEditField = uieditfield(app.CircularTab, 'numeric');
            app.StartangleEditField.Value = 15;
            app.AngledegEditField_3Label_2 = uilabel(app.CircularTab);
            app.AngledegEditField_3Label_2.Text = '(deg)';
            app.PointsCentrButton = uibutton(app.CircularTab, 'push');
            app.PointsCentrButton.ButtonPushedFcn = createCallbackFcn(app, @PointsCentrButtonPushed, true);
            app.PointsCentrButton.Text = '3 Points Centr';
            app.LeftLabel = uilabel(app.CircularTab);
            app.LeftLabel.Text = '( Left )';
            app.RightLabel = uilabel(app.CircularTab);
            app.RightLabel.Text = '( Right )';
            app.DefineThicknessButton = uibutton(app.CircularTab, 'push');
            app.DefineThicknessButton.ButtonPushedFcn = createCallbackFcn(app, @DefineThicknessButtonPushed, true);
            app.DefineThicknessButton.Text = 'Define Thickness';
            app.bySketchTab = uitab(app.TabGroup2);
            app.bySketchTab.Title = 'by Sketch';
            app.SketchthearchButton = uibutton(app.bySketchTab, 'push');
            app.SketchthearchButton.ButtonPushedFcn = createCallbackFcn(app, @SketchthearchButtonPushed, true);
            app.SketchthearchButton.Text = 'Sketch the arch';
            app.DefineCenterButton = uibutton(app.bySketchTab, 'push');
            app.DefineCenterButton.ButtonPushedFcn = createCallbackFcn(app, @DefineCenterButtonPushed, true);
            app.DefineCenterButton.Text = 'Define Center';
            app.ComputeblocksButton = uibutton(app.bySketchTab, 'push');
            app.ComputeblocksButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeblocksButtonPushed, true);
            app.ComputeblocksButton.Text = 'Compute blocks';
            app.SubdivideusingblocksnewaddLabel = uilabel(app.bySketchTab);
            app.SubdivideusingblocksnewaddLabel.Text = {'Subdivide '; 'using blocks '; '(new/add):'};
            app.SubdivideusingblocksnewaddEditField = uieditfield(app.bySketchTab, 'numeric');
            app.SubdivideusingblocksnewaddEditField.Value = 5;
            app.DefinePointAButton_2 = uibutton(app.bySketchTab, 'push');
            app.DefinePointAButton_2.ButtonPushedFcn = createCallbackFcn(app, @DefinePointAButtonPushed, true);
            app.DefinePointAButton_2.Text = 'Define Point A';
            app.DefinePointBButton_2 = uibutton(app.bySketchTab, 'push');
            app.DefinePointBButton_2.ButtonPushedFcn = createCallbackFcn(app, @DefinePointBButtonPushed, true);
            app.DefinePointBButton_2.Text = 'Define Point B';
            app.ThicknessEditField_3Label = uilabel(app.bySketchTab);
            app.ThicknessEditField_3Label.Text = 'Thickness';
            app.ThicknessEditField_3 = uieditfield(app.bySketchTab, 'numeric');
            app.ThicknessEditField_3.Value = 1;
            app.LoadDataTab = uitab(app.TabGroup2);
            app.LoadDataTab.Title = 'Load Data';
            app.LoadblocksButton = uibutton(app.LoadDataTab, 'push');
            app.LoadblocksButton.ButtonPushedFcn = createCallbackFcn(app, @LoadblocksButtonPushed, true);
            app.LoadblocksButton.Text = 'Load blocks';
            app.BlockdataPanel = uipanel(app.ArchDefinitionTab);
            app.BlockdataPanel.Title = 'Block data';
            app.UITable = uitable(app.BlockdataPanel);
            app.UITable.ColumnName = {'N.'; 'A'; 't'; 'W'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.DeleteallButton = uibutton(app.BlockdataPanel, 'push');
            app.DeleteallButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteallButtonPushed, true);
            app.DeleteallButton.Text = 'Delete all ';
            app.DeletebyNButton = uibutton(app.BlockdataPanel, 'push');
            app.DeletebyNButton.ButtonPushedFcn = createCallbackFcn(app, @DeletebyNButtonPushed, true);
            app.DeletebyNButton.Text = 'Delete by N.';
            app.NEditFieldLabel = uilabel(app.BlockdataPanel);
            app.NEditFieldLabel.Text = 'N.:';
            app.NEditField = uieditfield(app.BlockdataPanel, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.ViewEditblockdataButton = uibutton(app.BlockdataPanel, 'push');
            app.ViewEditblockdataButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEditblockdataButtonPushed, true);
            app.ViewEditblockdataButton.Text = 'View/Edit block data';
            app.BackgroundImagePanel = uipanel(app.ArchDefinitionTab);
            app.BackgroundImagePanel.Title = 'Background Image';
            app.LoadimageButton = uibutton(app.BackgroundImagePanel, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.FlipXButton = uibutton(app.BackgroundImagePanel, 'push');
            app.FlipXButton.ButtonPushedFcn = createCallbackFcn(app, @FlipXButtonPushed, true);
            app.FlipXButton.Text = 'Flip X';
            app.FlipYButton = uibutton(app.BackgroundImagePanel, 'push');
            app.FlipYButton.ButtonPushedFcn = createCallbackFcn(app, @FlipYButtonPushed, true);
            app.FlipYButton.Text = 'Flip Y';
            app.ScaleImageButton = uibutton(app.BackgroundImagePanel, 'push');
            app.ScaleImageButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleImageButtonPushed, true);
            app.ScaleImageButton.Text = 'Scale Image';
            app.PickReferenceButton = uibutton(app.BackgroundImagePanel, 'push');
            app.PickReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @PickReferenceButtonPushed, true);
            app.PickReferenceButton.Text = 'Pick Reference';
            app.scalefactorEditFieldLabel = uilabel(app.BackgroundImagePanel);
            app.scalefactorEditFieldLabel.Text = 'scale factor';
            app.scalefactorEditField = uieditfield(app.BackgroundImagePanel, 'numeric');
            app.scalefactorEditField.Value = 1;
            app.LEditFieldLabel = uilabel(app.BackgroundImagePanel);
            app.LEditFieldLabel.Text = 'L:';
            app.LEditField = uieditfield(app.BackgroundImagePanel, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.LEditField.Value = 1;
            app.InteractiveSwitchLabel = uilabel(app.BackgroundImagePanel);
            app.InteractiveSwitchLabel.Text = 'Interactive';
            app.InteractiveSwitch = uiswitch(app.BackgroundImagePanel, 'slider');
            app.InteractiveSwitch.Items = {'on', 'off'};
            app.InteractiveSwitch.Value = 'on';
            app.TargetEditFieldLabel = uilabel(app.BackgroundImagePanel);
            app.TargetEditFieldLabel.Text = 'Target';
            app.TargetEditField = uieditfield(app.BackgroundImagePanel, 'numeric');
            app.TargetEditField.Value = 100;
            app.BlockeditPanel = uipanel(app.ArchDefinitionTab);
            app.BlockeditPanel.Title = 'Block edit';
            app.ScaleBlocksButton = uibutton(app.BlockeditPanel, 'push');
            app.ScaleBlocksButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleBlocksButtonPushed, true);
            app.ScaleBlocksButton.Text = 'Scale Blocks';
            app.MoveBlockNodeButton = uibutton(app.BlockeditPanel, 'push');
            app.MoveBlockNodeButton.Text = 'Move Block Node';
            app.scalefactorEditField_2Label = uilabel(app.BlockeditPanel);
            app.scalefactorEditField_2Label.Text = 'scale factor';
            app.scalefactorEditField_2 = uieditfield(app.BlockeditPanel, 'numeric');
            app.scalefactorEditField_2.Value = 1;
            app.TranslateBlocksButton = uibutton(app.BlockeditPanel, 'push');
            app.TranslateBlocksButton.ButtonPushedFcn = createCallbackFcn(app, @TranslateBlocksButtonPushed, true);
            app.TranslateBlocksButton.Text = 'Translate Blocks';
            app.ThrustLineTab = uitab(app.TabGroup);
            app.ThrustLineTab.Title = 'Thrust Line';
            app.LineofThrustPanel = uipanel(app.ThrustLineTab);
            app.LineofThrustPanel.Title = 'Line of Thrust';
            app.UITable2 = uitable(app.LineofThrustPanel);
            app.UITable2.ColumnName = {'ID'; 'Thrust'};
            app.UITable2.RowName = {};
            app.InspectLineofthrustButton = uibutton(app.LineofThrustPanel, 'push');
            app.InspectLineofthrustButton.ButtonPushedFcn = createCallbackFcn(app, @InspectLineofthrustButtonPushed, true);
            app.InspectLineofthrustButton.Text = 'Inspect Line of thrust';
            app.LineofthrustPanel = uipanel(app.ThrustLineTab);
            app.LineofthrustPanel.Title = 'Line of thrust';
            app.LOTForcefactorEditFieldLabel = uilabel(app.LineofthrustPanel);
            app.LOTForcefactorEditFieldLabel.Text = 'LOT Force factor';
            app.LOTForcefactorEditField = uieditfield(app.LineofthrustPanel, 'numeric');
            app.LOTForcefactorEditField.Value = 10;
            app.FinalFUPButton = uibutton(app.LineofthrustPanel, 'push');
            app.FinalFUPButton.ButtonPushedFcn = createCallbackFcn(app, @FinalFUPButtonPushed, true);
            app.FinalFUPButton.Text = 'Final FUP';
            app.PreliminarFUPButton = uibutton(app.LineofthrustPanel, 'push');
            app.PreliminarFUPButton.ButtonPushedFcn = createCallbackFcn(app, @PreliminarFUPButtonPushed, true);
            app.PreliminarFUPButton.Text = 'Preliminar FUP';
            app.FinalFOPButton = uibutton(app.LineofthrustPanel, 'push');
            app.FinalFOPButton.ButtonPushedFcn = createCallbackFcn(app, @FinalFOPButtonPushed, true);
            app.FinalFOPButton.Text = 'Final FOP';
            app.PreliminarFOPButton = uibutton(app.LineofthrustPanel, 'push');
            app.PreliminarFOPButton.ButtonPushedFcn = createCallbackFcn(app, @PreliminarFOPButtonPushed, true);
            app.PreliminarFOPButton.Text = 'Preliminar FOP';
            app.ForcepolygonFOPandFunicolarPolygonFUPLabel = uilabel(app.LineofthrustPanel);
            app.ForcepolygonFOPandFunicolarPolygonFUPLabel.Text = 'Force polygon (FOP) and Funicolar Polygon (FUP)';
            app.DefineHorizontalThrustEditFieldLabel = uilabel(app.LineofthrustPanel);
            app.DefineHorizontalThrustEditFieldLabel.Text = 'Define Horizontal Thrust';
            app.DefineHorizontalThrustEditField = uieditfield(app.LineofthrustPanel, 'numeric');
            app.DefineHorizontalThrustEditField.Value = 1000;
            app.SliderButton = uibutton(app.LineofthrustPanel, 'push');
            app.SliderButton.ButtonPushedFcn = createCallbackFcn(app, @SliderButtonPushed, true);
            app.SliderButton.Text = 'Slider';
            app.DefinePointBButton = uibutton(app.LineofthrustPanel, 'push');
            app.DefinePointBButton.ButtonPushedFcn = createCallbackFcn(app, @DefinePointBButtonPushed, true);
            app.DefinePointBButton.Text = 'Define Point B';
            app.DefinePointAButton = uibutton(app.LineofthrustPanel, 'push');
            app.DefinePointAButton.ButtonPushedFcn = createCallbackFcn(app, @DefinePointAButtonPushed, true);
            app.DefinePointAButton.Text = 'Define Point A';
            app.UnknownsLabel = uilabel(app.LineofthrustPanel);
            app.UnknownsLabel.Text = 'Unknowns';
            app.InteractiveFUPfindtheLineofThrustLabel = uilabel(app.LineofthrustPanel);
            app.InteractiveFUPfindtheLineofThrustLabel.Text = 'Interactive FUP (find the Line of Thrust)';
            app.minEditFieldLabel = uilabel(app.LineofthrustPanel);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.LineofthrustPanel, 'numeric');
            app.minEditField.Value = 100;
            app.maxEditFieldLabel = uilabel(app.LineofthrustPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.LineofthrustPanel, 'numeric');
            app.maxEditField.Value = 1000;
            app.UpdateSliderButton = uibutton(app.LineofthrustPanel, 'push');
            app.UpdateSliderButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSliderButtonPushed, true);
            app.UpdateSliderButton.Text = 'Update Slider';
            app.HorizontalThrustLabel = uilabel(app.LineofthrustPanel);
            app.HorizontalThrustLabel.Text = 'Horizontal Thrust';
            app.HorizontalThrustSlider = uislider(app.LineofthrustPanel);
            app.HorizontalThrustSlider.Limits = [0 10000];
            app.HorizontalThrustSlider.ValueChangedFcn = createCallbackFcn(app, @HorizontalThrustSliderValueChanged, true);
            app.HorizontalThrustSlider.ValueChangingFcn = createCallbackFcn(app, @HorizontalThrustSliderValueChanging, true);
            app.minEditFieldLabel_2 = uilabel(app.LineofthrustPanel);
            app.minEditFieldLabel_2.Text = 'min';
            app.maxEditFieldLabel_2 = uilabel(app.LineofthrustPanel);
            app.maxEditFieldLabel_2.Text = 'max';
            app.CableanalogyPanel = uipanel(app.ThrustLineTab);
            app.CableanalogyPanel.Title = 'Cable analogy';
            app.PlotCableanalogyButton = uibutton(app.CableanalogyPanel, 'push');
            app.PlotCableanalogyButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCableanalogyButtonPushed, true);
            app.PlotCableanalogyButton.Text = 'Plot Cable analogy';
            app.AdmissibilityPanel = uipanel(app.ThrustLineTab);
            app.AdmissibilityPanel.Title = 'Admissibility';
            app.CheckadmissbilityButton = uibutton(app.AdmissibilityPanel, 'push');
            app.CheckadmissbilityButton.ButtonPushedFcn = createCallbackFcn(app, @CheckadmissbilityButtonPushed, true);
            app.CheckadmissbilityButton.Text = 'Check admissbility';
            app.ForcesPanel = uipanel(app.ThrustLineTab);
            app.ForcesPanel.Title = 'Forces';
            app.SelectpointButton = uibutton(app.ForcesPanel, 'push');
            app.SelectpointButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpointButtonPushed, true);
            app.SelectpointButton.Text = 'Select point';
            app.MagnitudeLabel = uilabel(app.ForcesPanel);
            app.MagnitudeLabel.Text = 'Magnitude: ';
            app.MagnitudeEditField = uieditfield(app.ForcesPanel, 'numeric');
            app.DeleteallButton_2 = uibutton(app.ForcesPanel, 'push');
            app.DeleteallButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteallButton_2Pushed, true);
            app.DeleteallButton_2.Text = 'Delete all';
            app.DeletebyNButton_2 = uibutton(app.ForcesPanel, 'push');
            app.DeletebyNButton_2.Text = 'Delete by N.';
            app.NLabel_2 = uilabel(app.ForcesPanel);
            app.NLabel_2.Text = 'N.:';
            app.NEditField_2 = uieditfield(app.ForcesPanel, 'numeric');
            app.ViewEditForcesButton = uibutton(app.ForcesPanel, 'push');
            app.ViewEditForcesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEditForcesButtonPushed, true);
            app.ViewEditForcesButton.Text = 'View/Edit Forces';
            app.MechanismTab = uitab(app.TabGroup);
            app.MechanismTab.Title = 'Mechanism';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.GraphicsTab = uitab(app.TabGroup3);
            app.GraphicsTab.Title = 'Graphics';
            app.ClearInteractiveAreaButton = uibutton(app.GraphicsTab, 'push');
            app.ClearInteractiveAreaButton.ButtonPushedFcn = createCallbackFcn(app, @ClearInteractiveAreaButtonPushed, true);
            app.ClearInteractiveAreaButton.Text = 'Clear Interactive Area';
            app.PlotblocksButton_2 = uibutton(app.GraphicsTab, 'push');
            app.PlotblocksButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotblocksButton_2Pushed, true);
            app.PlotblocksButton_2.Text = 'Plot blocks';
            app.forcesfactorEditFieldLabel = uilabel(app.GraphicsTab);
            app.forcesfactorEditFieldLabel.Text = 'forces factor';
            app.forcesfactorEditField = uieditfield(app.GraphicsTab, 'numeric');
            app.forcesfactorEditField.Value = 1;
            app.PlotSketchButton = uibutton(app.GraphicsTab, 'push');
            app.PlotSketchButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSketchButtonPushed, true);
            app.PlotSketchButton.Text = 'Plot Sketch';
            app.PlotForcesButton = uibutton(app.GraphicsTab, 'push');
            app.PlotForcesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotForcesButtonPushed, true);
            app.PlotForcesButton.Text = 'Plot Forces';
            app.ForceMagnitudeSwitchLabel = uilabel(app.GraphicsTab);
            app.ForceMagnitudeSwitchLabel.Text = 'Force Magnitude';
            app.ForceMagnitudeSwitch = uiswitch(app.GraphicsTab, 'slider');
            app.SketchedGeometrySwitchLabel = uilabel(app.GraphicsTab);
            app.SketchedGeometrySwitchLabel.Text = 'Sketched Geometry';
            app.SketchedGeometrySwitch = uiswitch(app.GraphicsTab, 'slider');
            app.BackgroundImageSwitch_2Label = uilabel(app.GraphicsTab);
            app.BackgroundImageSwitch_2Label.Text = 'Background Image';
            app.BackgroundImageSwitch_2 = uiswitch(app.GraphicsTab, 'slider');
            app.BackgroundImageSwitch_2.ValueChangedFcn = createCallbackFcn(app, @BackgroundImageSwitch_2ValueChanged, true);
            app.ClearSketchButton = uibutton(app.GraphicsTab, 'push');
            app.ClearSketchButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSketchButtonPushed, true);
            app.ClearSketchButton.Text = 'Clear Sketch';
            app.PlotBackgroundImageButton = uibutton(app.GraphicsTab, 'push');
            app.PlotBackgroundImageButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBackgroundImageButtonPushed, true);
            app.PlotBackgroundImageButton.Text = 'Plot Background Image';
            app.alphaWLabel = uilabel(app.GraphicsTab);
            app.alphaWLabel.Text = 'alpha W';
            app.alphaWEditField = uieditfield(app.GraphicsTab, 'numeric');
            app.PlotReactionsButton = uibutton(app.GraphicsTab, 'push');
            app.PlotReactionsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotReactionsButtonPushed, true);
            app.PlotReactionsButton.Text = 'Plot Reactions';
            app.FlipYaxisButton = uibutton(app.GraphicsTab, 'push');
            app.FlipYaxisButton.ButtonPushedFcn = createCallbackFcn(app, @FlipYaxisButtonPushed, true);
            app.FlipYaxisButton.Text = 'Flip Y axis';
            app.TextLabelsSwitchLabel = uilabel(app.GraphicsTab);
            app.TextLabelsSwitchLabel.Text = 'Text /Labels';
            app.TextLabelsSwitch = uiswitch(app.GraphicsTab, 'slider');
            app.SaveLoadExampleTab = uitab(app.TabGroup3);
            app.SaveLoadExampleTab.Title = 'Save/Load Example';
            app.SavetofolderExamplesButton = uibutton(app.SaveLoadExampleTab, 'push');
            app.SavetofolderExamplesButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofolderExamplesButtonPushed, true);
            app.SavetofolderExamplesButton.Text = 'Save to folder Examples';
            app.SaveasEditFieldLabel = uilabel(app.SaveLoadExampleTab);
            app.SaveasEditFieldLabel.Text = 'Save as:';
            app.SaveasEditField = uieditfield(app.SaveLoadExampleTab, 'text');
            app.SaveasEditField.ValueChangedFcn = createCallbackFcn(app, @SaveasEditFieldValueChanged, true);
            app.SaveasEditField.Value = 'FileName_Example';
            app.LoadExamplesfolderButton = uibutton(app.SaveLoadExampleTab, 'push');
            app.LoadExamplesfolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExamplesfolderButtonPushed, true);
            app.LoadExamplesfolderButton.Text = 'Load Examples folder ';
            app.ExampleDropDownLabel = uilabel(app.SaveLoadExampleTab);
            app.ExampleDropDownLabel.Text = 'Example:';
            app.ExampleDropDown = uidropdown(app.SaveLoadExampleTab);
            app.ExampleDropDown.Items = {'..', '..'};
            app.ExampleDropDown.ValueChangedFcn = createCallbackFcn(app, @ExampleDropDownValueChanged, true);
            app.ExampleDropDown.Value = '..';
            app.OpenExampleButton = uibutton(app.SaveLoadExampleTab, 'push');
            app.OpenExampleButton.ButtonPushedFcn = createCallbackFcn(app, @OpenExampleButtonPushed, true);
            app.OpenExampleButton.Text = 'Open Example';
            app.ClearAllVariablesButton = uibutton(app.SaveLoadExampleTab, 'push');
            app.ClearAllVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllVariablesButtonPushed, true);
            app.ClearAllVariablesButton.Text = 'Clear All Variables';
            app.BlocksMaterialpropertiesTab = uitab(app.TabGroup3);
            app.BlocksMaterialpropertiesTab.Title = 'Blocks Material properties';
            app.BlockThicknessEditFieldLabel = uilabel(app.BlocksMaterialpropertiesTab);
            app.BlockThicknessEditFieldLabel.Text = 'Block Thickness';
            app.BlockThicknessEditField = uieditfield(app.BlocksMaterialpropertiesTab, 'numeric');
            app.BlockThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @BlockThicknessEditFieldValueChanged, true);
            app.BlockThicknessEditField.Value = 1;
            app.SpecificWeightEditFieldLabel = uilabel(app.BlocksMaterialpropertiesTab);
            app.SpecificWeightEditFieldLabel.Text = 'Specific Weight';
            app.SpecificWeightEditField = uieditfield(app.BlocksMaterialpropertiesTab, 'numeric');
            app.SpecificWeightEditField.Value = 1;
            app.UnitsTab_2 = uitab(app.TabGroup3);
            app.UnitsTab_2.Title = 'Units';
            app.unitlengthLabel = uilabel(app.UnitsTab_2);
            app.unitlengthLabel.Text = 'unit length';
            app.PixelEditFieldLabel = uilabel(app.UnitsTab_2);
            app.PixelEditFieldLabel.Text = '1 Pixel =';
            app.PixelEditField = uieditfield(app.UnitsTab_2, 'numeric');
            app.PixelEditField.Value = 100;
            app.LengthButtonGroup = uibuttongroup(app.UnitsTab_2);
            app.LengthButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LengthButtonGroupSelectionChanged, true);
            app.LengthButtonGroup.Title = 'Length';
            app.mButton = uiradiobutton(app.LengthButtonGroup);
            app.mButton.Text = 'm';
            app.mButton.Value = true;
            app.cmButton = uiradiobutton(app.LengthButtonGroup);
            app.cmButton.Text = 'cm';
            app.mmButton = uiradiobutton(app.LengthButtonGroup);
            app.mmButton.Text = 'mm';
            app.MassButtonGroup = uibuttongroup(app.UnitsTab_2);
            app.MassButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MassButtonGroupSelectionChanged, true);
            app.MassButtonGroup.Title = 'Mass';
            app.kgButton = uiradiobutton(app.MassButtonGroup);
            app.kgButton.Text = 'kg';
            app.kgButton.Value = true;
            app.tonsButton = uiradiobutton(app.MassButtonGroup);
            app.tonsButton.Text = 'tons';
            app.gButton = uiradiobutton(app.MassButtonGroup);
            app.gButton.Text = 'g';
            app.ForceButtonGroup = uibuttongroup(app.UnitsTab_2);
            app.ForceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ForceButtonGroupSelectionChanged, true);
            app.ForceButtonGroup.Title = 'Force';
            app.NButton = uiradiobutton(app.ForceButtonGroup);
            app.NButton.Text = 'N';
            app.NButton.Value = true;
            app.kNButton = uiradiobutton(app.ForceButtonGroup);
            app.kNButton.Text = 'kN';
            app.kgfButton = uiradiobutton(app.ForceButtonGroup);
            app.kgfButton.Text = 'kgf';
            app.ModulusStressButtonGroup = uibuttongroup(app.UnitsTab_2);
            app.ModulusStressButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModulusStressButtonGroupSelectionChanged, true);
            app.ModulusStressButtonGroup.Title = 'Modulus/Stress';
            app.PaButton = uiradiobutton(app.ModulusStressButtonGroup);
            app.PaButton.Text = 'Pa';
            app.PaButton.Value = true;
            app.MPaButton_2 = uiradiobutton(app.ModulusStressButtonGroup);
            app.MPaButton_2.Text = 'MPa';
            app.kgcm2Button = uiradiobutton(app.ModulusStressButtonGroup);
            app.kgcm2Button.Text = 'kg/cm^2';
            app.SIButton = uibutton(app.UnitsTab_2, 'push');
            app.SIButton.ButtonPushedFcn = createCallbackFcn(app, @SIButtonPushed, true);
            app.SIButton.Text = 'SI';
            app.NmmButton = uibutton(app.UnitsTab_2, 'push');
            app.NmmButton.ButtonPushedFcn = createCallbackFcn(app, @NmmButtonPushed, true);
            app.NmmButton.Text = 'Nmm';
            app.kgcmButton = uibutton(app.UnitsTab_2, 'push');
            app.kgcmButton.ButtonPushedFcn = createCallbackFcn(app, @kgcmButtonPushed, true);
            app.kgcmButton.Text = 'kg cm';
            app.InteractiveAreaPanel = uipanel(app.RightPanel);
            app.InteractiveAreaPanel.Title = 'Interactive Area';
            app.UIAxes = uiaxes(app.InteractiveAreaPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1000];
            app.UIAxes.YLim = [0 1000];
            app.ResetViewButton = uibutton(app.InteractiveAreaPanel, 'push');
            app.ResetViewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetViewButtonPushed, true);
            app.ResetViewButton.Text = 'Reset View';
            app.ShowStructuralscaleButton = uibutton(app.InteractiveAreaPanel, 'push');
            app.ShowStructuralscaleButton.ButtonPushedFcn = createCallbackFcn(app, @ShowStructuralscaleButtonPushed, true);
            app.ShowStructuralscaleButton.Text = 'Show Structural scale ';
            app.PanButton = uibutton(app.InteractiveAreaPanel, 'push');
            app.PanButton.ButtonPushedFcn = createCallbackFcn(app, @PanButtonPushed, true);
            app.PanButton.Text = 'Pan';
            app.ScrollZoomonButton = uibutton(app.InteractiveAreaPanel, 'push');
            app.ScrollZoomonButton.ButtonPushedFcn = createCallbackFcn(app, @ScrollZoomonButtonPushed, true);
            app.ScrollZoomonButton.Text = 'Scroll Zoom on';
            app.ScrollZoomoffButton = uibutton(app.InteractiveAreaPanel, 'push');
            app.ScrollZoomoffButton.ButtonPushedFcn = createCallbackFcn(app, @ScrollZoomoffButtonPushed, true);
            app.ScrollZoomoffButton.Text = 'Scroll Zoom off';
            app.mLabel = uilabel(app.InteractiveAreaPanel);
            app.mLabel.Text = '[ m ]';
            app.CurrentunitsLabel = uilabel(app.InteractiveAreaPanel);
            app.CurrentunitsLabel.Text = 'Current units:';
            app.kgLabel = uilabel(app.InteractiveAreaPanel);
            app.kgLabel.Text = '[ kg ]';
            app.NLabel = uilabel(app.InteractiveAreaPanel);
            app.NLabel.Text = '[ N ]';
            app.PaLabel = uilabel(app.InteractiveAreaPanel);
            app.PaLabel.Text = ' [ Pa ] ';
            app.TabGroup4 = uitabgroup(app.RightPanel);
            app.GraphicsTab_2 = uitab(app.TabGroup4);
            app.GraphicsTab_2.Title = 'Graphics';
            app.ForceshrinkfactorEditFieldLabel = uilabel(app.GraphicsTab_2);
            app.ForceshrinkfactorEditFieldLabel.Text = 'Force shrink factor';
            app.ForceshrinkfactorEditField = uieditfield(app.GraphicsTab_2, 'numeric');
            app.Tab2 = uitab(app.TabGroup4);
            app.Tab2.Title = 'Tab2';
            app.TabGroup5 = uitabgroup(app.RightPanel);
            app.DViewTab = uitab(app.TabGroup5);
            app.DViewTab.Title = '3D View';
            app.UIAxes3 = uiaxes(app.DViewTab);
            app.ForcePolygonFPTab = uitab(app.TabGroup5);
            app.ForcePolygonFPTab.Title = 'Force Polygon (FP)';
            app.ForcePoligonPanel = uipanel(app.ForcePolygonFPTab);
            app.ForcePoligonPanel.Title = 'Force Poligon';
            app.UIAxes2 = uiaxes(app.ForcePoligonPanel);
            xlabel(app.UIAxes2, 'Force')
            ylabel(app.UIAxes2, 'Force')
            app.UIAxes2.XLim = [0 1000];
            app.UIAxes2.YLim = [-1000 1000];
            app.ResetViewButton_2 = uibutton(app.ForcePoligonPanel, 'push');
            app.ResetViewButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetViewButton_2Pushed, true);
            app.ResetViewButton_2.Text = 'Reset View';
            app.ShowForceScaleButton = uibutton(app.ForcePoligonPanel, 'push');
            app.ShowForceScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ShowForceScaleButtonPushed, true);
            app.ShowForceScaleButton.Text = 'Show Force Scale';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aLOTofImaginArches(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end